classdef gripForce < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MVCGripForceUIFigure         matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        OpenMenu                     matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        AvgMaxValueinonesecondLabel  matlab.ui.control.Label
        TimeatmaxValLabel            matlab.ui.control.Label
        MaxValueLabel                matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
        GripForce_data = {};
        filepath = "";
    end
    
    methods (Access = public)
        
        function results = GripForceFunction(app)
            cla(app.UIAxes);
            startPoint = 4;
            endPoint = height(app.GripForce_data);
            
            Time = app.GripForce_data(startPoint:endPoint,1);
            GripForce = app.GripForce_data(startPoint:endPoint,4);
            
            Time = Time{:,:};
            GripForce = GripForce{:,:};
            
            [GripmaxVal, GripmaxIndx] = max(GripForce);
            plot(app.UIAxes, Time, GripForce);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, Time(GripmaxIndx), GripForce(GripmaxIndx), '-o');
            app.MaxValueLabel.Text = "Max Value: " + string(GripmaxVal);
            app.TimeatmaxValLabel.Text = "Time at Max Value: " +string(Time(GripmaxIndx));


            %%%% Avg max in once second calculation
            avgGrip = zeros;
            for i = 1:(length(Time)-9999)
                avgGrip(i) = sum(GripForce(i:i+9999)/10000);
            end 
            [avgGripmaxVal, avgGripmaxIndx] = max(avgGrip);
            
            TimeAvg = Time(10000:length(Time));
            avgGrip =  avgGrip';
            TimeAtavgVal = Time(round(avgGripmaxIndx+(9999)));
            
            %%%% Display
            plot(app.UIAxes, TimeAvg, avgGrip);
            plot(app.UIAxes, TimeAtavgVal, avgGripmaxVal, '-o');
            
            lineX = [TimeAtavgVal-1.0 TimeAtavgVal+0];
            lineY = [avgGripmaxVal avgGripmaxVal];
            line(app.UIAxes, lineX , lineY, Color="green");
            
            lineX = [TimeAtavgVal-1.0 TimeAtavgVal-1.0];
            lineY = [avgGripmaxVal-20 avgGripmaxVal+20];
            line(app.UIAxes, lineX , lineY, Color="red");
            
            lineX = [TimeAtavgVal+0.0 TimeAtavgVal+0.0];
            lineY = [avgGripmaxVal-20 avgGripmaxVal+20];
            line(app.UIAxes, lineX , lineY, Color="red");
            
            app.AvgMaxValueinonesecondLabel.Text = "Avg Max Value in one second: " + string(avgGripmaxVal);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
             app.GripForce_data ={};
             app.filepath = "";
            %% clearing done
            
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [filename,path] = uigetfile('*.txt', 'Open text file','MultiSelect','on');
            delete(f);
            
            %[filename, path] = uigetfile('*.*');
            txt = string(path)+string(filename);
            app.filepath = txt;
            %app.MaxValueLabel.Text = txt;
            %startupConfig(app);
            
            if app.filepath ~=  ""
                if app.filepath ~= "00"
                    app.GripForce_data= readtable(app.filepath,'PreserveVariableNames',true);
                    %func2(app, app.endPoint, app.startPoint, app.sampleNumber);
                    GripForceFunction(app);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MVCGripForceUIFigure = uifigure('Visible', 'off');
            app.MVCGripForceUIFigure.Name = 'MVC Grip Force';
            app.MVCGripForceUIFigure.Icon = 'icon.png';
            app.FileMenu = uimenu(app.MVCGripForceUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.HelpMenu = uimenu(app.MVCGripForceUIFigure);
            app.HelpMenu.Text = 'Help';
            app.UIAxes = uiaxes(app.MVCGripForceUIFigure);
            title(app.UIAxes, 'MVC Grip')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Newton')
            zlabel(app.UIAxes, 'Z')
            app.MaxValueLabel = uilabel(app.MVCGripForceUIFigure);
            app.MaxValueLabel.Text = 'Max Value:';
            app.TimeatmaxValLabel = uilabel(app.MVCGripForceUIFigure);
            app.TimeatmaxValLabel.Text = 'Time at maxVal:';
            app.AvgMaxValueinonesecondLabel = uilabel(app.MVCGripForceUIFigure);
            app.AvgMaxValueinonesecondLabel.Text = 'Avg Max Value in one second: ';
            % Show the figure after all components are created
            app.MVCGripForceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gripForce

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MVCGripForceUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MVCGripForceUIFigure)
        end
    end
end