classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        URNeuroAppUIFigure             matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        CalibrationMenu                matlab.ui.container.Menu
        OpenCalibrationMenu            matlab.ui.container.Menu
        MVCGripForceMenu               matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        CSPTab                         matlab.ui.container.Tab
        SavaDataforMLButton            matlab.ui.control.Button
        AutoThresholdCheckBox          matlab.ui.control.CheckBox
        Test_Label_4                   matlab.ui.control.Label
        UpdateThButton                 matlab.ui.control.Button
        OffsetThEditField              matlab.ui.control.EditField
        OffsetThEditFieldLabel         matlab.ui.control.Label
        OnsetThEditField               matlab.ui.control.EditField
        OnsetThLabel                   matlab.ui.control.Label
        Test_Label_3                   matlab.ui.control.Label
        Test_Label_2                   matlab.ui.control.Label
        Test_Label_1                   matlab.ui.control.Label
        Disp_CSP_OnSetLabel            matlab.ui.control.Label
        Disp_CSP_OffSetLabel           matlab.ui.control.Label
        Disp_Peak2PeakLabel            matlab.ui.control.Label
        Disp_uV_MinLabel               matlab.ui.control.Label
        Disp_uV_MaxLabel               matlab.ui.control.Label
        Disp_RMS_After_CSPLabel        matlab.ui.control.Label
        Disp_RMSLabel                  matlab.ui.control.Label
        Disp_CSPLabel                  matlab.ui.control.Label
        OnsetButton                    matlab.ui.control.Button
        OffsetButton                   matlab.ui.control.Button
        ShowAllSaveButton              matlab.ui.control.Button
        HTML                           matlab.ui.control.HTML
        PreviousButton                 matlab.ui.control.Button
        NextButton                     matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        SampleNumberLabel              matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        MEPATab                        matlab.ui.container.Tab
        ThresholdLabel                 matlab.ui.control.Label
        MotorPotentialLabel            matlab.ui.control.Label
        TestLebel3Label                matlab.ui.control.Label
        ThLabel                        matlab.ui.control.Label
        MPLabel                        matlab.ui.control.Label
        ThLabel_MEPA                   matlab.ui.control.Label
        TheMEPAThesholdissetasLabel    matlab.ui.control.Label
        ThresholdEditField_MEPA        matlab.ui.control.EditField
        ChangeThresholdLabel           matlab.ui.control.Label
        MEPAIdentificationMarkerLabel  matlab.ui.control.Label
        ProcessAllSaveButton           matlab.ui.control.Button
        SelectROIButton                matlab.ui.control.Button
        Label_2                        matlab.ui.control.Label
        SampleNumberLabel_2            matlab.ui.control.Label
        PreviousButton_2               matlab.ui.control.Button
        NextButton_2                   matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        ITTTab                         matlab.ui.container.Tab
        ITT_ControlPeakOffsetLabel     matlab.ui.control.Label
        ITT_BaseOffsetLabel            matlab.ui.control.Label
        StimulationDetectionThresholdLabel  matlab.ui.control.Label
        ROIafterFirstStimulationLabel  matlab.ui.control.Label
        ITT_ROI_LevelLabel             matlab.ui.control.Label
        ITT_Th_LevelLabel              matlab.ui.control.Label
        RefreshITTButton               matlab.ui.control.Button
        SetROIButton                   matlab.ui.control.Button
        SetThresholdButton             matlab.ui.control.Button
        SaveButton_2                   matlab.ui.control.Button
        ITT_HTML                       matlab.ui.control.HTML
        ITTdirectoryLebel              matlab.ui.control.Label
        UIAxesITT                      matlab.ui.control.UIAxes
        TTATab                         matlab.ui.container.Tab
        TTA_AutoThresholdCheckBox      matlab.ui.control.CheckBox
        PedalThresholdEditField        matlab.ui.control.NumericEditField
        PedalThresholdEditFieldLabel   matlab.ui.control.Label
        SaveResultButton               matlab.ui.control.Button
        TTA_FileDirectoryLabel         matlab.ui.control.Label
        PedalOffsetEditField           matlab.ui.control.NumericEditField
        PedalOffsetEditFieldLabel      matlab.ui.control.Label
        BeepGainEditField              matlab.ui.control.EditField
        BeepGainEditFieldLabel         matlab.ui.control.Label
        PedalGainEditField             matlab.ui.control.EditField
        PedalGainEditFieldLabel        matlab.ui.control.Label
        TrialNoLabel                   matlab.ui.control.Label
        ShowMoreButton                 matlab.ui.control.Button
        SelectTaskDropDown             matlab.ui.control.DropDown
        SelectTaskDropDownLabel        matlab.ui.control.Label
        NextButton_TTA                 matlab.ui.control.Button
        PreviousButton_TTA             matlab.ui.control.Button
        TTA_RespVsBeep                 matlab.ui.control.UIAxes
        TTA_BeepVsPos                  matlab.ui.control.UIAxes
        UIAxesTTA                      matlab.ui.control.UIAxes
        HReflexTab                     matlab.ui.container.Tab
        Hreflex_FileDirectoryLabel     matlab.ui.control.Label
        SaveProcessedDataButton_Hreflex  matlab.ui.control.Button
        UIAxesHRAslope                 matlab.ui.control.UIAxes
        UIAxesHRoutput                 matlab.ui.control.UIAxes
        UIAxesHReflex                  matlab.ui.control.UIAxes
        MReflexTab                     matlab.ui.container.Tab
        MinLabel                       matlab.ui.control.Label
        MaxLabel                       matlab.ui.control.Label
        UIAxesMRoutput                 matlab.ui.control.UIAxes
        UIAxesMReflex                  matlab.ui.control.UIAxes
        TTACSPTab                      matlab.ui.container.Tab
        HighpassFreqEditFieldLabel     matlab.ui.control.Label
        HighpassFreqEditFieldTTACSP    matlab.ui.control.NumericEditField
        RightboundaryEditFieldLabel    matlab.ui.control.Label
        RightboundaryEditField         matlab.ui.control.NumericEditField
        LeftboundaryEditFieldLabel     matlab.ui.control.Label
        LeftboundaryEditField          matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel        matlab.ui.control.Label
        ThresholdEditFieldTTACSP       matlab.ui.control.EditField
        NextButton_TTACSP              matlab.ui.control.Button
        PreviousButton_TTACSP          matlab.ui.control.Button
        SplitButton                    matlab.ui.control.Button
        PreviousButton_splittedCSP     matlab.ui.control.Button
        NextButton_splittedCSP         matlab.ui.control.Button
        SubmitButtonTTACSP             matlab.ui.control.Button
        SplitNumberLabel               matlab.ui.control.Label
        FileDirectoryLabelTTACSP       matlab.ui.control.Label
        CSP_DurationLabel              matlab.ui.control.Label
        UIAxesTTACSP                   matlab.ui.control.UIAxes
        SettingsTab                    matlab.ui.container.Tab
        DeveloperModeCheckBox          matlab.ui.control.CheckBox
        SSValLabel                     matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        SampleSizeEditField            matlab.ui.control.EditField
        SampleSizeEditFieldLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
        data ={} ;
        mep_data ={};
        ITT_data = {};
        extractedCSP_data ={};  % {Trial_number, CSP_OnSet, CSP_OffSet}
        endPoint = 0;
        startPoint = 0;
        sampleNumber = 1;
        %AllCntSampleNumber = 1;
        SampleDataSize = 0;
        AutoThresholdCheckBoxValue = 1;
        lastPath = ""; % Property to store the last accessed path
        lastPathForSave = ""; % Property to store the last accessed path for saving
        
        Time_Data  = {};
        Amplitude_Data = {};
        
        dataPointPerSample = 1199; %1999;799
        AmpOffsetVaule = 0; % This creates CSP intractable. better not to use it.  
        
        fileNameOnly = "";
        filepath = "";
        freqPass = 30; %80;
        startP = 0;
        thresholdLevel = 0.021;
        spSearchStartPoint = 0.06;
        
        ROI_Start_X = 0;
        ROI_End_X = 0;
        ROI_X = zeros;
        
        TimeD2 = zeros;
        AmplitudeD2 = zeros;
        
        SampleSizeResult = 0;
        
        currentFileNameOnly = "";
        
        
        CSP_onSet_Th = 0;
        CSP_offSet_Th = 0;
        CSP_Th_Update_Button_On = 0;
        CSP_TotalTrail = 2;
        CSP_IntervalIndx = {};
        
        %--- MEPA ---%
        MEPA_Threshold = 0.2;
        MEP_TotalTrail = 2;
        MEP_IntervalIndx = {};
        
        %--- ITT ----%
        ITT_Threshold = -3;
        ITT_Threshold_Length = 0;
        ITT_ROI_after_firstStimulation = 1;
        ITT_firstStimulation = 0;
        ITT_Save_Button_Pressed = 0;
        ITT_isManualThreshold = 0;
        
        %--- TTA ---%
        TTA_data = {};
        TTA_TrialNumber = 1;
        TTA_TotalTrail = 2;
        TTA_IntervalIndx = {};
        ITA_Beep_to_Resp = {};
        ITA_BeepPositionBefResp = {};
        ITA_beep_number = {};
        TTA_progressdialog;
        TTA_PedalGain = 1;
        TTA_BeepGain = 1;
        TTA_PedalOffset = 0;
        TTA_PadelThreshold = 5;
        
        %--- HReflex ---%
        HReflex_data = {};
        HReflex_startSample =1;
        HReflex_endSample = 0;
        HReflex_IntervalIndx = {};
        %hrfClass = hreflex();
        HReflex_Cropped_1_Peak2Peak_All ={};
        HReflex_Cropped_2_Peak2Peak_All ={};
        HReflex_Peak_volt_All ={};
        HR_VHM_shorted = {};
        HreflexSlopeVal = 0;
        
        fivePercent_HmaxP2P = 0;
        fivePercent_HmaxP2P_Current = 0;
        
        fiftyPercent_HmaxP2P = 0;
        fiftyPercent_HmaxP2P_Current = 0;
        
        %--- MReflex ---%
        MReflex_data = {};
        MReflex_startSample = 1;
        MReflex_endSample =0;
        MReflex_IntervalIndx = {};
        
        %---TTACSP ---%
        globalLastIndices = zeros;
        glboalTime = zeros;
        globalTTACSP_EMG = zeros;
        TTA_TotalSplit = 2;
        TTA_SplitNumber = 1;
        ThresholdTTACSP = 2;
        SplitButtonPressed = 0;
        LeftboundaryTTACSP = 200;
        RightboundaryTTACSP = 1500;
        TTACSPhpFreq = 30;
        
    end
    
    methods (Access = public)
        function startupConfig(app)
            if app.filepath ~=  ""
                if app.filepath ~= "00"
                    app.SampleDataSize = size(readcell(app.filepath));
                end
            end


            try
                if exist('Appconfig.txt', 'file') == 2
                    % The file exists
                    disp('Appconfig.txt exists.');
                    configVals = readcell("Appconfig.txt");
                    app.freqPass = cell2mat(configVals(1,1));
                    app.thresholdLevel = cell2mat(configVals(2,1));
                    app.spSearchStartPoint = cell2mat(configVals(3,1));
                    app.ROI_Start_X = cell2mat(configVals(4,1));
                    app.ROI_End_X = cell2mat(configVals(4,2));
                    app.ROI_X(1) = app.ROI_Start_X;
                    app.ROI_X(2) = app.ROI_End_X ;
                    app.dataPointPerSample = cell2mat(configVals(5,1));
                    app.ITT_Threshold = cell2mat(configVals(6,1));
                    app.ITT_ROI_after_firstStimulation = cell2mat(configVals(7,1));
                    app.MEPA_Threshold = cell2mat(configVals(8,1)); 
                    app.AutoThresholdCheckBox.Value =1;
                    app.OnsetThEditField.Visible = "off";
                    app.OnsetThLabel.Visible = "off";
                    app.OffsetThEditFieldLabel.Visible = "off";
                    app.OffsetThEditField.Visible = "off";
                    app.UpdateThButton.Visible = "off";
                    app.lastPath = cell2mat(configVals(9,1));
                    app.lastPathForSave = cell2mat(configVals(10,1));
                else
                    % The file does not exist
                    disp('Appconfig.txt does not exist.');
                    app.lastPath = pwd;
                    app.lastPathForSave = pwd;
                    AppConfigSave(app);
                    disp('Appconfig.txt Created.');
                end 
                
            catch ME
                AppConfigSave(app);
                disp('Error in appconfig.txt file. New appconfig.txt file has been created');
            end
            
    
            DeveloperModeCheckBoxValueChanged(app);     % Check is Developer mode on? 
        end
        
        function results = func2(app,endPoint, startPoint, sampleNumber)
            if app.filepath ~= ""
                cla(app.UIAxes);
                %global endPoint ;
                %data= readcell(app.filepath);
                %DataSize = size(data);
                
%                 app.Test_Label_1.Text = string(app.startPoint);
%                 app.Test_Label_2.Text = string(app.endPoint);
                %app.Test_Label_3.Text = string(app.sampleNumber);
                
                app.Test_Label_1.Text = string((app.endPoint - app.startPoint)+1);
                
                app.SampleDataSize = size(app.data);
               
                            
    %             startPoint = endPoint+ 7;
    %             endPoint = startPoint+1999;
                %app.endPoint = endPoint;
                Time = app.data(startPoint:endPoint,1);
                Amplitude = app.data(startPoint:endPoint,2);
                
                TimeD =zeros;
                for i = 1:length(Time)
                    TimeD(i) = Time{i};
                end 
                
                AmplitudeD=zeros;
                for i = 1:length(Amplitude)
                    AmplitudeD(i) = Amplitude{i} + app.AmpOffsetVaule;
                    if isnan(AmplitudeD(i))
                        AmplitudeD(i) = AmplitudeD(i-1);
                    end
                end 
                
                %% AddBias to Signal for leveling it with zero
                SignalBias = (sum(AmplitudeD(1:40))/40)*(-1); 
                
                AmplitudeD = AmplitudeD' + SignalBias;  % updated on 01.August.2023
                TimeD = TimeD';
               
                
                zeroTime = find(TimeD == 0);
                %Max & Min uV & Time
                [FirstResp_maxVal,FirstResp_maxIndx] = max(AmplitudeD(zeroTime+41:length(AmplitudeD)));
                [FirstResp_minVal,FirstResp_minIndx] = min(AmplitudeD(zeroTime+41:length(AmplitudeD)));
                peak2peak = abs(FirstResp_maxVal) + abs(FirstResp_minVal);
                
                FirstResp_minIndx = FirstResp_minIndx+zeroTime+40;
                FirstResp_maxIndx = FirstResp_maxIndx+zeroTime+40;
                %end Max & Min uV & Time


                MinBefStim = app.CSP_onSet_Th;
                rectMinBefStim =app.CSP_offSet_Th;
                
                if app.AutoThresholdCheckBox.Value == 1
                    [MinBefStim, MinIdx] = min(AmplitudeD(1:150));
                    MinBefStim = MinBefStim*0.5; % it was 0.5 // changed o 31.07.2023  %this is for onset
                else
                    MinBefStim = app.CSP_onSet_Th;
                    app.Test_Label_4.Text = string(MinBefStim);
                    
                end
                
                app.OnsetThEditField.Value = string(MinBefStim); % display on-set detecting threshold value
                
                
                %================= CheckIfSampleIsExtracted ===================
                thisSampleExtractedBefore = 0;
                if size(app.extractedCSP_data,1) ==0
                    thisSampleExtractedBefore = 0;
                else
                    sampleNumberFound = 0;
                    for i = 1:size(app.extractedCSP_data,1)
                        if app.extractedCSP_data{i,1} == app.sampleNumber
                            sampleNumberFound = 1;
                            thisSampleExtractedBefore = 1;
                            break;
                        else
                            sampleNumberFound = 0;
                            thisSampleExtractedBefore = 0;
                        end
                    end
                    if sampleNumberFound == 0
                    end
                end
                
                %================= CSP analysis started =======================
     
                spOnSet = 0;
                spOffSet = 0;
                zeroTime = find(TimeD == 0);
                if thisSampleExtractedBefore == 0
                    hpData = highpass(AmplitudeD,app.freqPass,1e3);
                
                    meanHPdata = zeros;
                    for i = 1:length(hpData)
                        meanHPdata(i) = mean(hpData);
                    end 
                    
                    rectHPdata = zeros;
                    for i = 1:length(hpData)
                        if hpData(i)> mean(hpData)
                            rectHPdata(i) = mean(hpData); %rectified High-Pass Data
                        else
                            rectHPdata(i) = hpData(i);
                        end 
                    end 
                    
                    
                                        %This chunk added new 11.04.2023
                    CSP_onSet_Indx = 0;
                    CSP_onSet_Indx_Amp = 0;
                    %OnsetArray =zeros;
                    for i = 1:FirstResp_minIndx
                        if (AmplitudeD(FirstResp_minIndx+1-i)) >= MinBefStim
                            break;
                        end
                        CSP_onSet_Indx = FirstResp_minIndx+1-i;
                        CSP_onSet_Indx_Amp = AmplitudeD(FirstResp_minIndx+1-i);
                        %OnsetArray(i)  = AmplitudeD(FirstResp_minIndx+1-i);
                    end


                    if(CSP_onSet_Indx <= 0)
                        CSP_onSet_Indx = 1;
                    end

                    
                    spOnSet = TimeD(CSP_onSet_Indx);


                    if app.AutoThresholdCheckBox.Value == 1
                        [rectMinBefStim, rectMinIdx] = min(rectHPdata(1:150));
                        rectMinBefStim = rectMinBefStim*0.5; % was 0.5   -- change 31-07-2023  %this is for offset
                    else
                        rectMinBefStim = app.CSP_offSet_Th;
                    end

                    app.OffsetThEditField.Value = string(rectMinBefStim);  % disply off-set detection threshold


                    spSearchPoint = CSP_onSet_Indx+200;
                                           %End This chunk added new 11.04.2023
                    
                    for i = spSearchPoint:length(AmplitudeD)
                        if rectHPdata(i) < (rectMinBefStim)         %(meanHPdata-app.thresholdLevel)
                            spOffSet = TimeD(i);
                            break;
                        end 
                        %i = i+1;
                    end
                else
                    spOnSet = cell2mat(app.extractedCSP_data(app.sampleNumber, 2));
                    spOffSet = cell2mat(app.extractedCSP_data(app.sampleNumber, 3));
                end


                if spOnSet <=0
                    CorticalSilentPeriod = 0;
                else
                    CorticalSilentPeriod = spOffSet - spOnSet;
                end

                
                %RMS value caluculation
                
                init_rms = rms(AmplitudeD(1:200));
                
                [~,spOffSetIdx]=min(abs(TimeD-spOffSet));
                afterOffset_rms = rms(AmplitudeD(spOffSetIdx:length(AmplitudeD)));
                
                
                disp(spOffSet);
                disp(spOffSetIdx);
                disp(afterOffset_rms);
               
                % End RMS value caluculation
                
                %===================== display features =======================
                plot(app.UIAxes,TimeD,AmplitudeD);
                hold(app.UIAxes, 'on');
                
                %drawing Threshold Line for onset and offset threshold
                plot(app.UIAxes, TimeD, (zeros(1,length(TimeD))+MinBefStim), color ='green'); 
                plot(app.UIAxes, TimeD, (zeros(1,length(TimeD))+rectMinBefStim), color ='yellow');
                
                %txt = ['RMS = ' num2str(init_rms)];        % shows the CSP duration
                %text(app.UIAxes, spOnSet+0.01,-0.15,txt);
                app.Disp_RMSLabel.Text = string("RMS Before Stimulation = "+init_rms);


                txt = ['RMS After CSP = ' num2str(afterOffset_rms)];        % shows the CSP duration
                %text(app.UIAxes, spOnSet+0.10,-0.15,txt);
                app.Disp_RMS_After_CSPLabel.Text = string(txt);
                
                %app.Test_Label_3.Text = string(TimeD(FirstResp_maxIndx+zeroTime+40));
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, TimeD(FirstResp_maxIndx), FirstResp_maxVal, '-o');  % shows the Maximum uV
                txt = "uV Max = "+num2str(FirstResp_maxVal) +", Time = "+num2str(TimeD(FirstResp_maxIndx));
                text(app.UIAxes, TimeD(FirstResp_maxIndx+20), FirstResp_maxVal,txt);
                app.Disp_uV_MaxLabel.Text = txt;
                
                try
                    plot(app.UIAxes, TimeD(FirstResp_minIndx), FirstResp_minVal, '-o');  % shows the Minimum uV
                    txt = "uV Min = "+num2str(FirstResp_minVal) +", Time = "+num2str(TimeD(FirstResp_minIndx));
                    text(app.UIAxes, TimeD(FirstResp_minIndx+20), FirstResp_minVal,txt);
                    app.Disp_uV_MinLabel.Text = txt;
                    
                    %text(app.UIAxes, spOnSet+0.01,-0.20,"Peak-Peak (uV) = "+peak2peak);
                    app.Disp_Peak2PeakLabel.Text = string("Peak-Peak (uV) = "+peak2peak);
                catch ME
                    
                end


                if CorticalSilentPeriod > 0
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes, spOnSet,0, '-o');
                    
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes, spOffSet,0, '-o');
                    
                    lineX = [spOnSet spOffSet];
                    lineY = [0 0];
                    line(app.UIAxes, lineX, lineY, Color="red");
                    
        
                    txt = ['CSP: ' num2str(CorticalSilentPeriod) ' s'];        % shows the CSP duration
                    %text(app.UIAxes, spOnSet+0.01,-0.10,txt);
                    app.Disp_CSPLabel.Text = string(txt);
                    
                    txt = ['CSP Onset: ' num2str(spOnSet) 's'];            % shows the CSP starting time
                    %text(app.UIAxes, spOnSet,+0.15,txt, Color="black");
                    app.Disp_CSP_OnSetLabel.Text = txt;
                    
                    txt = ['CSP Offset: ' num2str(spOffSet) 's'];           % shows the CSP ending time
                    %text(app.UIAxes, spOffSet,+0.15,txt, Color="red");
                    app.Disp_CSP_OffSetLabel.Text = txt;
                else
                    txt = 'CSP: not measurable';
                    text(app.UIAxes, spOnSet+0.01,-0.10,txt);
                end
                
%                 plot(app.UIAxes,TimeD,AmplitudeD);
%                 hold(app.UIAxes, 'on');
%                 plot(app.UIAxes, spOnSet,0, '-o');
%                 
%                 hold(app.UIAxes, 'on')
%                 plot(app.UIAxes, spOffSet,0, '-o');
%                 
%                 lineX = [spOnSet spOffSet];
%                 lineY = [0 0];
%                 line(app.UIAxes, lineX, lineY, Color="red");
%                 
%     
%                 txt = ['CSP: ' num2str(CorticalSilentPeriod) ' ms'];
%                 text(app.UIAxes, spOnSet+0.01,-0.10,txt);
                
                app.SampleNumberLabel.Text = "Sample Number: " + string(app.sampleNumber);
                app.Label.Text = string(app.SampleDataSize(1) +","+ app.filepath);
                
                if app.sampleNumber <= 1
                    app.PreviousButton.Enable = 'off';
                else
                    app.PreviousButton.Enable = 'on';
                end
                
                if (endPoint+app.dataPointPerSample)> app.SampleDataSize(1)
                    app.NextButton.Enable = 'off';
                else
                    app.NextButton.Enable = 'on';
                end
                
                
                %app.extractedCSP_data = [app.extractedCSP_data; {app.sampleNumber,spOnSet,spOffSet}];
                %disp(size(app.extractedCSP_data,1));
                %disp([app.extractedCSP_data]);
                
                if size(app.extractedCSP_data,1) ==0
                    app.extractedCSP_data = [app.extractedCSP_data; {app.sampleNumber,spOnSet,spOffSet}];
                else
                    sampleNumberFound = 0;
                    for i = 1:size(app.extractedCSP_data,1)
                        if app.extractedCSP_data{i,1} == app.sampleNumber
                            sampleNumberFound = 1;
                            break;
                        else
                            sampleNumberFound = 0;
                        end
                    end
                    if sampleNumberFound == 0
                        app.extractedCSP_data = [app.extractedCSP_data; {app.sampleNumber,spOnSet,spOffSet}];
                    end
                end
                
                %disp([app.extractedCSP_data]);
                %app.dataPointPerSample
                
            end
            
            
        end


        %%%%% Function_3 for Calculating CSP altogether %%%%%
        
        function [CSPduration, results2, results1, FirstResp_maxVal, CSPmaxTime, FirstResp_minVal,CSPminTime, peak2peak, init_rms, afterCSP_rms, silentPeriodOnSetIndx, silentPeriodOffSetIndx] = func3(app,endPoint, startPoint, AllCntSampleNumber)
            
            if app.filepath == ""
                app.data= readcell('data2.txt');
            else
                app.data = app.data;
            end

            Time = app.data(startPoint:endPoint,1);
            Amplitude = app.data(startPoint:endPoint,2);
            
            TimeD = zeros;
            for i = 1:length(Time)
                TimeD(i) = Time{i};
            end 
            
            AmplitudeD = zeros;
            for i = 1:length(Amplitude)
                AmplitudeD(i) = Amplitude{i};
                if isnan(AmplitudeD(i))
                    AmplitudeD(i) = AmplitudeD(i-1);
                end
            end 
            
            
%             tabSeparatedValues_TimeD = strrep(TimeD, ' ', '\t');
%             tabSeparatedValues_AmplitudeD = strrep(AmplitudeD, ' ', '\t');
%             
%             app.Time_Data = tabSeparatedValues_TimeD;
%             app.Amplitude_Data = tabSeparatedValues_AmplitudeD;
            
            app.Time_Data = TimeD;
            app.Amplitude_Data = AmplitudeD;
            
            %% AddBias to Signal for leveling it with zero
            SignalBias = (sum(AmplitudeD(1:40))/40)*(-1); 
                
            AmplitudeD = AmplitudeD' + SignalBias;  % updated on 01.August.2023
            TimeD = TimeD';


            zeroTime = find(TimeD == 0);  %This chunk updated 11.04.2023
            %Max & Min uV & Time
            [FirstResp_maxVal,FirstResp_maxIndx] = max(AmplitudeD(zeroTime+41:length(AmplitudeD)));
            [FirstResp_minVal,FirstResp_minIndx] = min(AmplitudeD(zeroTime+41:length(AmplitudeD)));
            peak2peak = abs(FirstResp_maxVal) + abs(FirstResp_minVal);
            
            FirstResp_minIndx = FirstResp_minIndx+zeroTime+40;
            FirstResp_maxIndx = FirstResp_maxIndx+zeroTime+40;
            %end Max & Min uV & Time
            
            %[MinBefStim, MinIdx] = min(AmplitudeD(1:150));
            %MinBefStim = MinBefStim*0.5;
            
            MinBefStim = app.CSP_onSet_Th;
            rectMinBefStim =app.CSP_offSet_Th;
                
            if app.AutoThresholdCheckBox.Value == 1
                [MinBefStim, MinIdx] = min(AmplitudeD(1:150));
                MinBefStim = MinBefStim*0.5; % it was 0.5 // changed o 31.07.2023  %this is for onset
            else
                MinBefStim = app.CSP_onSet_Th;     
            end


            %================= CheckIfSampleIsExtracted ===================
                thisSampleExtractedBefore = 0;
                if size(app.extractedCSP_data,1) ==0
                    thisSampleExtractedBefore = 0;
                else
                    sampleNumberFound = 0;
                    for i = 1:size(app.extractedCSP_data,1)
                        if app.extractedCSP_data{i,1} == AllCntSampleNumber
                            sampleNumberFound = 1;
                            thisSampleExtractedBefore = 1;
                            break;
                        else
                            sampleNumberFound = 0;
                            thisSampleExtractedBefore = 0;
                        end
                    end
                    if sampleNumberFound == 0
                    end
                end
                
             %================= CSP analysis started =======================
            
             %================= CSP analysis started =======================
     
                spOnSet = 0;
                spOffSet = 0;
                zeroTime = find(TimeD == 0);
                if thisSampleExtractedBefore == 0
                    hpData = highpass(AmplitudeD,app.freqPass,1e3);
                
                    meanHPdata = zeros;
                    for i = 1:length(hpData)
                        meanHPdata(i) = mean(hpData);
                    end 
                    
                    rectHPdata = zeros;
                    for i = 1:length(hpData)
                        if hpData(i)> mean(hpData)
                            rectHPdata(i) = mean(hpData);
                        else
                            rectHPdata(i) = hpData(i);
                        end 
                        %i = i+1;
                    end 
                    
                    
                    CSP_onSet_Indx = 0;
                    CSP_onSet_Indx_Amp = 0;
                    %OnsetArray =zeros;
                    for i = 1:FirstResp_minIndx
                        if (AmplitudeD(FirstResp_minIndx+1-i)) >= MinBefStim
                            break;
                        end
                        CSP_onSet_Indx = FirstResp_minIndx+1-i;
                        CSP_onSet_Indx_Amp = AmplitudeD(FirstResp_minIndx+1-i);
                        %OnsetArray(i)  = AmplitudeD(FirstResp_minIndx+1-i);
                    end
                    
                    spOnSet = TimeD(CSP_onSet_Indx);
                    
                    %[rectMinBefStim, rectMinIdx] = min(rectHPdata(1:150));
                    %rectMinBefStim = rectMinBefStim*0.5;
                    
                    if app.AutoThresholdCheckBox.Value == 1
                        [rectMinBefStim, rectMinIdx] = min(rectHPdata(1:150));
                        rectMinBefStim = rectMinBefStim*0.5; % was 0.5   -- change 31-07-2023  %this is for offset
                    else
                        rectMinBefStim = app.CSP_offSet_Th;
                    end
                    
                    
                    spSearchPoint = CSP_onSet_Indx+200;
        
                    %spOffSet = 0;
                    for i = spSearchPoint:length(AmplitudeD)
                        if rectHPdata(i) < rectMinBefStim           %(meanHPdata-app.thresholdLevel)
                            spOffSet = TimeD(i);
                            break;
                        end 
                        %i = i+1;
                    end
                else
                    spOnSet = cell2mat(app.extractedCSP_data(AllCntSampleNumber, 2));
                    spOffSet = cell2mat(app.extractedCSP_data(AllCntSampleNumber, 3));
                end


            if spOnSet <=0
                    CorticalSilentPeriod = 0;
            else
                    CorticalSilentPeriod = spOffSet - spOnSet;
            end
            %CorticalSilentPeriod = spOffSet - spOnSet;
            CSPduration = CorticalSilentPeriod ;
            results2 = spOnSet;
            results1 = spOffSet;
            
%             silentPeriodOnSetIndx = find(TimeD == spOnSet);  this method doesnt work properly
%             silentPeriodOffSetIndx = find(TimeD == spOffSet);
            
            % Compute the absolute difference between each element in TimeD and spOnSet
            absDifference = abs(TimeD - spOnSet);
            
            % Find the index of the minimum value in the difference array
            [~, closestIndex] = min(absDifference);
            silentPeriodOnSetIndx = closestIndex; 
            
            absDifference = abs(TimeD - spOffSet);
            [~, silentPeriodOffSetIndx] = min(absDifference);
            
            [FirstResp_maxVal,FirstResp_maxIndx] = max(AmplitudeD(zeroTime+41:length(AmplitudeD)));
            [FirstResp_minVal,FirstResp_minIndx] = min(AmplitudeD(zeroTime+41:length(AmplitudeD)));
            CSPmaxTime = TimeD(FirstResp_maxIndx+zeroTime+40);
            CSPminTime = TimeD(FirstResp_minIndx+zeroTime+40);
            peak2peak = abs(FirstResp_maxVal) + abs(FirstResp_minVal);
            
            %RMS value caluculation
                
            init_rms = rms(AmplitudeD(1:200));
            
            [~,spOffSetIdx]=min(abs(TimeD-spOffSet));
            afterCSP_rms = rms(AmplitudeD(spOffSetIdx:length(AmplitudeD)));
               
            % End RMS value caluculation
        end


        function MEPAres = MEPAfunc(app,endPoint, startPoint, sampleNumber)
            if app.filepath ~= ""
                cla(app.UIAxes2);
                %global endPoint ;
                %app.data= readcell(app.filepath);
                %DataSize = size(data);
                app.SampleDataSize = size(app.mep_data);


                Time = app.mep_data(startPoint:endPoint,1);
                Amplitude = app.mep_data(startPoint:endPoint,2);
                
                TimeD =zeros;
                for i = 1:length(Time)
                    TimeD(i) = Time{i};
                    app.TimeD2(i) =Time{i};
                end 
                
                AmplitudeD=zeros;
                for i = 1:length(Amplitude)
                    AmplitudeD(i) = Amplitude{i};
                    app.AmplitudeD2(i) = Amplitude{i};
                    if isnan(AmplitudeD(i))
                        AmplitudeD(i) = AmplitudeD(i-1);
                        app.AmplitudeD2(i) = AmplitudeD{i-1};
                    end
                end 
                
                AmplitudeD = AmplitudeD';
                TimeD = TimeD';


                zeroTime = find(TimeD == 0);
                
                
                %RMS value caluculation
                
                init_rms = rms(AmplitudeD(1:200));
                
%                 [~,spOffSetIdx]=min(abs(TimeD-spOffSet));
%                 afterOffset_rms = rms(AmplitudeD(spOffSetIdx:length(AmplitudeD)));
%                 
%                 
%                 disp(spOffSet);
%                 disp(spOffSetIdx);
%                 disp(afterOffset_rms);
               
                % End RMS value caluculation


                %===================== display features =======================
                plot(app.UIAxes2,TimeD,AmplitudeD);
                
                
                app.SampleNumberLabel_2.Text = "Sample Number: " + string(app.sampleNumber);
                app.Label_2.Text = string(app.SampleDataSize(1) +","+ app.filepath);
                
                if sampleNumber <= 1
                    app.PreviousButton_2.Enable = 'off';
                else
                    app.PreviousButton_2.Enable = 'on';
                end
                
                if (endPoint+app.dataPointPerSample)> app.SampleDataSize(1)
                    app.NextButton_2.Enable = 'off';
                else
                    app.NextButton_2.Enable = 'on';
                end


                hold(app.UIAxes2, 'on');
                %ROI first bar
                px = app.ROI_Start_X; % app.ROI_X(1)
                py = 0;
                lineX6 = [px px];
                lineY6 = [py-0.5 py+0.5];
                line(app.UIAxes2, lineX6, lineY6,Color="green");
                
                %ROI Second bar
                px = app.ROI_End_X; % app.ROI_X(2)
                py = 0;
                lineX6 = [px px];
                lineY6 = [py-0.5 py+0.5];
                line(app.UIAxes2, lineX6, lineY6,Color="green");
                
                %ROI MEPA
                [~,startTime]=min(abs(app.TimeD2-app.ROI_X(1)));  % find index where the roi_x(1) is closest
                [~,endTime]=min(abs(app.TimeD2-app.ROI_X(2)));
                stp = startTime;
                cutAmp = app.AmplitudeD2(stp:endTime);
                [~,maxInx] = max(cutAmp);
                [~,minInx] = min(cutAmp);
                maxInx_stp = maxInx+stp-1;
                minInx_stp = minInx+stp-1;
                plot(app.UIAxes2, app.TimeD2(maxInx_stp), app.AmplitudeD2(maxInx_stp), '-o');
                hold(app.UIAxes2, 'on');
                plot(app.UIAxes2, app.TimeD2(minInx_stp), app.AmplitudeD2(minInx_stp), '-o');
                
                hold(app.UIAxes2, 'on');
                plot(app.UIAxes2, TimeD(maxInx_stp), AmplitudeD(maxInx_stp), '-o');
                hold(app.UIAxes2, 'on');
                plot(app.UIAxes2, TimeD(minInx_stp), AmplitudeD(minInx_stp), '-o');
                
                lineX2 = [TimeD(minInx_stp) TimeD(maxInx_stp)];
                lineY2 = [AmplitudeD(maxInx_stp) AmplitudeD(maxInx_stp)];
                line(app.UIAxes2, lineX2,lineY2,Color="red");
                
                lineX3 = [TimeD(minInx_stp) TimeD(maxInx_stp)];
                lineY3 = [AmplitudeD(minInx_stp) AmplitudeD(minInx_stp)];
                line(app.UIAxes2,lineX3, lineY3,Color="red");
                
                lineX4 = [TimeD(maxInx_stp) TimeD(maxInx_stp)];
                lineY4 = [AmplitudeD(minInx_stp) AmplitudeD(maxInx_stp)];
                line(app.UIAxes2,lineX4, lineY4,Color="red");
                
                lineX5 = [TimeD(minInx_stp) TimeD(minInx_stp)];
                lineY5 = [AmplitudeD(minInx_stp) AmplitudeD(maxInx_stp)];
                line(app.UIAxes2,lineX5, lineY5,Color="red");
                
                motorPotential = AmplitudeD(maxInx_stp) - AmplitudeD(minInx_stp);
                txt = ['Potential: ' num2str(motorPotential) ' V'];
                text(app.UIAxes2,TimeD(minInx_stp),AmplitudeD(minInx_stp)-0.10,txt);
                
                txt = ['RMS before stimulation: ' num2str(init_rms) ' V'];
                text(app.UIAxes2,TimeD(100),AmplitudeD(1)+0.5,txt);
                
                after_resp_rms  = rms(AmplitudeD(endTime:length(AmplitudeD)));
                
                Cropped_Resp_onSet_Indx = 0;
                for i = 1:minInx
                    if (cutAmp(minInx+1-i)) >= init_rms
                        break;
                    end
                    Cropped_Resp_onSet_Indx = minInx+1-i;
                end
                
                Cropped_Resp_offSet_Indx = 0;
                for i = maxInx:length(cutAmp)
                    if (cutAmp(i)) <= after_resp_rms
                        break;
                    end
                    Cropped_Resp_offSet_Indx = i;
                end
                
                Resp_onSet_Indx = Cropped_Resp_onSet_Indx+stp-1;
                Resp_offSet_Indx = Cropped_Resp_offSet_Indx+stp-1;
                
                plot(app.UIAxes2, app.TimeD2(Resp_onSet_Indx), app.AmplitudeD2(Resp_onSet_Indx), '-o');
                plot(app.UIAxes2, app.TimeD2(Resp_offSet_Indx), app.AmplitudeD2(Resp_offSet_Indx), '-o');
                
                
                if motorPotential < app.MEPA_Threshold
                    app.MEPAIdentificationMarkerLabel.Text = "No MEPA Detected";
                    app.MEPAIdentificationMarkerLabel.FontColor =  [1 0 1];
                else
                    app.MEPAIdentificationMarkerLabel.Text = "MEPA";
                    app.TestLebel3Label.Text = num2str(motorPotential);
                end
                
                app.MPLabel.Text = num2str(motorPotential);
                app.ThLabel.Text = num2str(app.MEPA_Threshold);
                
            end
        end
        
        
        function [isMEPA_Detected, TrailNumber, MaxuV, MaxTime, MinuV, MinTime, p2p, Duration, OnSet, OffSet] = MEPA_ALL(app,endPoint, startPoint, sampleNumber)
            if app.filepath ~= ""
                %cla(app.UIAxes2);
                app.SampleDataSize = size(app.mep_data);


                Time = app.mep_data(startPoint:endPoint,1);
                Amplitude = app.mep_data(startPoint:endPoint,2);
                
                TimeD =zeros;
                for i = 1:length(Time)
                    TimeD(i) = Time{i};
                    app.TimeD2(i) =Time{i};
                end 
                
                AmplitudeD=zeros;
                for i = 1:length(Amplitude)
                    AmplitudeD(i) = Amplitude{i};
                    app.AmplitudeD2(i) = Amplitude{i};
                    if isnan(AmplitudeD(i))
                        AmplitudeD(i) = AmplitudeD(i-1);
                        app.AmplitudeD2(i) = AmplitudeD{i-1};
                    end
                end 
                
                AmplitudeD = AmplitudeD';
                TimeD = TimeD';


                zeroTime = find(TimeD == 0);
                
                
                %RMS value caluculation
                
                init_rms = rms(AmplitudeD(1:200));
                
               
                % End RMS value caluculation


                %===================== display features =======================
%                 plot(app.UIAxes2,TimeD,AmplitudeD);
%                 
%                 
%                 app.SampleNumberLabel_2.Text = "Sample Number: " + string(app.sampleNumber);
%                 app.Label_2.Text = string(app.SampleDataSize(1) +","+ app.filepath);
                
%                 if sampleNumber <= 1
%                     app.PreviousButton_2.Enable = 'off';
%                 else
%                     app.PreviousButton_2.Enable = 'on';
%                 end
%                 
%                 if (endPoint+app.dataPointPerSample)> app.SampleDataSize(1)
%                     app.NextButton_2.Enable = 'off';
%                 else
%                     app.NextButton_2.Enable = 'on';
%                 end
%                 


                %ROI MEPA
                [~,startTime]=min(abs(app.TimeD2-app.ROI_X(1)));  % find index where the roi_x(1) is closest
                [~,endTime]=min(abs(app.TimeD2-app.ROI_X(2)));
                stp = startTime;
                cutAmp = app.AmplitudeD2(stp:endTime);
                [~,maxInx] = max(cutAmp);
                [~,minInx] = min(cutAmp);
                maxInx_stp = maxInx+stp-1;
                minInx_stp = minInx+stp-1;
                
%                 plot(app.UIAxes2, app.TimeD2(maxInx_stp), app.AmplitudeD2(maxInx_stp), '-o');
%                 hold(app.UIAxes2, 'on');
%                 plot(app.UIAxes2, app.TimeD2(minInx_stp), app.AmplitudeD2(minInx_stp), '-o');
%                 
%                 hold(app.UIAxes2, 'on');
%                 plot(app.UIAxes2, TimeD(maxInx_stp), AmplitudeD(maxInx_stp), '-o');
%                 hold(app.UIAxes2, 'on');
%                 plot(app.UIAxes2, TimeD(minInx_stp), AmplitudeD(minInx_stp), '-o');
%                 
%                 lineX2 = [TimeD(minInx_stp) TimeD(maxInx_stp)];
%                 lineY2 = [AmplitudeD(maxInx_stp) AmplitudeD(maxInx_stp)];
%                 line(app.UIAxes2, lineX2,lineY2,Color="red");
%                 
%                 lineX3 = [TimeD(minInx_stp) TimeD(maxInx_stp)];
%                 lineY3 = [AmplitudeD(minInx_stp) AmplitudeD(minInx_stp)];
%                 line(app.UIAxes2,lineX3, lineY3,Color="red");
%                 
%                 lineX4 = [TimeD(maxInx_stp) TimeD(maxInx_stp)];
%                 lineY4 = [AmplitudeD(minInx_stp) AmplitudeD(maxInx_stp)];
%                 line(app.UIAxes2,lineX4, lineY4,Color="red");
%                 
%                 lineX5 = [TimeD(minInx_stp) TimeD(minInx_stp)];
%                 lineY5 = [AmplitudeD(minInx_stp) AmplitudeD(maxInx_stp)];
%                 line(app.UIAxes2,lineX5, lineY5,Color="red");
                
                
                motorPotential = AmplitudeD(maxInx_stp) - AmplitudeD(minInx_stp);
                
%                 txt = ['Potential: ' num2str(motorPotential) ' V'];
%                 text(app.UIAxes2,TimeD(minInx_stp),AmplitudeD(minInx_stp)-0.10,txt);
%                 
%                 txt = ['RMS before stimulation: ' num2str(init_rms) ' V'];
%                 text(app.UIAxes2,TimeD(100),AmplitudeD(1)+0.5,txt);
                
                after_resp_rms  = rms(AmplitudeD(endTime:length(AmplitudeD)));
                
                Cropped_Resp_onSet_Indx = 0;
                for i = 1:minInx
                    if (cutAmp(minInx+1-i)) >= init_rms
                        break;
                    end
                    Cropped_Resp_onSet_Indx = minInx+1-i;
                end
                
                Cropped_Resp_offSet_Indx = 0;
                for i = maxInx:length(cutAmp)
                    if (cutAmp(i)) <= after_resp_rms
                        break;
                    end
                    Cropped_Resp_offSet_Indx = i;
                end
                
                Resp_onSet_Indx = Cropped_Resp_onSet_Indx+stp-1;
                Resp_offSet_Indx = Cropped_Resp_offSet_Indx+stp-1;
                
%                 plot(app.UIAxes2, app.TimeD2(Resp_onSet_Indx), app.AmplitudeD2(Resp_onSet_Indx), '-o');
%                 plot(app.UIAxes2, app.TimeD2(Resp_offSet_Indx), app.AmplitudeD2(Resp_offSet_Indx), '-o');
                
%                 if motorPotential < 0.2
% %                     app.MEPAIdentificationMarkerLabel.Text = "No MEPA Detected";
% %                     app.MEPAIdentificationMarkerLabel.FontColor =  [1 0 1];
%                     TrailNumber = sampleNumber;
%                     MaxuV = AmplitudeD(maxInx_stp);
%                     MaxTime = TimeD(maxInx_stp);
%                     MinuV = AmplitudeD(minInx_stp);
%                     MinTime = TimeD(minInx_stp);
%                     p2p = motorPotential;
%                     Duration = TimeD(maxInx_stp) - TimeD(minInx_stp);
%                     OnSet = app.TimeD2(Resp_onSet_Indx);
%                     OffSet = app.TimeD2(Resp_offSet_Indx);
%                 else
%                     
%                 end
                    TrailNumber = sampleNumber;
                    MaxuV = AmplitudeD(maxInx_stp);
                    MaxTime = TimeD(maxInx_stp);
                    MinuV = AmplitudeD(minInx_stp);
                    MinTime = TimeD(minInx_stp);
                    p2p = motorPotential;
                    Duration = TimeD(maxInx_stp) - TimeD(minInx_stp);
                    OnSet = app.TimeD2(Resp_onSet_Indx);
                    OffSet = app.TimeD2(Resp_offSet_Indx);
                    
                    if motorPotential < app.MEPA_Threshold
                        isMEPA_Detected = 0;
                        %app.MEPAIdentificationMarkerLabel.Text = "No MEPA Detected";
                        %app.MEPAIdentificationMarkerLabel.FontColor =  [1 0 1];
                        %pause(10);
                    else
                        isMEPA_Detected =1;
                        %app.MEPAIdentificationMarkerLabel.Text = "Detected";
                        %app.MEPAIdentificationMarkerLabel.FontColor =  [1 0 1];
                    end
                    app.MEPAIdentificationMarkerLabel.Text = "Processing "+string(sampleNumber);
                    app.MEPAIdentificationMarkerLabel.FontColor =  [1 0 1];
                    pause(0.1);
                    
                    if sampleNumber >=80
                        app.MEPAIdentificationMarkerLabel.Text = "Process Complete!";
                        app.MEPAIdentificationMarkerLabel.FontColor =  [1 0 0];
                    end
                
            end
        end
        
        
%         function SampleSizeResult = urNeuroApp(SampleSize)
%             
%             SampleSizeResult = SmapleSize;
%         end
        
        function ITTresults = ITTFunction(app)
            if app.filepath ~= ""
                cla(app.UIAxesITT);
                InterpolData = app.ITT_data;
                Datalength = length(app.ITT_data);
                Time = InterpolData(7:Datalength,1);
                EMG = InterpolData(7:Datalength,2);
                Force = InterpolData(7:Datalength,4);
                
                app.ITT_Threshold_Length = Time{length(Time)};
                
                for i = 1:length(Time)
                    TimeD(i) = Time{i};
                end 
                
                for i = 1:length(EMG)
                    EMGD(i) = EMG{i};
                end 
                
                for i = 1:length(Force)
                    ForceD(i) = Force{i};
                end 
                
                EMGD = EMGD';
                TimeD = TimeD';
                ForceD = ForceD';
                
                if app.ITT_isManualThreshold == 0
                    try
                        % find the third most negative peaks for auto taking of threshold level
                        most_lowest_indices = getAscendedSignificantPeaksIndices(EMGD, 500, 1); % Data, min_peak_distance, is_reversed
                                 
                        third_lowest_index = most_lowest_indices(3);
                        
                        %plot(app.UIAxesITT, TimeD(third_lowest_index), EMGD(third_lowest_index), '-o', Color="Black");
                        
                        app.ITT_Threshold = EMGD(third_lowest_index); % update the ITT threshold
                        
                    catch ME
                        disp("Error at auto threshold level");
                    end
                end 
                
                app.ITT_isManualThreshold = 0;


                EMGD_index = zeros;
                j =1;
                peaked =0;
                for i = 7:length(EMGD)
                    if peaked ==0
                        if EMGD(i) <= app.ITT_Threshold
                            EMGD_index(j) = i;
                            j = j+1;
                            peaked =i;
                        end
                    else
                        if i > (peaked+100)
                            peaked = 0;
                        end 
                    end
                end
                
                
                %-------------------Display --------------------%
                plot(app.UIAxesITT, TimeD, EMGD);
                hold(app.UIAxesITT,"on");
                plot(app.UIAxesITT, TimeD, ForceD);


                %Threshold bar
                lineX6 = [0 app.ITT_Threshold_Length];
                lineY6 = [app.ITT_Threshold app.ITT_Threshold];
                line(app.UIAxesITT, lineX6, lineY6,Color="Red");
                
                app.ITT_Th_LevelLabel.Text = "Threshold: "+num2str(app.ITT_Threshold);
                
                %end threshold bar
                
                % Peak ROI 
                app.ITT_firstStimulation = TimeD(EMGD_index(1));
                
                px = app.ITT_firstStimulation + app.ITT_ROI_after_firstStimulation;
                lineX6 = [px px];
                lineY6 = [-1 +1];
                line(app.UIAxesITT, lineX6, lineY6,Color="Green");
                
                [~,ITT_ROI]=min(abs(TimeD-px));
                
                ITT_ROI_after_firstStimulation_indx = ITT_ROI - EMGD_index(1);
                app.ITT_ROI_LevelLabel.Text = "ROI: "+num2str(ITT_ROI_after_firstStimulation_indx);
                % end Peak ROI
                
                offsetVal = sum(ForceD(1:1000))/1000;
                
                app.ITT_BaseOffsetLabel.Text = "Base Offset: " + num2str(offsetVal);
                app.ITT_ControlPeakOffsetLabel.Text = "Ctrl Peak Offset: " + num2str(ForceD(EMGD_index(3)));
                
                MaxITT_1 = max(ForceD(1:EMGD_index(1)));
                MaxITT_2 = max(ForceD(EMGD_index(1)+1000:EMGD_index(2)));
                MaxITT_3 = max(ForceD(EMGD_index(2)+1000:EMGD_index(3)));
                
                MaxITT = max([MaxITT_1, MaxITT_2, MaxITT_3]) - offsetVal;
                
                maxLevel_1 = zeros(length(TimeD),1)+MaxITT_1;
                maxLevel_2 = zeros(length(TimeD),1)+MaxITT_2;
                maxLevel_3 = zeros(length(TimeD),1)+MaxITT_3;
                plot(app.UIAxesITT, TimeD, maxLevel_1);
                plot(app.UIAxesITT, TimeD, maxLevel_2);
                plot(app.UIAxesITT, TimeD, maxLevel_3);
                
                plot(app.UIAxesITT, TimeD(EMGD_index), EMGD(EMGD_index), '-o')

                plot(app.UIAxesITT, TimeD(EMGD_index), ForceD(EMGD_index), '-o');


                ITTidx = 1;
                [~,idx] = max(ForceD(EMGD_index(ITTidx): EMGD_index(ITTidx)+ITT_ROI_after_firstStimulation_indx));
                EMGD_Stimu_inx =  EMGD_index(ITTidx)+idx;
                aa = ForceD(EMGD_Stimu_inx);
                TTA_FirstPeak = aa - offsetVal;
                intTwitchHeight(ITTidx) = abs(ForceD(EMGD_Stimu_inx) - ForceD(EMGD_index(ITTidx)));
                txt = ['iT_1: ' num2str(intTwitchHeight(ITTidx))];
                lineX = [TimeD(EMGD_Stimu_inx) TimeD(EMGD_Stimu_inx)];
                lineY = [ForceD(EMGD_Stimu_inx) ForceD(EMGD_index(ITTidx))];
                
                plot(app.UIAxesITT, TimeD(EMGD_Stimu_inx), ForceD(EMGD_Stimu_inx), '-o');
                text(app.UIAxesITT,TimeD(EMGD_Stimu_inx+10),ForceD(EMGD_Stimu_inx+20)-0.10,txt);
                line(app.UIAxesITT, lineX,lineY,Color="red");
                
                
                ITTidx = 2;
                [~,idx] = max(ForceD(EMGD_index(ITTidx): EMGD_index(ITTidx)+ITT_ROI_after_firstStimulation_indx));
                EMGD_Stimu_inx =  EMGD_index(ITTidx)+idx;
                bb = ForceD(EMGD_Stimu_inx);
                TTA_SecondPeak = bb - offsetVal;
                intTwitchHeight(ITTidx) = abs(ForceD(EMGD_Stimu_inx) - ForceD(EMGD_index(ITTidx)));
                txt = ['iT_2: ' num2str(intTwitchHeight(ITTidx))];
                lineX = [TimeD(EMGD_Stimu_inx) TimeD(EMGD_Stimu_inx)];
                lineY = [ForceD(EMGD_Stimu_inx) ForceD(EMGD_index(ITTidx))];
                
                plot(app.UIAxesITT, TimeD(EMGD_Stimu_inx), ForceD(EMGD_Stimu_inx), '-o');
                text(app.UIAxesITT,TimeD(EMGD_Stimu_inx+10),ForceD(EMGD_Stimu_inx+20)-0.10,txt);
                line(app.UIAxesITT, lineX,lineY,Color="red");
                
                
                ITTidx = 3;
                [~,idx] = max(ForceD(EMGD_index(ITTidx): EMGD_index(ITTidx)+ITT_ROI_after_firstStimulation_indx));
                EMGD_Stimu_inx =  EMGD_index(ITTidx)+idx;
                cc = ForceD(EMGD_Stimu_inx);
                TTA_ThirdPeak = cc - offsetVal;
                intTwitchHeight(ITTidx) = abs(ForceD(EMGD_Stimu_inx) - ForceD(EMGD_index(ITTidx)));
                txt = ['iT_c: ' num2str(intTwitchHeight(ITTidx))];
                lineX = [TimeD(EMGD_Stimu_inx) TimeD(EMGD_Stimu_inx)];
                lineY = [ForceD(EMGD_Stimu_inx) ForceD(EMGD_index(ITTidx))];
                
                plot(app.UIAxesITT, TimeD(EMGD_Stimu_inx), ForceD(EMGD_Stimu_inx), '-o');
                text(app.UIAxesITT,TimeD(EMGD_Stimu_inx+10),ForceD(EMGD_Stimu_inx+20)-0.10,txt);
                line(app.UIAxesITT, lineX,lineY,Color="red");


                html_txt = "";
%                 html_txt = html_txt + newline + ['<a>iTT_1 = ' num2str(intTwitchHeight(1)) ' </a><br>'];
%                 html_txt = html_txt+ newline + ['<a>iTT_2 = ' num2str(intTwitchHeight(2)) ' </a><br>'];
%                 html_txt = html_txt+ newline + ['<a>iTT_3 = ' num2str(intTwitchHeight(3)) ' </a><br>'];
                
                html_txt = html_txt+ newline + ['<a>Max = ' num2str(MaxITT) ' </a><br>'];
                html_txt = html_txt+ '<br>';
                html_txt = html_txt+ newline + ['<a>A (First Peak) = ' num2str(TTA_FirstPeak) ' </a><br>'];
                html_txt = html_txt+ newline + ['<a>B (Second Peak) = ' num2str(TTA_SecondPeak) ' </a><br>'];
                html_txt = html_txt+ newline + ['<a>C (Control Peak) = ' num2str(TTA_ThirdPeak) ' </a><br>'];
                
                html_txt = html_txt+ '<br>';
                html_txt = html_txt+ '<br>';
                html_txt = html_txt+ newline + ['<a>iTT_a (A - Max) =  ' num2str(TTA_FirstPeak-MaxITT) ' </a><br>'];
                html_txt = html_txt+ newline + ['<a>iTT_b (B - Max)= ' num2str(TTA_SecondPeak-MaxITT) ' </a><br>'];
                html_txt = html_txt+ newline + ['<a>iTT_c = ' num2str(intTwitchHeight(3)) ' </a><br>'];
                
                app.ITT_HTML.HTMLSource = html_txt;
                
                if app.ITT_Save_Button_Pressed == 1
                    txt_save = "Max \tFirst Peak \tSecond Peak \tControl Peak \tiTT_A \tiTT_B \tiTT_C ";

                    txt_save = txt_save + newline + [ num2str(MaxITT) ' \t ' num2str(TTA_FirstPeak) '\t' num2str(TTA_SecondPeak) '\t' num2str(TTA_ThirdPeak) '\t' num2str(TTA_FirstPeak-MaxITT) '\t' num2str(TTA_SecondPeak-MaxITT) '\t' num2str(intTwitchHeight(3))];
                    
                    
                    f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                    %[filename,path] = uigetfile('*.txt', 'Open text file','MultiSelect','on');
                    [file_name, File_Path]= uiputfile("ITT_Result.txt");
                    delete(f);
                    
                    if exist(string(File_Path), 'file')
                        fileID = fopen(fullfile(File_Path,file_name),'w');
                        fprintf(fileID,txt_save);
                        fclose(fileID);
                        
                    end
                    
                end
                app.ITT_Save_Button_Pressed = 0;
                
            end
        end
        
        function resultTTACSP = TTACSPFunction(app)
            %BeepThreshold = 2; % 0.04
            cla(app.UIAxesTTACSP);
            app.CSP_DurationLabel.Visible = "off";

            startPoint = app.TTA_IntervalIndx(app.TTA_TrialNumber)+6;
            endPoint = app.TTA_IntervalIndx(app.TTA_TrialNumber+1)-1;
            SampleLength = endPoint - startPoint+1;
            
            %Foot_Padel = app.TTA_data(startPoint:endPoint,5);
            TTACSP_EMG = app.TTA_data(startPoint:endPoint,2);
            Time = app.TTA_data(startPoint:endPoint,1);
            
            
            TimeD =zeros;
            try
                for i = 1:SampleLength %length(Time)
                    TimeD(i) = Time{i};
                end 
            catch
                warning('TTA: TimeD - 1145');
            end 
            
            TTACSP_EMGD=zeros;
            try
                for i = 1:SampleLength %length(Foot_Padel)
                
                    TTACSP_EMGD(i) = TTACSP_EMG{i};
                    if isnan(TTACSP_EMGD(i))
                        TTACSP_EMGD(i) = TTACSP_EMGD(i-1);
                    end
                end
            catch
                warning('TTA: Foot_PadelD - 1154');
            end 
            
            
            TTACSP_EMG =TTACSP_EMGD';
            Time = TimeD';
            
            % Logical array where values are greater than ThresholdTTACSP
            aboveThree = TTACSP_EMG > app.ThresholdTTACSP;
            
            % Initialize variables
            lastValues = zeros;
            lastIndices = zeros;
            currentIndex = 1;
            % Initialize index for storing results
            resultIndex = 1;
            
            % Iterate through the array
            while currentIndex <= length(TTACSP_EMG)
                if aboveThree(currentIndex)
                    % Find the end of the current sequence
                    endIndex = currentIndex;
                    while endIndex <= length(TTACSP_EMG) && aboveThree(endIndex)
                        endIndex = endIndex + 1;
                    end
                    % Append the last value of the sequence
                    lastValues(resultIndex) = TTACSP_EMG(endIndex - 1);
                    lastIndices(resultIndex) = endIndex - 1;
                    resultIndex = resultIndex + 1;
                    % Update currentIndex to the end of the current sequence
                    currentIndex = endIndex;
                else
                    currentIndex = currentIndex + 1;
                end
            end
            %disp(lastIndices);
            
            newMaxIndices = findClusterMaxIndices(TTACSP_EMG,app.ThresholdTTACSP,1500); % see the findClusterMaxIndices.m file
            app.globalLastIndices = newMaxIndices;
            
            %app.globalLastIndices = lastIndices;
            app.glboalTime = Time;
            app.globalTTACSP_EMG = TTACSP_EMG;
            app.TTA_TotalSplit = length(app.globalLastIndices);
            app.ThresholdEditFieldTTACSP.Value = string(app.ThresholdTTACSP);
                 

            try
                hold(app.UIAxesTTACSP,"on");
                plot(app.UIAxesTTACSP, Time, TTACSP_EMG);
                %plot(app.UIAxesTTACSP, Time(lastIndices), TTACSP_EMG(lastIndices), '-o');
                plot(app.UIAxesTTACSP, Time(newMaxIndices), TTACSP_EMG(newMaxIndices), '-o');
%                 plot(app.UIAxesTTACSP, Time, refLine);
%                 plot(app.UIAxesTTACSP, Time, PadelThresholdLine);
%                 if ~isnan(Beep)
%                    plot(app.UIAxesTTACSP, Time, Beep);
%                    plot(app.UIAxesTTACSP, Time(BeepPosition), Beep(BeepPosition), '-o');
%                     plot(app.UIAxesTTACSP, Time(ResponsePosition), Foot_Padel(ResponsePosition), '-o');
%                     plot(app.UIAxesTTACSP, Time(BeepPositionBefResp), Beep(BeepPositionBefResp)+10, '-o');
%                 end
            catch
                warning('TTACSP: Display - 1271');
            end
            
            
        end
        
        function rst = splittedCSP(app)
            cla(app.UIAxesTTACSP);
            if app.SplitButtonPressed == 1
                app.TTA_SplitNumber = 1;
                app.SplitButtonPressed = 0;
                app.NextButton_splittedCSP.Enable = "on";
            end
            
            app.SplitNumberLabel.Text = string(app.TTA_SplitNumber);
            window  = app.globalLastIndices(app.TTA_SplitNumber);
            firstWindowPoint = window-app.LeftboundaryTTACSP;
            lastWindowPoint = window+app.RightboundaryTTACSP;
            
            if (lastWindowPoint) > length(app.globalTTACSP_EMG)
                lastWindowPoint = length(app.globalTTACSP_EMG);
            end
            if firstWindowPoint < 1
                firstWindowPoint = 1;
            end
            
            wTime = app.glboalTime(firstWindowPoint:lastWindowPoint);
            wTTACSP_EMG = app.globalTTACSP_EMG(firstWindowPoint:lastWindowPoint);
            hold(app.UIAxesTTACSP,"on");
            plot(app.UIAxesTTACSP, wTime, wTTACSP_EMG);
            plot(app.UIAxesTTACSP, wTime(app.LeftboundaryTTACSP), wTTACSP_EMG(app.LeftboundaryTTACSP), '-o');
            
            hpwTTACSP_EMG = highpass(wTTACSP_EMG,app.TTACSPhpFreq,1e3);
            
            rectHPdata = zeros;
                for i = 1:length(hpwTTACSP_EMG)
                    if hpwTTACSP_EMG(i)>= mean(hpwTTACSP_EMG)
                        rectHPdata(i) = mean(hpwTTACSP_EMG); %rectified High-Pass Data
                    else
                        rectHPdata(i) = hpwTTACSP_EMG(i);
                    end 
                end 
            
            plot(app.UIAxesTTACSP, wTime, rectHPdata+0);
            
            pointToSearchCSP = 400;
            
            avgSignalAfterCSP = min(rectHPdata(pointToSearchCSP:end))*0.50;
            
            meanSignalAfterCSP_original = mean(rectHPdata(pointToSearchCSP:end));
            minSignalAfterCSP_original = min(rectHPdata(pointToSearchCSP:end));
            middleLabel = ((abs(minSignalAfterCSP_original - meanSignalAfterCSP_original))/2)+minSignalAfterCSP_original;
            %avgSignalAfterCSP_original = min(wTTACSP_EMG(pointToSearchCSP:end))*0.50;
            
            try
                plot(app.UIAxesTTACSP, wTime(pointToSearchCSP), rectHPdata(pointToSearchCSP), '-o', color ='black');
            
                %plot(app.UIAxesTTACSP, wTime, (zeros(1,length(wTime))+avgSignalAfterCSP_original), color ='red'); 
                plot(app.UIAxesTTACSP, wTime, (zeros(1,length(wTime))+meanSignalAfterCSP_original), color ='yellow');
                plot(app.UIAxesTTACSP, wTime, (zeros(1,length(wTime))+middleLabel), color ='yellow');
                %plot(app.UIAxesTTACSP, wTime, (zeros(1,length(wTime))+avgSignalAfterCSP+2), color ='red');
            catch ME
                
            end
            
            try
                TTACSP_offsetPoint = 1;
                for i = pointToSearchCSP:length(rectHPdata)
                    if rectHPdata(i)< middleLabel
                        TTACSP_offsetPoint = i;
                        break;
                    end 
                end
                TTACSP_onsetPoint = 1;
                for i = pointToSearchCSP:-1:1
                    if rectHPdata(i)<= minSignalAfterCSP_original %middleLabel
                        TTACSP_onsetPoint = i;
                        break;
                    end 
                end
                
            catch ME
                
            end
            
            try
                plot(app.UIAxesTTACSP, wTime(TTACSP_offsetPoint), wTTACSP_EMG(TTACSP_offsetPoint), '-o');
                plot(app.UIAxesTTACSP, wTime(TTACSP_onsetPoint), wTTACSP_EMG(TTACSP_onsetPoint), '-o');
                lineX = [wTime(TTACSP_onsetPoint) wTime(TTACSP_offsetPoint)];
                lineY = [0 0];
                line(app.UIAxesTTACSP, lineX, lineY, Color="red");
                
                app.CSP_DurationLabel.Visible = "on";
                app.CSP_DurationLabel.Text = "CSP_Duration:" + string(wTime(TTACSP_offsetPoint) - wTime(TTACSP_onsetPoint));
                
            catch ME
                
            end


            app.LeftboundaryEditField.Value = app.LeftboundaryTTACSP;
            app.RightboundaryEditField.Value = app.RightboundaryTTACSP;
            app.HighpassFreqEditFieldTTACSP.Value = app.TTACSPhpFreq;
        end
        
        function rstTTACSPanalysis = splittedCSPAnalysis
            %hpData = highpass(AmplitudeD,app.freqPass,1e3);
        end
        
        function resultsTTA = TTAFunction(app)
            % app.TTA_TrialNumber = 3;
            %PadelThreshold = 5;
            BeepThreshold = 2; % 0.04
            cla(app.UIAxesTTA);
            cla(app.TTA_BeepVsPos);
            cla(app.TTA_RespVsBeep);
            

            startPoint = app.TTA_IntervalIndx(app.TTA_TrialNumber)+6;
            endPoint = app.TTA_IntervalIndx(app.TTA_TrialNumber+1)-1;
            SampleLength = endPoint - startPoint+1;
            
            Foot_Padel = app.TTA_data(startPoint:endPoint,5);
            Beep = app.TTA_data(startPoint:endPoint,6);
            Time = app.TTA_data(startPoint:endPoint,1);
            
            
            %----------------------------------%
            
            TimeD =zeros;
            try
                for i = 1:SampleLength %length(Time)
                    TimeD(i) = Time{i};
                end 
            catch
                warning('TTA: TimeD - 1145');
            end 
            
            Foot_PadelD=zeros;
            try
                for i = 1:SampleLength %length(Foot_Padel)
                
                    Foot_PadelD(i) = Foot_Padel{i};
                    if isnan(Foot_PadelD(i))
                        Foot_PadelD(i) = Foot_PadelD(i-1);
                    end
                end
            catch
                warning('TTA: Foot_PadelD - 1154');
            end 
            
            BeepD = zeros;
            try
                for i = 1:SampleLength %length(Beep)
                
                    BeepD(i) = Beep{i};
                    if isnan(BeepD(i))
                        BeepD(i) = Foot_PadelD(i-1);
                    end
                end
            catch
                warning('TTA: BeepD - 1167');
            end 
            
            Foot_PadelD = Foot_PadelD * app.TTA_PedalGain + app.TTA_PedalOffset;
            BeepD = BeepD * app.TTA_BeepGain;
            
            if app.TTA_AutoThresholdCheckBox.Value ==1
                app.TTA_PadelThreshold = mean(Foot_PadelD) + (max(Foot_PadelD)-mean(Foot_PadelD))*0.3; % auto Threshold for pedal data added 13.02.2024
                BeepThreshold = mean(BeepD) + (max(BeepD)-mean(BeepD))*0.3; % auto thereshold for beep added on 13.02.2024
                app.PedalThresholdEditField.Value = app.TTA_PadelThreshold;
            end
            
            %app.TTA_PadelThreshold = mean(Foot_PadelD) + (max(Foot_PadelD)-mean(Foot_PadelD))*0.3; % auto Threshold for pedal data added 13.02.2024
            %BeepThreshold = mean(BeepD) + (max(BeepD)-mean(BeepD))*0.3; % auto thereshold for beep added on 13.02.2024
            
            
            refLine = zeros(1, SampleLength)+BeepThreshold;
            PadelThresholdLine = zeros(1, SampleLength)+app.TTA_PadelThreshold;


            BeepPosition = zeros;
            BeepDetected = 0;
            ResponsePosition =  zeros;
            ResponseDetected = 0;
            
            BeepPositionBefResp = zeros;
            LastBeepPosition = 0;
            j=1;
            k=1;
            Timer =0;
            try
                for i = 1:SampleLength %length(Beep)
                    if BeepDetected == 0
                        if BeepD(i) > BeepThreshold
                            BeepPosition(j) = i;
                            LastBeepPosition = i;
                            BeepDetected = 1;
                            j = j+1;
                            ResponseDetected = 0;
                        end
                    else
                        if Timer >= 3000
                            BeepDetected =0;
                            
                            Timer = 0;
                            BeepPosition(j) = i;
                            j = j+1;
                        end
                        if ResponseDetected ==0
                            if Foot_PadelD(i)>= app.TTA_PadelThreshold
                                ResponseDetected = 1;
                                ResponsePosition(k) = i;
                                BeepPositionBefResp(k) = LastBeepPosition; 
                                k=k+1;
                            end
                        end 
                        Timer = Timer+1;
                    end
                end 
            catch
                warning('TTA: Extaction - 1193');
            end 
            
            Foot_Padel =Foot_PadelD';
            Time = TimeD';
            Beep = BeepD';
            
            
            % Foot_Padel =str2double(Foot_Padel);
            % Time = str2double(Time);
            
            OddBeepPosition = BeepPosition(1:2:end);
            
            
            Beep_to_Resp = zeros;
            beep_number = zeros;
            try
                for i = 1:length(BeepPositionBefResp)
                    Beep_to_Resp(i) = Time(ResponsePosition(i)) - Time(BeepPositionBefResp(i));
                    beep_number(i) = i;
                end 
            catch
                warning('TTA: Beep_to_Resp - 1167');
            end 
            
            app.ITA_Beep_to_Resp = Beep_to_Resp;
            app.ITA_BeepPositionBefResp = BeepPositionBefResp;
            app.ITA_beep_number = beep_number;


            try
                hold(app.UIAxesTTA,"on");
                plot(app.UIAxesTTA, Time, Foot_Padel);
                plot(app.UIAxesTTA, Time, refLine);
                plot(app.UIAxesTTA, Time, PadelThresholdLine);
                if ~isnan(Beep)
                   plot(app.UIAxesTTA, Time, Beep);
                   %plot(app.UIAxesTTA, Time(BeepPosition), Beep(BeepPosition), '-o');
                    %plot(app.UIAxesTTA, Time(ResponsePosition), Foot_Padel(ResponsePosition), '-o');
                    %plot(app.UIAxesTTA, Time(BeepPositionBefResp), Beep(BeepPositionBefResp)+10, '-o');
                end
            catch
                warning('TTA: Display - 1255');
            end
            
            app.TrialNoLabel.Text = "Trial No.: " + string(app.TTA_TrialNumber);


%             f1 = figure('Position', [100 100 570 420]);
%             
%             plot(app.ITA_BeepPositionBefResp ,app.ITA_beep_number );
% 
%             
%             figure;
%             plot(app.ITA_beep_number , app.ITA_Beep_to_Resp);
            
            app.TTA_BeepVsPos.Visible = 'on';
            plot(app.TTA_BeepVsPos, app.ITA_BeepPositionBefResp ,app.ITA_beep_number );
            xlabel(app.TTA_BeepVsPos,'Beep Position Before Response');
            ylabel(app.TTA_BeepVsPos,'Beep Number') ;
            
            app.TTA_RespVsBeep.Visible = 'on';
            plot(app.TTA_RespVsBeep, app.ITA_beep_number , app.ITA_Beep_to_Resp);
            xlabel(app.TTA_RespVsBeep,'Beep Number');
            ylabel(app.TTA_RespVsBeep,'Response Time') ;
            
            app.ShowMoreButton.Enable = 'on';
        end
        
        
        function AppConfigSave(app)
            fileID = fopen('Appconfig.txt','w');
            fileValue = string(app.freqPass)+"\tFrqPass"+"\n"+string(app.thresholdLevel)+"\tThLvl"+"\n"+string(app.spSearchStartPoint)+"\tSPsearch";
            fileValue = append( fileValue+ "\n" + string(app.ROI_Start_X) + "\t" + string(app.ROI_End_X) + "\tROI");
            fileValue = append(fileValue+ "\n" +string(app.dataPointPerSample) + "\tSampleSize");
            fileValue = append(fileValue+ "\n" +string(app.ITT_Threshold) + "\tITT_Threshold");
            fileValue = append(fileValue+ "\n" +string(app.ITT_ROI_after_firstStimulation) + "\tITT_ROI_after_firstStimulation");
            fileValue = append(fileValue+ "\n" +string(app.MEPA_Threshold) + "\tMEPA_Threshold");
            
            % Ensure the file path's backslashes are correctly represented
            lastPathCorrected = strrep(string(app.lastPath), '\', '\\');
            lastPathForSaveCorrected = strrep(string(app.lastPathForSave), '\', '\\');
            
            fileValue = append(fileValue+ "\n" +lastPathCorrected + "\tLast Filepath");
            fileValue = append(fileValue+ "\n" +lastPathForSaveCorrected + "\tLast_Filepath_for_save");
            
            %fileValue = ;
            fprintf(fileID,fileValue);
            fclose(fileID);
            
            app.SSValLabel.Text = string(app.dataPointPerSample +1);
        end
        
        function myprogress1(app)
            %fig = uifigure;
            app.TTA_progressdialog = uiprogressdlg(app.URNeuroAppUIFigure,'Title','Please Wait',...
                'Message','Opening the application');
            pause(.5)
        
            % Perform calculations
            % ...
            app.TTA_progressdialog.Value = .33; 
            app.TTA_progressdialog.Message = 'Loading your data';
            pause(1)
        
            % Perform calculations
            % ...
            app.TTA_progressdialog.Value = .67;
            app.TTA_progressdialog.Message = 'Processing the data';
            pause(1)
            
            % Finish calculations
            % ...
            app.TTA_progressdialog.Value = 1;
            app.TTA_progressdialog.Message = 'Finishing';
            pause(1)
        
            % Close dialog box
            close(app.TTA_progressdialog);
        end
        
        function MReflexFunction(app)
            cla(app.UIAxesMReflex);
            cla(app.UIAxesMRoutput);
            startSample = app.MReflex_startSample;
            endSample = app.MReflex_endSample;
            
            for SampleNumber = startSample : endSample
                [TimeD, AmplitudeD, VoltageD] = xReflexProcessAll(app, app.MReflex_data, app.MReflex_IntervalIndx, SampleNumber);
                hold(app.UIAxesMReflex,"on");
                plot(app.UIAxesMReflex, TimeD, AmplitudeD);
                plot(app.UIAxesMReflex, TimeD, ((VoltageD.*0.03)-4));
                
            end 
            
                       
        end
        
        function MreflecxROIfunction(app)
            % ---ROI implementation:
            [TimeD, AmplitudeD, VoltageD] = xReflexProcessAll(app, app.MReflex_data, app.MReflex_IntervalIndx, 1);
            % Set up figure handle visibility, run ginput, and return state
            fhv = app.URNeuroAppUIFigure.HandleVisibility;        % Current status
            app.URNeuroAppUIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
            set(0, 'CurrentFigure', app.URNeuroAppUIFigure)       % Make fig current
            
            %--- First ROI ---
            cnt = 0;
            roi_x=zeros;
            roi_y=zeros;
            while 1
                [px,py,buton]= ginput(1);
                plot(px,py,'*');
                lineX6 = [px px];
                lineY6 = [py-0.5 py+0.5];
                line(lineX6, lineY6,Color="red");
                if buton == 1
                    cnt = cnt+1;
                    roi_x(cnt)=px;
                    roi_y(cnt)=py;
                    %buton =0;
                    if cnt ==2 
                        break;
                    end
                end
            end
            
            
            [~,Cropped_startTime_1]=min(abs(TimeD-roi_x(1)));  % find index where the roi_x(1) is closest
            [~,Cropped_endTime_1]=min(abs(TimeD-roi_x(2)));
            stp = Cropped_startTime_1;
            CroppedAmp_1 = AmplitudeD(stp:Cropped_endTime_1);
            [~,maxInx] = max(CroppedAmp_1);
            [~,minInx] = min(CroppedAmp_1);
            Cropped_maxInx_stp_1 = maxInx+stp-1;
            Cropped_minInx_stp_1 = minInx+stp-1;
            
            
            plot(app.UIAxesMReflex,TimeD(Cropped_maxInx_stp_1), AmplitudeD(Cropped_maxInx_stp_1), '-o');
            hold(app.UIAxesMReflex, "on");
            plot(app.UIAxesMReflex,TimeD(Cropped_minInx_stp_1), AmplitudeD(Cropped_minInx_stp_1), '-o');
            
            
%             %--- Second ROI ---
%             cnt = 0;
%             roi_x=zeros;
%             roi_y=zeros;
%             while 1
%                 [px,py,buton]= ginput(1);
%                 plot(px,py,'*');
%                 lineX6 = [px px];
%                 lineY6 = [py-0.5 py+0.5];
%                 line(lineX6, lineY6,Color="Green");
%                 if buton == 1
%                     cnt = cnt+1;
%                     roi_x(cnt)=px;
%                     roi_y(cnt)=py;
%                     %buton =0;
%                     if cnt ==2 
%                         break;
%                     end
%                 end
%             end
%             
%             
%             [~,Cropped_startTime_2]=min(abs(TimeD-roi_x(1)));  % find index where the roi_x(1) is closest
%             [~,Cropped_endTime_2]=min(abs(TimeD-roi_x(2)));
%             stp = Cropped_startTime_2;
%             CroppedAmp_2 = AmplitudeD(stp:Cropped_endTime_2);
%             [maxVal,maxInx] = max(CroppedAmp_2);
%             [minVal,minInx] = min(CroppedAmp_2);
%             Cropped_maxInx_stp_2 = maxInx+stp-1;
%             Cropped_minInx_stp_2 = minInx+stp-1;
%             
%             
%             plot(app.UIAxesHReflex,TimeD(Cropped_maxInx_stp_2), AmplitudeD(Cropped_maxInx_stp_2), '-o');
%             plot(app.UIAxesHReflex,TimeD(Cropped_minInx_stp_2), AmplitudeD(Cropped_minInx_stp_2), '-o');
%             
            app.URNeuroAppUIFigure.HandleVisibility = fhv;        % return original state
            
            
            Cropped_1_Peak2Peak_All = zeros;
            Cropped_2_Peak2Peak_All = zeros;
            Positive_Peak_volt_All = zeros;
            Negative_Peak_volt_All =  zeros;
            Cropped_1_Peak = zeros;
            Cropped_1_Bottom = zeros;
            startSample = app.MReflex_startSample;
            endSample = app.MReflex_endSample;
            for SampleNumber = startSample : endSample
                [TimeD, AmplitudeD, VoltageD] = xReflexProcessAll(app, app.MReflex_data, app.MReflex_IntervalIndx, SampleNumber);
                [Cropped_1_Peak(SampleNumber) , Cropped_1_Bottom(SampleNumber), Cropped_1_Peak2Peak] = Trial_Cropped_1(AmplitudeD,Cropped_startTime_1, Cropped_endTime_1);
                %[Cropped_2_Peak, Cropped_2_Bottom, Cropped_2_Peak2Peak] = Trial_Cropped_2(AmplitudeD,Cropped_startTime_2, Cropped_endTime_2);
                Cropped_1_Peak2Peak_All(SampleNumber) = Cropped_1_Peak2Peak;
                %Cropped_2_Peak2Peak_All(SampleNumber) = Cropped_2_Peak2Peak;
                %figure;
                %plot(TimeD, AmplitudeD);
                %hold on;
                %plot(TimeD(Cropped_1_Peak),AmplitudeD(Cropped_1_Peak), '-o');
                %plot(TimeD(Cropped_1_Bottom),AmplitudeD(Cropped_1_Bottom), '-o');
                %plot(TimeD(Cropped_2_Peak),AmplitudeD(Cropped_2_Peak), '-o');
                %plot(TimeD(Cropped_2_Bottom),AmplitudeD(Cropped_2_Bottom), '-o');
                
                [Positive_Peak_volt, Negative_Peak_volt] = voltagePeakNbottom(VoltageD);
                Positive_Peak_volt_All(SampleNumber) = Positive_Peak_volt;
                Negative_Peak_volt_All(SampleNumber) = Negative_Peak_volt;
                
                %plot(TimeD, ((VoltageD.*0.03)-4));
                %hold off;
            end 
            
            
            hold(app.UIAxesMRoutput,"on");
            plot(app.UIAxesMRoutput, Cropped_1_Peak2Peak_All, 'LineWidth',2, 'DisplayName','Peak-to-Peak');
            %plot(app.UIAxesMRoutput, Cropped_2_Peak2Peak_All, 'LineWidth',2, 'DisplayName','Peak-to-Peak - Second Peak');
            
            if max(abs(Positive_Peak_volt_All)) > max(abs(Negative_Peak_volt_All))
                Peak_volt_All = Positive_Peak_volt_All;
            else 
                Peak_volt_All = abs(Negative_Peak_volt_All);
            end
            plot(app.UIAxesMRoutput, Peak_volt_All*0.1,'LineWidth',2, 'DisplayName','Voltage');
            
            plot(app.UIAxesMRoutput, Cropped_1_Peak, 'LineWidth',2, 'DisplayName','Peak');
            plot(app.UIAxesMRoutput, Cropped_1_Bottom, 'LineWidth',2, 'DisplayName','min');
            
            app.MaxLabel.Text = string("Max: " + max(Cropped_1_Peak));
            app.MinLabel.Text = string("Min: " + min(Cropped_1_Bottom));
            
            %app.HReflex_Cropped_1_Peak2Peak_All = Cropped_1_Peak2Peak_All;
            %app.HReflex_Cropped_2_Peak2Peak_All = Cropped_2_Peak2Peak_All;
            %app.HReflex_Peak_volt_All = Peak_volt_All*0.1;
            
            legend(app.UIAxesMRoutput);


            %----Mrf functions 
            function [Positive_Peak_volt, Negative_Peak_volt] = voltagePeakNbottom(VoltageD)
                Positive_Peak_volt = max(VoltageD);
                Negative_Peak_volt = min(VoltageD);
            end 
            
            
            function [Cropped_1_Peak, Cropped_1_Bottom, Cropped_1_Peak2Peak] = Trial_Cropped_1(AmplitudeD,Cropped_startTime_1, Cropped_endTime_1)
                CroppedAmp_1 = AmplitudeD(Cropped_startTime_1:Cropped_endTime_1);
                [maxVal,maxInx] = max(CroppedAmp_1);
                [minVal,minInx] = min(CroppedAmp_1);
                Cropped_1_Peak2Peak = abs(maxVal) + abs(minVal);
                Cropped_1_Peak = max(CroppedAmp_1); %maxInx+Cropped_startTime_1-1;
                Cropped_1_Bottom = min(CroppedAmp_1); %minInx+Cropped_startTime_1-1;  
            end 
            
            function [Cropped_2_Peak, Cropped_2_Bottom, Cropped_2_Peak2Peak] = Trial_Cropped_2(AmplitudeD,Cropped_startTime_2, Cropped_endTime_2)
                CroppedAmp_2 = AmplitudeD(Cropped_startTime_2:Cropped_endTime_2);
                [maxVal,maxInx] = max(CroppedAmp_2);
                [minVal,minInx] = min(CroppedAmp_2);
                Cropped_2_Peak2Peak = abs(maxVal) + abs(minVal);
                Cropped_2_Peak = maxInx+Cropped_startTime_2-1;
                Cropped_2_Bottom = minInx+Cropped_startTime_2-1;  
            end 
        end


        %--------------------   H-Reflex Function   -----------------%
        
        function HReflexFunction(app)
            cla(app.UIAxesHReflex);
            cla(app.UIAxesHRoutput);
            cla(app.UIAxesHRAslope);
            startSample = app.HReflex_startSample;
            endSample = app.HReflex_endSample;
            
            for SampleNumber = startSample : endSample
                [TimeD, AmplitudeD, VoltageD] = xReflexProcessAll(app, app.HReflex_data, app.HReflex_IntervalIndx, SampleNumber);
                hold(app.UIAxesHReflex,"on");
                plot(app.UIAxesHReflex, TimeD, AmplitudeD);
                plot(app.UIAxesHReflex, TimeD, ((VoltageD.*0.03)-4));
                
            end 
            
                       
        end
        
        function hrf2 = HreflecxROIfunction(app)
            % ---ROI implementation:
            [TimeD, AmplitudeD, VoltageD] = xReflexProcessAll(app, app.HReflex_data, app.HReflex_IntervalIndx, 1);
            % Set up figure handle visibility, run ginput, and return state
            fhv = app.URNeuroAppUIFigure.HandleVisibility;        % Current status
            app.URNeuroAppUIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
            set(0, 'CurrentFigure', app.URNeuroAppUIFigure)       % Make fig current
            
            %--- First ROI ---
            cnt = 0;
            roi_x=zeros;
            roi_y=zeros;
            while 1
                [px,py,buton]= ginput(1);
                plot(px,py,'*');
                lineX6 = [px px];
                lineY6 = [py-0.5 py+0.5];
                line(lineX6, lineY6,Color="red");
                if buton == 1
                    cnt = cnt+1;
                    roi_x(cnt)=px;
                    roi_y(cnt)=py;
                    %buton =0;
                    if cnt ==2 
                        break;
                    end
                end
            end
            
            
            [~,Cropped_startTime_1]=min(abs(TimeD-roi_x(1)));  % find index where the roi_x(1) is closest
            [~,Cropped_endTime_1]=min(abs(TimeD-roi_x(2)));
            stp = Cropped_startTime_1;
            CroppedAmp_1 = AmplitudeD(stp:Cropped_endTime_1);
            [~,maxInx] = max(CroppedAmp_1);
            [~,minInx] = min(CroppedAmp_1);
            Cropped_maxInx_stp_1 = maxInx+stp-1;
            Cropped_minInx_stp_1 = minInx+stp-1;
            
            
            plot(app.UIAxesHReflex,TimeD(Cropped_maxInx_stp_1), AmplitudeD(Cropped_maxInx_stp_1), '-o');
            hold(app.UIAxesHReflex, "on");
            plot(app.UIAxesHReflex,TimeD(Cropped_minInx_stp_1), AmplitudeD(Cropped_minInx_stp_1), '-o');
            
            
            %--- Second ROI ---
            cnt = 0;
            roi_x=zeros;
            roi_y=zeros;
            while 1
                [px,py,buton]= ginput(1);
                plot(px,py,'*');
                lineX6 = [px px];
                lineY6 = [py-0.5 py+0.5];
                line(lineX6, lineY6,Color="Green");
                if buton == 1
                    cnt = cnt+1;
                    roi_x(cnt)=px;
                    roi_y(cnt)=py;
                    %buton =0;
                    if cnt ==2 
                        break;
                    end
                end
            end
            
            
            [~,Cropped_startTime_2]=min(abs(TimeD-roi_x(1)));  % find index where the roi_x(1) is closest
            [~,Cropped_endTime_2]=min(abs(TimeD-roi_x(2)));
            stp = Cropped_startTime_2;
            CroppedAmp_2 = AmplitudeD(stp:Cropped_endTime_2);
            [maxVal,maxInx] = max(CroppedAmp_2);
            [minVal,minInx] = min(CroppedAmp_2);
            Cropped_maxInx_stp_2 = maxInx+stp-1;
            Cropped_minInx_stp_2 = minInx+stp-1;
            
            
            plot(app.UIAxesHReflex,TimeD(Cropped_maxInx_stp_2), AmplitudeD(Cropped_maxInx_stp_2), '-o');
            plot(app.UIAxesHReflex,TimeD(Cropped_minInx_stp_2), AmplitudeD(Cropped_minInx_stp_2), '-o');
            
            app.URNeuroAppUIFigure.HandleVisibility = fhv;        % return original state
            
            
            Cropped_1_Peak2Peak_All = zeros;
            Cropped_2_Peak2Peak_All = zeros;
            Positive_Peak_volt_All = zeros;
            Negative_Peak_volt_All =  zeros;
            startSample = app.HReflex_startSample;
            endSample = app.HReflex_endSample;
            for SampleNumber = startSample : endSample
                [TimeD, AmplitudeD, VoltageD] = xReflexProcessAll(app, app.HReflex_data, app.HReflex_IntervalIndx, SampleNumber);
                [Cropped_1_Peak, Cropped_1_Bottom, Cropped_1_Peak2Peak] = Trial_Cropped_1(AmplitudeD,Cropped_startTime_1, Cropped_endTime_1);
                [Cropped_2_Peak, Cropped_2_Bottom, Cropped_2_Peak2Peak] = Trial_Cropped_2(AmplitudeD,Cropped_startTime_2, Cropped_endTime_2);
                Cropped_1_Peak2Peak_All(SampleNumber) = Cropped_1_Peak2Peak;
                Cropped_2_Peak2Peak_All(SampleNumber) = Cropped_2_Peak2Peak;
                %figure;
                %plot(TimeD, AmplitudeD);
                %hold on;
                %plot(TimeD(Cropped_1_Peak),AmplitudeD(Cropped_1_Peak), '-o');
                %plot(TimeD(Cropped_1_Bottom),AmplitudeD(Cropped_1_Bottom), '-o');
                %plot(TimeD(Cropped_2_Peak),AmplitudeD(Cropped_2_Peak), '-o');
                %plot(TimeD(Cropped_2_Bottom),AmplitudeD(Cropped_2_Bottom), '-o');
                
                [Positive_Peak_volt, Negative_Peak_volt] = voltagePeakNbottom(VoltageD);
                Positive_Peak_volt_All(SampleNumber) = Positive_Peak_volt;
                Negative_Peak_volt_All(SampleNumber) = Negative_Peak_volt;
                
                %plot(TimeD, ((VoltageD.*0.03)-4));
                %hold off;
            end 
            
            
            hold(app.UIAxesHRoutput,"on");
            plot(app.UIAxesHRoutput, Cropped_1_Peak2Peak_All, 'LineWidth',2, 'DisplayName','Peak-to-Peak - First Peak');
            plot(app.UIAxesHRoutput, Cropped_2_Peak2Peak_All, 'LineWidth',2, 'DisplayName','Peak-to-Peak - Second Peak');


            if max(abs(Positive_Peak_volt_All)) > max(abs(Negative_Peak_volt_All))
                Peak_volt_All = Positive_Peak_volt_All;
            else 
                Peak_volt_All = abs(Negative_Peak_volt_All);
            end
            plot(app.UIAxesHRoutput, Peak_volt_All*0.1,'LineWidth',2, 'DisplayName','Voltage (mv*0.1)');
            
            app.HReflex_Cropped_1_Peak2Peak_All = Cropped_1_Peak2Peak_All;
            app.HReflex_Cropped_2_Peak2Peak_All = Cropped_2_Peak2Peak_All;
            app.HReflex_Peak_volt_All = Peak_volt_All*1;   % why multiplied with 0.1? 0.1mv is equal to 1amp taken!
            
            legend(app.UIAxesHRoutput);
            
            HR_VHM = [app.HReflex_Peak_volt_All; app.HReflex_Cropped_1_Peak2Peak_All; app.HReflex_Cropped_2_Peak2Peak_All];
            HR_VHM = HR_VHM';
            app.HR_VHM_shorted =  sortrows(HR_VHM, 1);
            
            
            hold(app.UIAxesHRAslope, "on");
            plot(app.UIAxesHRAslope, app.HR_VHM_shorted(:,1), app.HR_VHM_shorted(:,2), 'LineWidth',2, 'DisplayName','Peak-to-Peak - First Peak');
            plot(app.UIAxesHRAslope, app.HR_VHM_shorted(:,1), app.HR_VHM_shorted(:,3), 'LineWidth',2, 'DisplayName','Peak-to-Peak - Second Peak');
            
            %plot(app.UIAxesHRAslope, app.HReflex_Cropped_2_Peak2Peak_All, app.HReflex_Peak_volt_All, 'LineWidth',2, 'DisplayName','Peak-to-Peak - Second Peak');
            
            %----Hrf functions 
            function [Positive_Peak_volt, Negative_Peak_volt] = voltagePeakNbottom(VoltageD)
                Positive_Peak_volt = max(VoltageD);
                Negative_Peak_volt = min(VoltageD);
            end 
            
            
            function [Cropped_1_Peak, Cropped_1_Bottom, Cropped_1_Peak2Peak] = Trial_Cropped_1(AmplitudeD,Cropped_startTime_1, Cropped_endTime_1)
                CroppedAmp_1 = AmplitudeD(Cropped_startTime_1:Cropped_endTime_1);
                [maxVal,maxInx] = max(CroppedAmp_1);
                [minVal,minInx] = min(CroppedAmp_1);
                Cropped_1_Peak2Peak = abs(maxVal) + abs(minVal);
                Cropped_1_Peak = maxInx+Cropped_startTime_1-1;
                Cropped_1_Bottom = minInx+Cropped_startTime_1-1;  
            end 
            
            function [Cropped_2_Peak, Cropped_2_Bottom, Cropped_2_Peak2Peak] = Trial_Cropped_2(AmplitudeD,Cropped_startTime_2, Cropped_endTime_2)
                CroppedAmp_2 = AmplitudeD(Cropped_startTime_2:Cropped_endTime_2);
                [maxVal,maxInx] = max(CroppedAmp_2);
                [minVal,minInx] = min(CroppedAmp_2);
                Cropped_2_Peak2Peak = abs(maxVal) + abs(minVal);
                Cropped_2_Peak = maxInx+Cropped_startTime_2-1;
                Cropped_2_Bottom = minInx+Cropped_startTime_2-1;  
            end 
        end
        
        
        function [TimeD, AmplitudeD,VoltageD] = xReflexProcessAll(app,Data, IntervalIndx, SampleNumber)

            startPoint = IntervalIndx(SampleNumber)+6;
            endPoint = IntervalIndx(SampleNumber+1)-1;
            SampleLength = endPoint - startPoint+1;
        
            Amplitude = Data(startPoint:endPoint,2);
            Voltage = Data(startPoint:endPoint,3);
            Time = Data(startPoint:endPoint,1);


            TimeD =zeros;
            for i = 1:SampleLength %length(Time)
                TimeD(i) = Time{i};
            end 
        
            AmplitudeD=zeros;
            for i = 1:SampleLength %length(Foot_Padel)
        
                AmplitudeD(i) = Amplitude{i};
                if isnan(AmplitudeD(i))
                    AmplitudeD(i) = AmplitudeD(i-1);
                end
            end 
        
            VoltageD = zeros;
            for i = 1:SampleLength %length(Beep)
        
                VoltageD(i) = Voltage{i};
                if isnan(VoltageD(i))
                    VoltageD(i) = VoltageD(i-1);
                end
            end
        end
        
        % Callback or function to open file dialog
        function [file, path] = openFileFunction(app)
            % Check if lastPath is empty or not
            if isempty(app.lastPath)
                startPath = pwd; % Start in the current directory if lastPath is empty
            else
                startPath = app.lastPath; % Start in the last accessed directory
            end
            
            %create a dummy figure so that uigetfile doesn't minimize our GUI
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); 
            % Open the file dialog starting in startPath
            [file, path] = uigetfile('*.txt', 'Open text file', startPath); % Example for .txt files
            delete(f);
            
            % Check if the user selected a file or clicked cancel
            if isequal(file, 0) || isequal(path, 0)
                disp('User pressed cancel.');
            else
                disp(['User selected ', fullfile(path, file)]);
                
                % Update lastPath with the new directory
                app.lastPath = path;
                
                % Continue with handling the selected file...
            end
            AppConfigSave(app);
        end

        function [file_name, File_Path] = saveFileFunction(app, file_to_save, fileNameOnly) 
            % Check if lastPath is empty or not
            if isempty(app.lastPathForSave )
                startPath = pwd; % Start in the current directory if lastPath is empty
            else
                startPath = app.lastPathForSave; % Start in the last accessed directory
            end
            
            completeSaveFileName = startPath + string(fileNameOnly);
            
            %create a dummy figure so that uigetfile doesn't minimize our GUI
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); 
            
            % Open the file dialog starting in startPath
            %[filename,path] = uigetfile('*.txt', 'Open text file','MultiSelect','on');
            [file, path]= uiputfile("*.txt",'Save text file', completeSaveFileName+"_Result.txt");
            delete(f);
            
            if exist(string(path), 'file')
                fileID = fopen(fullfile(path,file),'w');
                fprintf(fileID,file_to_save);
                fclose(fileID);
                
                % Split the string into a cell array using the delimiter '\t'
                header = split(file_to_save, '\t');
                
                % Convert the cell array to a table
                headerTable = array2table(header');

                % Remove file extension from the filename
                [~, name, ~] = fileparts(fullfile(path, file));
 
                % Write the table to a CSV file
                writetable(headerTable, path+string(name)+".CSV", 'WriteVariableNames', false);
            end
            
            
            % Check if the user selected a file or clicked cancel
            if isequal(file, 0) || isequal(path, 0)
                disp('User pressed cancel.');
            else
                disp(['User selected ', fullfile(path, file)]);
                
                % Update lastPath with the new directory
                app.lastPathForSave = path;
                
                % Continue with handling the selected file...
            end
            AppConfigSave(app);   
        end


    end


    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            startupConfig(app);
            app.endPoint = 0;
            app.sampleNumber = 1;
            app.startPoint = app.endPoint+ 7;
            app.endPoint = app.startPoint+app.dataPointPerSample;
            app.SampleSizeEditField.Value = string(app.dataPointPerSample+1);
            app.SSValLabel.Text = string(app.dataPointPerSample +1);
            func2(app, app.endPoint, app.startPoint, app.sampleNumber);
            app.HTML.HTMLSource ="<html></html>";
            app.ITT_HTML.HTMLSource ="<html></html>";
            app.ThresholdEditField_MEPA.Value = num2str(app.MEPA_Threshold);
            app.ThLabel_MEPA.Text = num2str(app.MEPA_Threshold);
            app.PedalGainEditField.Value = num2str(app.TTA_PedalGain);
            app.BeepGainEditField.Value = num2str(app.TTA_BeepGain);
            app.PedalOffsetEditField.Value = app.TTA_PedalOffset;
            app.PedalThresholdEditField.Value = app.TTA_PadelThreshold;
            if isempty(app.lastPath)
                app.lastPath = pwd;
            end
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            
            
            if (app.TabGroup.SelectedTab == app.CSPTab)
                %% clearing initials 
                app.endPoint = 0;
                app.sampleNumber = 1;
                app.startPoint = app.endPoint+ 7;
                app.endPoint = app.startPoint+app.dataPointPerSample;
                app.extractedCSP_data ={};
                app.HTML.HTMLSource ="<html></html>";
                %% clearing done
                
%                 f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
%                 [filename,path] = uigetfile('*.txt', 'Open text file','MultiSelect','on');
%                 delete(f);
                [filename,path] = openFileFunction(app);
                
                % Create Processing dialogbox
                app.TTA_progressdialog = uiprogressdlg(app.URNeuroAppUIFigure,'Title','Processing...',...
                    'Indeterminate','on');
                    drawnow;
                
                try
                    if ~isempty(filename)
                        % Remove file extension from the filename
                        [~, name, ~] = fileparts(filename);
                        app.fileNameOnly = name;
                        app.currentFileNameOnly = name;
        
                        txt = string(path)+string(filename);
                        app.filepath = txt;
                        app.Label.Text = txt;     
                    end
                    
                catch ME
                    disp("Erron in CSP File Opening");
                end

                startupConfig(app);
                
                if app.filepath ~=  ""
                    if app.filepath ~= "00"
                        app.data= readcell(app.filepath);
                        
                        TimeFull = app.data(1:length(app.data), 1);
                        
                        IntervalIndx = zeros;
                        j=0;
                        for i = 1:length(TimeFull)
                            if num2str(TimeFull{i}) == "Interval="
                                j = j+1;
                                IntervalIndx(j) = i;
                            end
                            if i == length(TimeFull)
                                j = j+1;
                                IntervalIndx(j) = i+1;
                            end 
                        end
                        
                        app.CSP_TotalTrail = length(IntervalIndx) -1;
                        app.CSP_IntervalIndx = IntervalIndx;
                        
                        app.startPoint = app.CSP_IntervalIndx(app.sampleNumber)+6;
                        app.endPoint = app.CSP_IntervalIndx(app.sampleNumber+1)-1;
                        
                        func2(app, app.endPoint, app.startPoint, app.sampleNumber);
                    end
                end
                
                % Close Processing dialog box
                close(app.TTA_progressdialog);
                       
            elseif (app.TabGroup.SelectedTab == app.MEPATab)
                %% clearing initials 
                app.endPoint = 0;
                app.sampleNumber = 1;
                app.startPoint = app.endPoint+ 7;
                app.endPoint = app.startPoint+app.dataPointPerSample;
                %% clearing done
                
%                 f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
%                 [filename,path] = uigetfile('*.txt', 'Open text file','MultiSelect','on');
%                 delete(f);
                [filename,path] = openFileFunction(app);
                
                % Create Processing dialogbox
                app.TTA_progressdialog = uiprogressdlg(app.URNeuroAppUIFigure,'Title','Processing... It will take some time',...
                    'Indeterminate','on');
                    drawnow;
               
                    
               if ~isempty(filename)
                    % Remove file extension from the filename
                    [~, name, ~] = fileparts(filename);
                    app.currentFileNameOnly = name;
    
                    txt = string(path)+string(filename);
                    app.filepath = txt;
                    app.Label_2.Text = txt;     
                end
                              
                
                if app.filepath ~=  ""
                    if app.filepath ~= "00"
                        startupConfig(app);
                        app.mep_data= readcell(app.filepath);
                        
                        TimeFull = app.mep_data(1:length(app.mep_data), 1);
                        
                        IntervalIndx = zeros;
                        j=0;
                        for i = 1:length(TimeFull)
                            if num2str(TimeFull{i}) == "Interval="
                                j = j+1;
                                IntervalIndx(j) = i;
                            end
                            if i == length(TimeFull)
                                j = j+1;
                                IntervalIndx(j) = i+1;
                            end 
                        end
                        
                        app.MEP_TotalTrail = length(IntervalIndx) -1;
                        app.MEP_IntervalIndx = IntervalIndx;
                        
                        app.startPoint = app.MEP_IntervalIndx(app.sampleNumber)+6;
                        app.endPoint = app.MEP_IntervalIndx(app.sampleNumber+1)-1;
                        
                        MEPAfunc(app, app.endPoint, app.startPoint, app.sampleNumber);
                    end
                end
                
                % Close Processing dialog box
                close(app.TTA_progressdialog);
               
            elseif (app.TabGroup.SelectedTab == app.ITTTab)
                %% clearing initials 
                app.endPoint = 0;
                app.sampleNumber = 1;
                app.startPoint = app.endPoint+ 7;
                app.endPoint = app.startPoint+app.dataPointPerSample;
                %% clearing done


%                 f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
%                 [filename,path] = uigetfile('*.txt', 'Open text file','MultiSelect','on');
%                 delete(f);
                [filename,path] = openFileFunction(app);
                
                % Create Processing dialogbox
                app.TTA_progressdialog = uiprogressdlg(app.URNeuroAppUIFigure,'Title','Processing... It will take some time',...
                    'Indeterminate','on');
                    drawnow;
                
                
                %[filename, path] = uigetfile('*.*');
                txt = string(path)+string(filename);
                app.filepath = txt;
                app.ITTdirectoryLebel .Text = txt;
                
                
                if app.filepath ~=  ""
                    if app.filepath ~= "00"
                        startupConfig(app);
                        app.ITT_data= readcell(app.filepath);
                        ITTFunction(app);
                    end
                end
                
                % Close Processing dialog box
                close(app.TTA_progressdialog);
                
            elseif (app.TabGroup.SelectedTab == app.TTATab)
                
                app.TTA_TrialNumber = 1;
                
                
%                 f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
%                 [filename,path] = uigetfile('*.txt', 'Open text file','MultiSelect','on');
%                 delete(f);
                [filename,path] = openFileFunction(app);
                
                %myprogress1(app);
                
                % Create Processing dialogbox
                app.TTA_progressdialog = uiprogressdlg(app.URNeuroAppUIFigure,'Title','Processing... It will take some time',...
                    'Indeterminate','on');
                    drawnow;
                    

                %[filename, path] = uigetfile('*.*');
                txt = string(path)+string(filename);
                app.filepath = txt;
                app.TTA_FileDirectoryLabel.Text = txt;
                %app.ITTdirectoryLebel .Text = txt;


                if app.filepath ~=  ""
                    if app.filepath ~= "00"
                        startupConfig(app);
                        app.TTA_data= readcell(app.filepath);
                        
                        TimeFull = app.TTA_data(1:length(app.TTA_data), 1);
                        
                        IntervalIndx = zeros;
                        j=0;
                        for i = 1:length(TimeFull)
                            if num2str(TimeFull{i}) == "Interval="
                                j = j+1;
                                IntervalIndx(j) = i;
                            end
                            if i == length(TimeFull)
                                j = j+1;
                                IntervalIndx(j) = i+1;
                            end 
                        end 
            
                        app.TTA_TotalTrail = length(IntervalIndx) -1;
                        app.TTA_IntervalIndx = IntervalIndx;
                        
                        TTAFunction(app);
                    end
                end
                
            
                % Close Processing dialog box
                close(app.TTA_progressdialog)
                
            elseif (app.TabGroup.SelectedTab == app.HReflexTab)
                
%                 f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
%                 [filename,path] = uigetfile('*.txt', 'Open text file','MultiSelect','on');
%                 delete(f);
                [filename,path] = openFileFunction(app);
                
                % Create Processing dialogbox
                app.TTA_progressdialog = uiprogressdlg(app.URNeuroAppUIFigure,'Title','Processing... It will take some time',...
                    'Indeterminate','on');
                    drawnow;
                    
                try
                    if ~isempty(filename)
                        % Remove file extension from the filename
                        [~, name, ~] = fileparts(filename);
                        app.fileNameOnly = name;
                        app.currentFileNameOnly = name;
        
                        txt = string(path)+string(filename);
                        app.filepath = txt;
                        app.Hreflex_FileDirectoryLabel.Text = "File Directory: "+txt;     
                    end
                    
                catch ME
                    disp("Erron in Hreflex File Opening");
                end
                
%                 txt = string(path)+string(filename);
%                 app.filepath = txt;
%                 app.Hreflex_FileDirectoryLabel.Text = txt;
                
                
                if app.filepath ~=  ""
                    if app.filepath ~= "00"
                        startupConfig(app);
                        app.HReflex_data= readcell(app.filepath);
                        
                        TimeFull = app.HReflex_data(1:length(app.HReflex_data), 1);
                        
                        IntervalIndx = zeros;
                        j=0;
                        for i = 1:length(TimeFull)
                            if num2str(TimeFull{i}) == "Interval="
                                j = j+1;
                                IntervalIndx(j) = i;
                            end
                            if i == length(TimeFull)
                                j = j+1;
                                IntervalIndx(j) = i+1;
                            end 
                        end 
            
                        
                        app.HReflex_startSample = 1;
                        app.HReflex_endSample = length(IntervalIndx)-1;
                        app.HReflex_IntervalIndx = IntervalIndx;
                        
                        HReflexFunction(app);
                        
                        app.SaveProcessedDataButton_Hreflex.Enable = "off";
                        close(app.TTA_progressdialog);
                        HreflecxROIfunction(app);
                    else
                        close(app.TTA_progressdialog);
                    end
                else
                    close(app.TTA_progressdialog);
                end
                
%                 app.SaveProcessedDataButton_Hreflex.Enable = "off";
%                 close(app.TTA_progressdialog);
%                 if app.filepath ~=  ""
%                     if app.filepath ~= "00"
%                         HreflecxROIfunction(app);
%                     end
%                 end
                
            elseif (app.TabGroup.SelectedTab == app.MReflexTab)
                
%                 f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
%                 [filename,path] = uigetfile('*.txt', 'Open text file','MultiSelect','on');
%                 delete(f);
                [filename,path] = openFileFunction(app);
                
                % Create Processing dialogbox
                app.TTA_progressdialog = uiprogressdlg(app.URNeuroAppUIFigure,'Title','Processing... It will take some time',...
                    'Indeterminate','on');
                    drawnow;
                
                txt = string(path)+string(filename);
                app.filepath = txt;
                if app.filepath ~=  ""
                    if app.filepath ~= "00"
                        startupConfig(app);
                        app.MReflex_data= readcell(app.filepath);
                        
                        TimeFull = app.MReflex_data(1:length(app.MReflex_data), 1);
                        
                        IntervalIndx = zeros;
                        j=0;
                        for i = 1:length(TimeFull)
                            if num2str(TimeFull{i}) == "Interval="
                                j = j+1;
                                IntervalIndx(j) = i;
                            end
                            if i == length(TimeFull)
                                j = j+1;
                                IntervalIndx(j) = i+1;
                            end 
                        end 
            
                        
                        app.MReflex_startSample = 1;
                        app.MReflex_endSample = length(IntervalIndx)-1;
                        app.MReflex_IntervalIndx = IntervalIndx;
                        
                        MReflexFunction(app);
                    end
                end
                close(app.TTA_progressdialog);
                MreflecxROIfunction(app);
                
            elseif (app.TabGroup.SelectedTab == app.TTACSPTab)
                                
                app.TTA_TrialNumber = 1;
                
                
%                 f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
%                 [filename,path] = uigetfile('*.txt', 'Open text file','MultiSelect','on');
%                 delete(f);
                
                [filename,path] = openFileFunction(app);
                
                %myprogress1(app);
                
                % Create Processing dialogbox
                app.TTA_progressdialog = uiprogressdlg(app.URNeuroAppUIFigure,'Title','Processing... It will take some time',...
                    'Indeterminate','on');
                    drawnow;
                    

                %[filename, path] = uigetfile('*.*');
                txt = string(path)+string(filename);
                app.filepath = txt;
                app.FileDirectoryLabelTTACSP.Text = txt;
                %app.ITTdirectoryLebel .Text = txt;


                if app.filepath ~=  ""
                    if app.filepath ~= "00"
                        startupConfig(app);
                        app.TTA_data= readcell(app.filepath);
                        
                        TimeFull = app.TTA_data(1:length(app.TTA_data), 1);
                        
                        IntervalIndx = zeros;
                        j=0;
                        for i = 1:length(TimeFull)
                            if num2str(TimeFull{i}) == "Interval="
                                j = j+1;
                                IntervalIndx(j) = i;
                            end
                            if i == length(TimeFull)
                                j = j+1;
                                IntervalIndx(j) = i+1;
                            end 
                        end 
            
                        app.TTA_TotalTrail = length(IntervalIndx) -1;
                        app.TTA_IntervalIndx = IntervalIndx;
                        
                        TTACSPFunction(app);
                    end
                end
                
            
                % Close Processing dialog box
                close(app.TTA_progressdialog)
                
            else
                
            end
            
            
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            %app.sampleNumber = app.sampleNumber+1;
%             if (app.endPoint+7+app.dataPointPerSample) <= app.SampleDataSize(1)
            if app.sampleNumber < app.CSP_TotalTrail
                app.sampleNumber = app.sampleNumber+1;
%                 app.startPoint = app.endPoint + 7;
%                 app.endPoint = app.startPoint + app.dataPointPerSample;
                  app.startPoint = app.CSP_IntervalIndx(app.sampleNumber)+6;
                  app.endPoint = app.CSP_IntervalIndx(app.sampleNumber+1)-1;
            else
%                 app.endPoint = app.endPoint;
%                 app.startPoint = app.endPoint - app.dataPointPerSample;
                  app.startPoint = app.CSP_IntervalIndx(app.sampleNumber)+6;
                  app.endPoint = app.CSP_IntervalIndx(app.sampleNumber+1)-1;
                app.NextButton.Enable = 'off';
            end
            func2(app, app.endPoint, app.startPoint, app.sampleNumber);
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if app.sampleNumber > 1
                app.sampleNumber = app.sampleNumber-1;
%                 app.startPoint = app.endPoint - app.dataPointPerSample -app.dataPointPerSample-7;
%                 app.endPoint = app.startPoint + app.dataPointPerSample;
                  app.startPoint = app.CSP_IntervalIndx(app.sampleNumber)+6;
                  app.endPoint = app.CSP_IntervalIndx(app.sampleNumber+1)-1;
            else
                app.sampleNumber = 1;
%                 app.endPoint = app.endPoint;
%                 app.startPoint = app.endPoint -app.dataPointPerSample;
                  app.startPoint = app.CSP_IntervalIndx(app.sampleNumber)+6;
                  app.endPoint = app.CSP_IntervalIndx(app.sampleNumber+1)-1;
                app.PreviousButton.Enable = 'off';
            end
            func2(app, app.endPoint, app.startPoint, app.sampleNumber);  
        end

        % Button pushed function: ShowAllSaveButton
        function ShowAllSaveButtonPushed(app, event)
            endPoint = 0;
            %app.sampleNumber = 1;
            NumberOfCountedSample = 0;
            %AllCntSampleNumber = 1;
            TotalCSPtime = 0; 
            txt = "";
            %txt_Labelled_Data = "";
            txt_save = "Trial Number \t\tMaximum(uV) \tMaximum Time(s) \tMinimum(uV) \tMinimum Time(s) \tPeak-Peak(uV) \tCSP Duration(s)  \tCSP Onset(s) \tCSP Offset(s) \tRMS Before Stimulation \tRMS after CSP \tSilentPeriodOnSetIndx \tSilentPeriodOffSetIndx";
            %maxLimit = round(app.SampleDataSize(1)/(app.dataPointPerSample+7));
            for i = 1:app.CSP_TotalTrail
%                 app.startPoint = endPoint+ 7;
%                 AllCntSampleNumber = i;
%                 endPoint = app.startPoint+app.dataPointPerSample;
                AllCntSampleNumber = i;
                app.startPoint = app.CSP_IntervalIndx(AllCntSampleNumber)+6;
                app.endPoint = app.CSP_IntervalIndx(AllCntSampleNumber+1)-1;

                [CSP, spOnSet, spOffSet, FirstResp_maxVal, CSPmaxTime, FirstResp_minVal,CSPminTime, peak2peak, init_rms, afterCSP_rms, silentPeriodOnSetIndx, silentPeriodOffSetIndx] = func3(app,app.endPoint, app.startPoint, AllCntSampleNumber);
                if CSP <= 0
                    txt = txt + newline + ['<a>CSP Sample ' num2str(i) ' :</a><a style="color:red;"> Not Detected</a><br>'];
                    txt_save = txt_save + newline + [ num2str(i) ' \tNot Detected'];
                    %txt_Labelled_Data = txt_Labelled_Data + [num2str(app.Time_Data') ' ' num2str(app.Amplitude_Data') ' ' num2str(0) ' ' num2str(0) ' ' num2str(0) ' ' num2str(0) ' ' num2str(0) ' ' num2str(0) ' ' num2str(0) ' ' num2str(0) ' ' num2str(0) ' ' num2str(0)] +newline;
                else
                    txt = txt + newline + ['<a>CSP Sample ' num2str(i) ' : ' num2str(CSP) ' s </a><br>'];
                    txt_save = txt_save + newline + [ num2str(i) ' \t\t ' num2str(FirstResp_maxVal) '\t' num2str(CSPmaxTime) '\t' num2str(FirstResp_minVal) '\t' num2str(CSPminTime) '\t' num2str(peak2peak) '\t' num2str(CSP) '\t' num2str(spOnSet) '\t' num2str(spOffSet) '\t' num2str(init_rms) '\t' num2str(afterCSP_rms) '\t' num2str(silentPeriodOnSetIndx) '\t' num2str(silentPeriodOffSetIndx)];
                    %txt_Labelled_Data = txt_Labelled_Data + [num2str(app.Time_Data) ' ' num2str(app.Amplitude_Data) ' ' num2str(FirstResp_maxVal) ' ' num2str(CSPmaxTime) ' ' num2str(FirstResp_minVal) ' ' num2str(CSPminTime) ' ' num2str(peak2peak) ' ' num2str(CSP) ' ' num2str(spOnSet) ' ' num2str(spOffSet) ' ' num2str(init_rms) ' ' num2str(afterCSP_rms)] +newline;
                    NumberOfCountedSample = NumberOfCountedSample+1;
                    TotalCSPtime = TotalCSPtime + CSP;
                end
                %txt_Labelled_Data = txt_Labelled_Data + [num2str(app.Time_Data') '\t' num2str(app.Amplitude_Data') '\t'] +newline;
            end

                txt = txt+ newline + newline + ['<b><a style="color:blue;">Avg. CSP: ' num2str(TotalCSPtime/NumberOfCountedSample) ' s </a></b><br>'];
                txt_save = txt_save+ newline + newline + ['\t\t\t\t\t\tAvg. CSP Duration \t' num2str(TotalCSPtime/NumberOfCountedSample)];
            %app.DisplayAllCSPLabel.Text = txt;
            app.HTML.HTMLSource =txt;
            
%             fileID = fopen('SavedResult.txt','w');
%             fprintf(fileID,txt_save);
%             fclose(fileID);
            %app.endPoint = 0;
            
            saveFileFunction(app, txt_save, app.currentFileNameOnly); % app, file_to_save, fineNameOnly
            
%             f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
%             %[filename,path] = uigetfile('*.txt', 'Open text file','MultiSelect','on');
%             [file_name, File_Path]= uiputfile(string(app.currentFileNameOnly)+"_Result.txt");
%             delete(f);
            
%             if exist(string(File_Path), 'file')
%                 fileID = fopen(fullfile(File_Path,file_name),'w');
%                 fprintf(fileID,txt_save);
%                 fclose(fileID);
%             end


%                     [file_name, File_Path]= uiputfile("SavedCSP_Labelled_Data.txt");
%                     delete(f);
%                     
%                     if exist(string(File_Path), 'file')
%                         fileID = fopen(fullfile(File_Path,file_name),'w');
%                         fprintf(fileID,txt_Labelled_Data);
%                         fclose(fileID);
%                     end
        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            %app.sampleNumber = app.sampleNumber+1;
%             if (app.endPoint+7+app.dataPointPerSample) <= app.SampleDataSize(1)
            if app.sampleNumber < app.MEP_TotalTrail
                app.sampleNumber = app.sampleNumber+1;
%                 app.startPoint = app.endPoint + 7;
%                 app.endPoint = app.startPoint + app.dataPointPerSample;
                app.startPoint = app.MEP_IntervalIndx(app.sampleNumber)+6;
                app.endPoint = app.MEP_IntervalIndx(app.sampleNumber+1)-1;
            else
%                 app.endPoint = app.endPoint;
%                 app.startPoint = app.endPoint -app.dataPointPerSample;
                app.startPoint = app.MEP_IntervalIndx(app.sampleNumber)+6;
                app.endPoint = app.MEP_IntervalIndx(app.sampleNumber+1)-1;
                app.NextButton.Enable = 'off';
            end
            MEPAfunc(app, app.endPoint, app.startPoint, app.sampleNumber);
        end

        % Button pushed function: PreviousButton_2
        function PreviousButton_2Pushed(app, event)
            if app.sampleNumber > 1
                app.sampleNumber = app.sampleNumber-1;
%                 app.startPoint = app.endPoint - app.dataPointPerSample -app.dataPointPerSample-7;
%                 app.endPoint = app.startPoint + app.dataPointPerSample;
                  app.startPoint = app.MEP_IntervalIndx(app.sampleNumber)+6;
                  app.endPoint = app.MEP_IntervalIndx(app.sampleNumber+1)-1;
            else
                app.sampleNumber = 1;
%                 app.endPoint = app.endPoint;
%                 app.startPoint = app.endPoint -app.dataPointPerSample;
                app.startPoint = app.MEP_IntervalIndx(app.sampleNumber)+6;
                app.endPoint = app.MEP_IntervalIndx(app.sampleNumber+1)-1;
                app.PreviousButton.Enable = 'off';
            end
            MEPAfunc(app, app.endPoint, app.startPoint, app.sampleNumber);  
        end

        % Button pushed function: SelectROIButton
        function SelectROIButtonPushed(app, event)

            cnt = zeros;
            roi_x=zeros;
            roi_y=zeros;
            while 1
                % Set up figure handle visibility, run ginput, and return state
                fhv = app.URNeuroAppUIFigure.HandleVisibility;        % Current status
                app.URNeuroAppUIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
                set(0, 'CurrentFigure', app.URNeuroAppUIFigure)       % Make fig current
                
                [px,py,button]= ginput(1);
                plot(px,py,'*');

                %new bar
                lineX6 = [px px];
                lineY6 = [py-0.5 py+0.5];
                line(app.UIAxes2, lineX6, lineY6,Color="red");
                
                app.URNeuroAppUIFigure.HandleVisibility = fhv;        % return original state
                if button == 1
                    cnt = cnt+1;
                    roi_x(cnt)=px;
                    roi_y(cnt)=py;
                    
                    %previous bar
                    lineX6 = [app.ROI_X(cnt) app.ROI_X(cnt)];
                    lineY6 = [0-0.5 0+0.5];
                    line(app.UIAxes2, lineX6, lineY6,Color="white");
                    %button = 0;
                    if cnt ==2 
                        break;
                    end
                end
            end
            
            [~,startTime]=min(abs(app.TimeD2-roi_x(1)));  % find index where the roi_x(1) is closest
            [~,endTime]=min(abs(app.TimeD2-roi_x(2)));
            %startTime = find(Time == roi_x(1));
            %endTime = find(Time == roi_x(2));
            stp = startTime;
            cutAmp = app.AmplitudeD2(stp:endTime);
            [~,maxInx] = max(cutAmp);
            [~,minInx] = min(cutAmp);
            maxInx_stp = maxInx+stp-1;
            minInx_stp = minInx+stp-1;
            plot(app.UIAxes2, app.TimeD2(maxInx_stp), app.AmplitudeD2(maxInx_stp), '-o');
            hold(app.UIAxes2, 'on');
            plot(app.UIAxes2, app.TimeD2(minInx_stp), app.AmplitudeD2(minInx_stp), '-o');
            
            %hold(app.UIAxes, 'on');
            
            app.ROI_Start_X= roi_x(1);
            app.ROI_End_X= roi_x(2);
            app.ROI_X(1) = roi_x(1);
            app.ROI_X(2) = roi_x(2);
            
            AppConfigSave(app);
            
%             fileID = fopen('Appconfig.txt','w');
%             fileValue = string(app.freqPass)+"\tFrqPass"+"\n"+string(app.thresholdLevel)+"\tThLvl"+"\n"+string(app.spSearchStartPoint)+"\tSPsearch";
%             fileValue = append( fileValue+ "\n" + string(app.ROI_Start_X) + "\t" + string(app.ROI_End_X) + "\tROI");
%             fileValue = append(fileValue+ "\n" +string(app.dataPointPerSample) + "\tSampleSize");
%             %fileValue = ;
%             fprintf(fileID,fileValue);
%             fclose(fileID);
        end

        % Button down function: CSPTab
        function CSPTabButtonDown(app, event)
            app.Label.Text = "CSP";
        end

        % Menu selected function: OpenCalibrationMenu
        function OpenCalibrationMenuSelected(app, event)
            calib(app.filepath);
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            about;
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            app.delete;
        end

        % Button pushed function: OffsetButton
        function OffsetButtonPushed(app, event)
            while 1
                % Set up figure handle visibility, run ginput, and return state
                fhv = app.URNeuroAppUIFigure.HandleVisibility;        % Current status
                app.URNeuroAppUIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
                set(0, 'CurrentFigure', app.URNeuroAppUIFigure)       % Make fig current
                
                [px,py,button]= ginput(1);
                plot(px,py,'o');
                
                %new bar
                lineX6 = [px px];
                lineY6 = [py-0.5 py+0.5];
                line(app.UIAxes, lineX6, lineY6,Color="red");


                app.extractedCSP_data(app.sampleNumber, 3) = {px};
                
                
                spOnSet = cell2mat(app.extractedCSP_data(app.sampleNumber, 2));
                spOffSet = cell2mat(app.extractedCSP_data(app.sampleNumber, 3));
                
                lineX = [spOnSet spOffSet];
                lineY = [0 0];
                line(app.UIAxes, lineX, lineY, Color="green");
                
                
                app.URNeuroAppUIFigure.HandleVisibility = fhv;        % return original state
                if button == 1
                    break;
                end
            end
        end

        % Button pushed function: OnsetButton
        function OnsetButtonPushed(app, event)
             while 1
                % Set up figure handle visibility, run ginput, and return state
                fhv = app.URNeuroAppUIFigure.HandleVisibility;        % Current status
                app.URNeuroAppUIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
                set(0, 'CurrentFigure', app.URNeuroAppUIFigure)       % Make fig current
                
                [px,py,button]= ginput(1);
                plot(px,py,'o');
                
                %new bar
                lineX6 = [px px];
                lineY6 = [py-0.5 py+0.5];
                line(app.UIAxes, lineX6, lineY6,Color="red");
                
                
                app.extractedCSP_data(app.sampleNumber, 2) = {px};
                
                spOnSet = cell2mat(app.extractedCSP_data(app.sampleNumber, 2));
                spOffSet = cell2mat(app.extractedCSP_data(app.sampleNumber, 3));
                
                lineX = [spOnSet spOffSet];
                lineY = [0 0];
                line(app.UIAxes, lineX, lineY, Color="green");
                
                
                app.URNeuroAppUIFigure.HandleVisibility = fhv;        % return original state
                if button == 1
                    break;
                end
            end
        end

        % Button pushed function: ProcessAllSaveButton
        function ProcessAllSaveButtonPushed(app, event)
            endPoint = 0;
            %app.sampleNumber = 1;
            NumberOfCountedSample = 0;
            %AllCntSampleNumber = 1;
            TotalCSPtime = 0; 
            txt = "";
            txt_save = "Trial Number \tis_MEPA_Detected \t\tMaximum(uV) \tMaximum Time(s) \tMinimum(uV) \tMinimum Time(s) \tPeak-Peak(uV) \tDuration(s)  \tOnset(s) \tOffset(s)";
            %maxLimit = round(app.SampleDataSize(1)/(app.dataPointPerSample+7));
            for i = 1:app.MEP_TotalTrail
%                 app.startPoint = endPoint+ 7;
                AllCntSampleNumber = i;
%                 endPoint = app.startPoint+app.dataPointPerSample;
                app.startPoint = app.MEP_IntervalIndx(AllCntSampleNumber)+6;
                app.endPoint = app.MEP_IntervalIndx(AllCntSampleNumber+1)-1;
                [isMEPA_Detected, TrailNumber, MaxuV, MaxTime, MinuV, MinTime, p2p, Duration, OnSet, OffSet] = MEPA_ALL(app,app.endPoint, app.startPoint, AllCntSampleNumber);
%                 if CSP <= 0
%                     txt = txt + newline + ['<a>CSP Sample ' num2str(i) ' :</a><a style="color:red;"> Not Detected</a><br>'];
%                     txt_save = txt_save + newline + [ num2str(i) ' \tNot Detected'];
%                 else
%                     txt = txt + newline + ['<a>CSP Sample ' num2str(i) ' : ' num2str(CSP) ' s </a><br>'];
%                     txt_save = txt_save + newline + [ num2str(i) ' \t\t ' num2str(FirstResp_maxVal) '\t' num2str(CSPmaxTime) '\t' num2str(FirstResp_minVal) '\t' num2str(CSPminTime) '\t' num2str(peak2peak) '\t' num2str(CSP) '\t' num2str(spOnSet) '\t' num2str(spOffSet) '\t' num2str(init_rms) '\t' num2str(afterCSP_rms)];
%                     NumberOfCountedSample = NumberOfCountedSample+1;
%                     TotalCSPtime = TotalCSPtime + CSP;
%                 end
                if isMEPA_Detected ~= 0
                    txt_save = txt_save + newline + [ num2str(TrailNumber) '\tYes \t\t ' num2str(MaxuV) '\t' num2str(MaxTime) '\t' num2str(MinuV) '\t' num2str(MinTime) '\t' num2str(p2p) '\t' num2str(Duration) '\t' num2str(OnSet) '\t' num2str(OffSet)];
                else
                    txt_save = txt_save + newline + [ num2str(TrailNumber) '\tNo \t\t ' num2str(MaxuV) '\t' num2str(MaxTime) '\t' num2str(MinuV) '\t' num2str(MinTime) '\t' num2str(p2p) '\t' num2str(Duration) '\t' num2str(OnSet) '\t' num2str(OffSet)];
                end
                
            end

            %    txt = txt+ newline + newline + ['<b><a style="color:blue;">Avg. CSP: ' num2str(TotalCSPtime/NumberOfCountedSample) ' s </a></b><br>'];
            %    txt_save = txt_save+ newline + newline + ['\t\t\t\t\t\tAvg. CSP Duration \t' num2str(TotalCSPtime/NumberOfCountedSample)];
            %app.DisplayAllCSPLabel.Text = txt;
            
             app.HTML.HTMLSource =txt;
%             fileID = fopen('SavedMEPAALL.txt','w');
%             fprintf(fileID,txt_save);
%             fclose(fileID);
            
            saveFileFunction(app, txt_save, app.currentFileNameOnly);
            
%             f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
%                     %[filename,path] = uigetfile('*.txt', 'Open text file','MultiSelect','on');
%                     [file_name, File_Path]= uiputfile("MEPA_Result.txt");
%                     delete(f);
%                     
%                     if exist(string(File_Path), 'file')
%                         fileID = fopen(fullfile(File_Path,file_name),'w');
%                         fprintf(fileID,txt_save);
%                         fclose(fileID);
%                     end
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.dataPointPerSample = str2num(app.SampleSizeEditField.Value)-1;
            
            AppConfigSave(app);
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            app.ITT_Save_Button_Pressed = 1;
            ITTFunction(app);
        end

        % Button pushed function: SetThresholdButton
        function SetThresholdButtonPushed(app, event)
            app.ITT_isManualThreshold = 1;
            while 1
                % Set up figure handle visibility, run ginput, and return state
                fhv = app.URNeuroAppUIFigure.HandleVisibility;        % Current status
                app.URNeuroAppUIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
                set(0, 'CurrentFigure', app.URNeuroAppUIFigure)       % Make fig current
                
                [px,py,button]= ginput(1);
                plot(px,py,'o');
                
                %new bar
                lineX6 = [0 app.ITT_Threshold_Length];
                lineY6 = [py py];
                line(app.UIAxesITT, lineX6, lineY6,Color="red");
                
                
                app.ITT_Threshold = py;
                
                
%                 app.extractedCSP_data(app.sampleNumber, 2) = {px};
%                 
%                 spOnSet = cell2mat(app.extractedCSP_data(app.sampleNumber, 2));
%                 spOffSet = cell2mat(app.extractedCSP_data(app.sampleNumber, 3));
%                 
%                 lineX = [spOnSet spOffSet];
%                 lineY = [0 0];
%                 line(app.UIAxesITT, lineX, lineY, Color="green");
                
                
                app.URNeuroAppUIFigure.HandleVisibility = fhv;        % return original state
                if button == 1
                    break;
                end
            end
            ITTFunction(app);
            AppConfigSave(app);
        end

        % Button pushed function: RefreshITTButton
        function RefreshITTButtonPushed(app, event)
            ITTFunction(app);
            AppConfigSave(app);
        end

        % Button pushed function: SetROIButton
        function SetROIButtonPushed(app, event)
            app.ITT_isManualThreshold = 1;
            while 1
                % Set up figure handle visibility, run ginput, and return state
                fhv = app.URNeuroAppUIFigure.HandleVisibility;        % Current status
                app.URNeuroAppUIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
                set(0, 'CurrentFigure', app.URNeuroAppUIFigure)       % Make fig current
                
                [px,py,button]= ginput(1);
                plot(px,py,'o');
                
                %new bar
                lineX6 = [px px];
                lineY6 = [py-1 py+1];
                line(app.UIAxesITT, lineX6, lineY6,Color="red");
                
                
                app.ITT_ROI_after_firstStimulation = px - app.ITT_firstStimulation;


                app.URNeuroAppUIFigure.HandleVisibility = fhv;        % return original state
                if button == 1
                    break;
                end
            end
            ITTFunction(app);
            AppConfigSave(app);
        end

        % Value changed function: ThresholdEditField_MEPA
        function ThresholdEditField_MEPAValueChanged(app, event)
            app.MEPA_Threshold = str2double(app.ThresholdEditField_MEPA.Value);
            AppConfigSave(app);
            %startupConfig(app);
            app.ThLabel_MEPA.Text = num2str(app.MEPA_Threshold);
        end

        % Button pushed function: NextButton_TTA
        function NextButton_TTAPushed(app, event)
            if app.TTA_TrialNumber < app.TTA_TotalTrail
                app.TTA_TrialNumber = app.TTA_TrialNumber +1;
                app.PreviousButton_TTA.Enable = 'on';
                TTAFunction(app);
                if app.TTA_TrialNumber >= app.TTA_TotalTrail
                    app.NextButton_TTA.Enable = 'off';
                end
            else
                app.NextButton_TTA.Enable = 'off';
            end 
        end

        % Button pushed function: PreviousButton_TTA
        function PreviousButton_TTAPushed(app, event)
            if app.TTA_TrialNumber > 1
                app.TTA_TrialNumber = app.TTA_TrialNumber -1;
                app.NextButton_TTA.Enable = 'on';
                TTAFunction(app);
                if app.TTA_TrialNumber <= 1
                   app.PreviousButton_TTA.Enable = 'off'; 
                end
            else
                app.PreviousButton_TTA.Enable = 'off';
                
            end
        end

        % Button pushed function: ShowMoreButton
        function ShowMoreButtonPushed(app, event)
            figure('Position', [50 350 560 420]);
            
            plot(app.ITA_BeepPositionBefResp ,app.ITA_beep_number );
            xlabel('BeepPosition Before Response');
            ylabel('Beep Number') ;

            figure('Position', [620 350 560 420]);
            plot(app.ITA_beep_number , app.ITA_Beep_to_Resp);
            xlabel('Beep Number');
            ylabel('Response Time') ;
        end

        % Button down function: TTA_BeepVsPos
        function TTA_BeepVsPosButtonDown(app, event)
            if ~isempty(app.ITA_beep_number)
                fig = figure('Position', [50 350 560 420]);
                set(fig, 'Name', 'Beep vs Position', 'NumberTitle', 'off');
                
                title('Beep vs Position');
                plot(app.ITA_BeepPositionBefResp ,app.ITA_beep_number );
                xlabel('Beep Position Before Response');
                ylabel('Beep Number') ;
            end
            
            
        end

        % Button down function: TTA_RespVsBeep
        function TTA_RespVsBeepButtonDown(app, event)
            if ~isempty(app.ITA_Beep_to_Resp)
                fig = figure('Position', [620 350 560 420]);
                set(fig, 'Name', 'Response vs Beep', 'NumberTitle', 'off');
                
                title('Response vs Beep');
                plot(app.ITA_beep_number , app.ITA_Beep_to_Resp);
                xlabel('Beep Number');
                ylabel('Response Time') ;
            end 
        end

        % Value changed function: PedalGainEditField
        function PedalGainEditFieldValueChanged(app, event)
            app.TTA_PedalGain = str2num(app.PedalGainEditField.Value);
            TTAFunction(app);
        end

        % Value changed function: BeepGainEditField
        function BeepGainEditFieldValueChanged(app, event)
            app.TTA_BeepGain = str2num(app.BeepGainEditField.Value);
            TTAFunction(app);
        end

        % Value changed function: PedalOffsetEditField
        function PedalOffsetEditFieldValueChanged(app, event)
            app.TTA_PedalOffset = app.PedalOffsetEditField.Value;
            TTAFunction(app);
            
        end

        % Menu selected function: MVCGripForceMenu
        function MVCGripForceMenuSelected(app, event)
            gripForce;
        end

        % Value changed function: SampleSizeEditField
        function SampleSizeEditFieldValueChanged(app, event)
            app.dataPointPerSample = str2num(app.SampleSizeEditField.Value)-1;
            
            AppConfigSave(app);
            
        end

        % Button pushed function: UpdateThButton
        function UpdateThButtonPushed(app, event)
            app.CSP_onSet_Th = str2num(app.OnsetThEditField.Value);
            app.CSP_offSet_Th = str2num(app.OffsetThEditField.Value);
            app.CSP_Th_Update_Button_On =1;
            app.AutoThresholdCheckBoxValue = app.AutoThresholdCheckBox.Value;
            app.extractedCSP_data(app.sampleNumber,:) =[];
            func2(app, app.endPoint, app.startPoint, app.sampleNumber);
        end

        % Button down function: UIAxesHRoutput
        function UIAxesHRoutputButtonDown(app, event)
            if ~isempty(app.HReflex_Cropped_1_Peak2Peak_All)
                fig = figure('Position', [50 350 560 420]);
                set(fig, 'Name', 'H-reflex Result', 'NumberTitle', 'off');
                
                title('H-reflex Result');
                hold on;
                plot(app.HReflex_Cropped_1_Peak2Peak_All,'LineWidth',2, 'DisplayName','Peak-to-Peak - First Peak');
                plot(app.HReflex_Cropped_2_Peak2Peak_All, 'LineWidth',2, 'DisplayName','Peak-to-Peak - Second Peak');
                plot(app.HReflex_Peak_volt_All*0.1,'LineWidth',2, 'DisplayName','Voltage (mV*0.1)');
                ylabel('P2P & Volt (mV)');
                xlabel('Trial Number') ;
                legend;
                
               
            end
        end

        % Value changed function: AutoThresholdCheckBox
        function AutoThresholdCheckBoxValueChanged(app, event)
            value = app.AutoThresholdCheckBox.Value;
            if value == 1
                app.OnsetThEditField.Visible = "off";
                app.OnsetThLabel.Visible = "off";
                app.OffsetThEditFieldLabel.Visible = "off";
                app.OffsetThEditField.Visible = "off";
                app.UpdateThButton.Visible = "off";
            else 
                app.OnsetThEditField.Visible = "on";
                app.OnsetThLabel.Visible = "on";
                app.OffsetThEditFieldLabel.Visible = "on";
                app.OffsetThEditField.Visible = "on";
                app.UpdateThButton.Visible = "on";
          
            end
        end

        % Button pushed function: SaveProcessedDataButton_Hreflex
        function SaveProcessedDataButton_HreflexPushed(app, event)
            txt = "";
            txt_save = "Trial Number \t\tM-Reflex \tH-Reflex \tCurrent";
            
            [Mmax, Midx] = max(app.HReflex_Cropped_1_Peak2Peak_All);
            Mcurrent = app.HReflex_Peak_volt_All(Midx);
            [Hmax, Hidx] = max(app.HReflex_Cropped_2_Peak2Peak_All);
            Hcurrent = max(app.HReflex_Peak_volt_All(Hidx));


            for i = 1:length(app.HReflex_Cropped_1_Peak2Peak_All)
                AllCntSampleNumber = i;
                txt_save = txt_save + newline + [ num2str(AllCntSampleNumber) ' \t\t ' num2str(app.HReflex_Cropped_1_Peak2Peak_All(AllCntSampleNumber)) '\t' num2str(app.HReflex_Cropped_2_Peak2Peak_All(AllCntSampleNumber)) '\t' num2str(app.HReflex_Peak_volt_All(AllCntSampleNumber)*1)]; %updated 08.02.2024 no need to multiply  by 10, scalling is set for display only, not effect the original data %multiplied by 10 because we devide by 10 previously for scaling 
                
            end
            txt_save = txt_save + newline;
            txt_save = txt_save + newline;
            txt_save = txt_save + newline + ['M-Reflex_Max \t\t' num2str(Mmax) ' \t M-Reflex_Max Current \t\t ' num2str(Mcurrent) ]; 
            txt_save = txt_save + newline + ['H-Reflex_Max \t\t' num2str(Hmax) '\t H-Reflex_Max Current \t\t ' num2str(Hcurrent) ];
            txt_save = txt_save + newline + ['Mmax-trial Number: \t\t' num2str(Midx)];
            txt_save = txt_save + newline + ['Hmax-trial Number: \t\t' num2str(Hidx)];
            
            txt_save = txt_save + newline;
            txt_save = txt_save + newline + ['Hmax Fifty-Percent-P2P Value: \t\t' num2str(app.fiftyPercent_HmaxP2P)];
            txt_save = txt_save + newline + ['Hmax Fifty-Percent-P2P Current: \t\t' num2str(app.fiftyPercent_HmaxP2P_Current)];
            
            txt_save = txt_save + newline;
            txt_save = txt_save + newline + ['Hmax Five-Percent-P2P Value: \t\t' num2str(app.fivePercent_HmaxP2P)];
            txt_save = txt_save + newline + ['Hmax Five-Percent-P2P Current: \t\t' num2str(app.fivePercent_HmaxP2P_Current)];
            txt_save = txt_save + newline + ['Slope Value: \t\t' num2str(app.HreflexSlopeVal)];
            

            app.HTML.HTMLSource =txt;
             
             
            saveFileFunction(app, txt_save, app.currentFileNameOnly);
            
%             f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
%                     %[filename,path] = uigetfile('*.txt', 'Open text file','MultiSelect','on');
%                     [file_name, File_Path]= uiputfile("SavedHReflex.txt");
%                     delete(f);
%                     
%                     if exist(string(File_Path), 'file')
%                         fileID = fopen(fullfile(File_Path,file_name),'w');
%                         fprintf(fileID,txt_save);
%                         fclose(fileID);
%                     end
            
        end

        % Button down function: UIAxesHRAslope
        function UIAxesHRAslopeButtonDown(app, event)
            if ~isempty(app.HReflex_Cropped_1_Peak2Peak_All)
                
                fig = figure('Position', [50 350 560 420]);
                % Set the window title to "title on"
                set(fig, 'Name', 'H-reflex-ascending slope', 'NumberTitle', 'off');
                
                title('H-reflex-ascending slope');
                hold on;
                plot( app.HR_VHM_shorted(:,1), app.HR_VHM_shorted(:,2),"Color","blue", 'LineWidth',1, 'DisplayName','Peak-to-Peak -- M-max');
                plot( app.HR_VHM_shorted(:,1), app.HR_VHM_shorted(:,3),"Color","red", 'LineWidth',1, 'DisplayName','Peak-to-Peak -- H-Max');
                
                %smoothing the data
                %smoothMmax = lowpass(app.HR_VHM_shorted(:,2),30,1e3);  % Data, Frequency, 1e3
                %smoothHmax = lowpass(app.HR_VHM_shorted(:,3),30,1e3);
                %plot( app.HR_VHM_shorted(:,1), smoothMmax, 'LineWidth',2, 'DisplayName','Peak-to-Peak -- smooth M-max');
                %plot( app.HR_VHM_shorted(:,1), smoothHmax, 'LineWidth',2, 'DisplayName','Peak-to-Peak -- smooth H-Max');


                % using matlab polynomial function suggested by Prof. Cam
                p = polyfit(app.HR_VHM_shorted(:,1), app.HR_VHM_shorted(:,3), 8); % good at 6
                fitted_H = polyval(p, app.HR_VHM_shorted(:,1));
                plot(app.HR_VHM_shorted(:,1),fitted_H,'--r','LineWidth', 1,"Color",[1, 0.5, 0], 'DisplayName','Peak-to-Peak -- Linearfitted H-Max');
              
                smoothHmax = fitted_H;  % loaded fitted Hmax to smoothHmax
                
                coefficients = polyfit(app.HR_VHM_shorted(:,1),app.HR_VHM_shorted(:,3),8);
                %fitted_H_2 = polyval(coefficients, app.HR_VHM_shorted(:,1));
                %plot(app.HR_VHM_shorted(:,1),fitted_H_2,'--r','LineWidth', 1,"Color",[1, 0.5, 0.5], 'DisplayName','Peak-to-Peak -- Linearfitted H-Max_2');
                a = coefficients(1);
                b = coefficients(2);
                c = coefficients(3);
                a_8 = coefficients(1);
                a_7 = coefficients(2);
                a_6 = coefficients(3);
                a_5 = coefficients(4);
                a_4 = coefficients(5);
                a_3 = coefficients(6);
                a_2 = coefficients(7);
                a_1 = coefficients(8);
                a_0 = coefficients(9);
                
                % Differentiate the polynomial to get the slope formula: dy/dx = 2ax + b
%                 slopeFormula= @(x) 2*a*x + b;
                slopeFormula2 = @(x) 8*a_8*x.^7 + 7*a_7*x.^6 + 6*a_6*x.^5 + 5*a_5*x.^4 + 4*a_4*x.^3 + 3*a_3*x.^2 + 2*a_2*x + a_1;


                p = polyfit(app.HR_VHM_shorted(:,1), app.HR_VHM_shorted(:,2), 8); % good at 6
                fitted_M = polyval(p, app.HR_VHM_shorted(:,1));
                plot(app.HR_VHM_shorted(:,1),fitted_M,'--r','LineWidth', 1,"Color",[0.5, 0, 0.5],'DisplayName','Peak-to-Peak -- Linearfitted M-max');
                % H max when M-wave is 5% maximal - avg of a few response
                
                %TenPercent_Hmax = max(app.HR_VHM_shorted(:,3))*0.05;
                
                FivePercent_Hmax = max(smoothHmax)*0.05; % 5 percent taken.
                
                fivePercent_SmHmax = max(smoothHmax)*0.05;
                [~, idxf] = max(smoothHmax);
                
%                 disp('fivePercent_SmHmax: ');
%                 disp(fivePercent_SmHmax);
%                 
%                 disp("idxf: "+num2str(idxf));
%                 disp("Length: "+num2str(length(smoothHmax)));
%                 disp("Non smooth Hmax length: " + num2str(length(app.HR_VHM_shorted(:,1))));
                
                abs_diff = abs(smoothHmax(1:idxf) - fivePercent_SmHmax);
                [~, x_fivePercent_SmHmax_indx] = min(abs_diff);
                
                try
                    % Interpolate to find the corresponding x value
                    x_fivePercent_SmHmax = interp1(smoothHmax(1:idxf), app.HR_VHM_shorted(1:idxf,1), fivePercent_SmHmax, 'linear');
                        
                catch ME
                    x_fivePercent_SmHmax = smoothHmax(x_fivePercent_SmHmax_indx);
                end
                
%                plot(x_fivePercent_SmHmax, fivePercent_SmHmax, '-o',"Color","green",'DisplayName','');


                H_all_val = app.HR_VHM_shorted(:,3);
                m_all_val = app.HR_VHM_shorted(:,2);
                ntrail_all_val = app.HR_VHM_shorted(:,1);
                
                for i = 1:length(m_all_val)
                    if H_all_val(i) > FivePercent_Hmax
                        idx = i;
                        break
                    end
                end
                try
                    if (H_all_val(idx) - FivePercent_Hmax) > (H_all_val(idx-1) - FivePercent_Hmax)
                        idx = idx-1;      
                    end
                    
                catch ME
                    
                end

                
                %find 
                %abs_diff = abs(app.HR_VHM_shorted(:,3) - TenPercent_Hmax);
                %[~, idx] = min(abs_diff);


                plot(ntrail_all_val(idx), H_all_val(idx), 'ro', 'MarkerSize', 5, 'MarkerFaceColor', 'g','DisplayName','Five Percent Hmax Point');
                %plot(ntrail_all_val(idx), m_all_val(idx), '*','DisplayName','');


                fiveP_Hmax_Current = ntrail_all_val(idx);
                fiveP_Hmax_Val = H_all_val(idx);
                
                Horizontal_x = linspace(1, fiveP_Hmax_Current, length(smoothHmax));
                Horizontal_y = fiveP_Hmax_Val*ones(1,length(smoothHmax));
                plot(Horizontal_x,Horizontal_y,"Color","yellow",'DisplayName','');
                
                
                Vertical_y = linspace(min(smoothHmax), fiveP_Hmax_Val, length(smoothHmax));
                Vertical_x = fiveP_Hmax_Current*ones(1,length(smoothHmax));
                plot(Vertical_x, Vertical_y, "Color","yellow",'DisplayName','');
                
                
                %end Hmax when M-wave 
                
                % 1/2 Hmax p2p current
                
                %TenPercent_Hmax = max(app.HR_VHM_shorted(:,3))*0.5;
                
                fiftyPercent_SmHmax = max(smoothHmax)*0.5;
                
                abs_diff = abs(smoothHmax(1:idxf) - fiftyPercent_SmHmax);
                [~, x_fiftyPercent_SmHmax_indx] = min(abs_diff);
                
                try
                    % Interpolate to find the corresponding x value
                    x_fiftyPercent_SmHmax = interp1(smoothHmax(1:idxf), app.HR_VHM_shorted(1:idxf,1), fiftyPercent_SmHmax, 'linear');
                    
                catch ME
                    x_fiftyPercent_SmHmax = app.HR_VHM_shorted(x_fiftyPercent_SmHmax_indx,1);
                end
               
                
                %plot(app.HR_VHM_shorted(1:idxf,1)+1, smoothHmax(1:idxf));
                plot(x_fiftyPercent_SmHmax, fiftyPercent_SmHmax, 'bo', 'MarkerSize', 5, 'MarkerFaceColor', 'r','DisplayName','Fifty Percent Hmax Point');
                
                
%                 slopeAtPoints = slopeFormula(x_fiftyPercent_SmHmax);
%                 
%                 disp('Slope of H_reflex at FiftyPercent:');
%                 disp(slopeAtPoints);
                
                
%                 % Define the point of tangency
%                 x0 = x_fiftyPercent_SmHmax;
%                 y0 = a*x0^2 + b*x0 + c; % Calculate the corresponding y value using the quadratic equation
                
                x_point = x_fiftyPercent_SmHmax;
                % Calculate y_0 using polyval
                y_0 = polyval(coefficients, x_point);
                
                slope_at_x = slopeFormula2(x_point);
                % Define a small range around x_0 for plotting the tangent line
                x_range = linspace(x_point-5, x_point+5, 100);
                
                % Calculate the y values of the tangent line
                y_tangent = slope_at_x * (x_range - x_point) + y_0;
                
                plot(x_range, y_tangent, 'b--', 'LineWidth', 1,'DisplayName','Tangent Line');
                
%                 % Define the tangent line equation using the point-slope form
%                 tangentLine = @(x) slopeAtPoints*(x - x0) + y0;
%                 
%                 % Draw the tangent line near the point of tangency
%                 xTangent = linspace(x_fiftyPercent_SmHmax-1, x_fiftyPercent_SmHmax+1, 100); % Define a range around x0 for the tangent line
%                 yTangent = tangentLine(xTangent);
%                 plot(xTangent, yTangent, 'r--', 'LineWidth', 2);
                
                text(x_fiftyPercent_SmHmax+2, fiftyPercent_SmHmax, 'Slope: '+string(slope_at_x), 'Color', 'blue', 'FontSize', 10);
                disp('Slope of H_reflex_2 at FiftyPercent:');
                disp(slope_at_x);
                
                app.HreflexSlopeVal = slope_at_x;
%                 disp(slopeFormula(17.5));
%                 disp(slopeFormula(30));
%                 disp(slopeFormula(40));
%                 disp(slopeFormula(50));
%                 disp(slopeFormula(60));
                
                app.fivePercent_HmaxP2P = fiveP_Hmax_Val;
                app.fivePercent_HmaxP2P_Current = fiveP_Hmax_Current;
                
                app.fiftyPercent_HmaxP2P = fiftyPercent_SmHmax;
                app.fiftyPercent_HmaxP2P_Current = x_fiftyPercent_SmHmax;
                
%                 for i = 1:length(m_all_val)
%                     if H_all_val(i) > TenPercent_Hmax
%                         idx = i;
%                         break
%                     end
%                 end
%                 
%                 if (H_all_val(idx) - TenPercent_Hmax) > (H_all_val(idx-1) - TenPercent_Hmax)
%                     idx = idx-1;      
%                 end
%                 
%                 %kkk =  1:1:length(ntrail_all_val);
%                 
%                 kkk = linspace(1, max(ntrail_all_val), length(ntrail_all_val));
%                 h_kk = H_all_val(idx)*ones(1,length(ntrail_all_val));
%                 plot(kkk,h_kk,"Color","green");
                
                
                kkk = linspace(1, max(x_fiftyPercent_SmHmax), length(smoothHmax));
                h_kk = fiftyPercent_SmHmax*ones(1,length(smoothHmax));
                plot(kkk,h_kk,"Color","green",'DisplayName','');
                
                
                kkk = linspace(min(smoothHmax), fiftyPercent_SmHmax, length(smoothHmax));
                h_kk = x_fiftyPercent_SmHmax*ones(1,length(smoothHmax));
                plot(h_kk, kkk, "Color","green",'DisplayName','');
                % end1/2 Hmax p2p current


                ylabel('P2P (mV)');
                xlabel('Current Level (mV)') ;
                legend;
                

                app.SaveProcessedDataButton_Hreflex.Enable = "on";
               
            end
        end

        % Button pushed function: SaveResultButton
        function SaveResultButtonPushed(app, event)
                        txt = "";
            txt_save = "SN \t\tBeep Number \tResponse Time";
            
%             [Mmax, Midx] = max(app.HReflex_Cropped_1_Peak2Peak_All);
%             Mcurrent = app.HReflex_Peak_volt_All(Midx);
%             [Hmax, Hidx] = max(app.HReflex_Cropped_2_Peak2Peak_All);
%             Hcurrent = max(app.HReflex_Peak_volt_All(Hidx));
            
            %plot(app.ITA_beep_number , app.ITA_Beep_to_Resp);
            
            
            for i = 1:length(app.ITA_beep_number)
                AllCntBeepNumber = i;
                txt_save = txt_save + newline + [ num2str(AllCntBeepNumber) ' \t\t ' num2str(app.ITA_beep_number(AllCntBeepNumber)) '\t' num2str(app.ITA_Beep_to_Resp(AllCntBeepNumber)) ]; 
                
            end
            
            txt_save = txt_save + newline + "SN \t\tITA_BeepPositionBefResp \tBeep Number Time";
            
            %plot(app.ITA_BeepPositionBefResp ,app.ITA_beep_number );
            
            for i = 1:length(app.ITA_BeepPositionBefResp)
                AllCntBeepNumber = i;
                txt_save = txt_save + newline + [ num2str(AllCntBeepNumber) ' \t\t ' num2str(app.ITA_BeepPositionBefResp(AllCntBeepNumber)) '\t' num2str(app.ITA_beep_number(AllCntBeepNumber)) ]; 
                
            end
            
%             txt_save = txt_save + newline + ['M-Reflex_Max \t\t' num2str(Mmax) ' \t\t ' num2str(Mcurrent*10) ]; %multiplied by 10 because we devide by 10 previously for scaling 
%             txt_save = txt_save + newline + ['H-Reflex_Max \t\t\t' num2str(Hmax) '\t ' num2str(Hcurrent*10) ];
%             txt_save = txt_save + newline + ['Mmax-trial Number: \t\t' num2str(Midx)];
%             txt_save = txt_save + newline + ['Hmax-trial Number: \t\t' num2str(Hidx)];

             app.HTML.HTMLSource =txt;
            
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                    %[filename,path] = uigetfile('*.txt', 'Open text file','MultiSelect','on');
                    [file_name, File_Path]= uiputfile("SavedTTA_Result.txt");
                    delete(f);
                    
                    if exist(string(File_Path), 'file')
                        fileID = fopen(fullfile(File_Path,file_name),'w');
                        fprintf(fileID,txt_save);
                        fclose(fileID);
                    end
        end

        % Button pushed function: NextButton_TTACSP
        function NextButton_TTACSPPushed(app, event)
             if app.TTA_TrialNumber < app.TTA_TotalTrail
                app.TTA_TrialNumber = app.TTA_TrialNumber +1;
                app.PreviousButton_TTACSP.Enable = 'on';
                TTACSPFunction(app);
                if app.TTA_TrialNumber >= app.TTA_TotalTrail
                    app.NextButton_TTACSP.Enable = 'off';
                end
            else
                app.NextButton_TTACSP.Enable = 'off';
            end 
        end

        % Button pushed function: PreviousButton_TTACSP
        function PreviousButton_TTACSPPushed(app, event)
            if app.TTA_TrialNumber > 1
                app.TTA_TrialNumber = app.TTA_TrialNumber -1;
                app.NextButton_TTACSP.Enable = 'on';
                TTACSPFunction(app);
                if app.TTA_TrialNumber <= 1
                   app.PreviousButton_TTACSP.Enable = 'off'; 
                end
            else
                app.PreviousButton_TTACSP.Enable = 'off';
                
            end
        end

        % Button pushed function: SplitButton
        function SplitButtonPushed(app, event)
%             cla(app.UIAxesTTACSP);
%             window  = app.globalLastIndices(1);
%             wTime = app.glboalTime(window-200:window+2000);
%             wTTACSP_EMG = app.globalTTACSP_EMG(window-200:window+2000);
%             hold(app.UIAxesTTACSP,"on");
%             plot(app.UIAxesTTACSP, wTime, wTTACSP_EMG);
                app.SplitButtonPressed = 1;
                splittedCSP(app);
        end

        % Button pushed function: NextButton_splittedCSP
        function NextButton_splittedCSPPushed(app, event)
            if app.TTA_SplitNumber < app.TTA_TotalSplit
                app.TTA_SplitNumber = app.TTA_SplitNumber +1;
                app.PreviousButton_splittedCSP.Enable = 'on';
                splittedCSP(app);
                if app.TTA_SplitNumber >= app.TTA_TotalSplit
                    app.NextButton_splittedCSP.Enable = 'off';
                end
            else
                app.NextButton_splittedCSP.Enable = 'off';
            end 
        end

        % Button pushed function: PreviousButton_splittedCSP
        function PreviousButton_splittedCSPPushed(app, event)
            if app.TTA_SplitNumber > 1
                app.TTA_SplitNumber = app.TTA_SplitNumber -1;
                app.NextButton_splittedCSP.Enable = 'on';
                splittedCSP(app);
                if app.TTA_SplitNumber <= 1
                   app.PreviousButton_splittedCSP.Enable = 'off'; 
                end
            else
                app.PreviousButton_splittedCSP.Enable = 'off';
                
            end
        end

        % Button pushed function: SubmitButtonTTACSP
        function SubmitButtonTTACSPPushed(app, event)
            app.ThresholdTTACSP = str2num(app.ThresholdEditFieldTTACSP.Value);
            TTACSPFunction(app);
        end

        % Value changed function: LeftboundaryEditField
        function LeftboundaryEditFieldValueChanged(app, event)
            app.LeftboundaryTTACSP = app.LeftboundaryEditField.Value;
            splittedCSP(app);
        end

        % Value changed function: RightboundaryEditField
        function RightboundaryEditFieldValueChanged(app, event)
            app.RightboundaryTTACSP = app.RightboundaryEditField.Value;
            splittedCSP(app);
        end

        % Value changed function: HighpassFreqEditFieldTTACSP
        function HighpassFreqEditFieldTTACSPValueChanged(app, event)
            app.TTACSPhpFreq = app.HighpassFreqEditFieldTTACSP.Value;
            splittedCSP(app);
        end

        % Value changed function: PedalThresholdEditField
        function PedalThresholdEditFieldValueChanged(app, event)
            app.TTA_PadelThreshold = app.PedalThresholdEditField.Value;
            TTAFunction(app);
        end

        % Value changed function: TTA_AutoThresholdCheckBox
        function TTA_AutoThresholdCheckBoxValueChanged(app, event)
            value = app.TTA_AutoThresholdCheckBox.Value;
            TTAFunction(app);
        end

        % Button pushed function: SavaDataforMLButton
        function SavaDataforMLButtonPushed(app, event)
            endPoint = 0;
            %app.sampleNumber = 1;
            NumberOfCountedSample = 0;
            %AllCntSampleNumber = 1;
            TotalCSPtime = 0; 
            txt = "";
            txt_Labelled_Data = "";
            %txt_save = "Trial Number \t\tMaximum(uV) \tMaximum Time(s) \tMinimum(uV) \tMinimum Time(s) \tPeak-Peak(uV) \tCSP Duration(s)  \tCSP Onset(s) \tCSP Offset(s) \tRMS Before Stimulation \tRMS after CSP";
            %maxLimit = round(app.SampleDataSize(1)/(app.dataPointPerSample+7));
            for i = 1:app.CSP_TotalTrail
%                 app.startPoint = endPoint+ 7;
%                 AllCntSampleNumber = i;
%                 endPoint = app.startPoint+app.dataPointPerSample;
                AllCntSampleNumber = i;
                app.startPoint = app.CSP_IntervalIndx(AllCntSampleNumber)+6;
                app.endPoint = app.CSP_IntervalIndx(AllCntSampleNumber+1)-1;

                [CSP, spOnSet, spOffSet, FirstResp_maxVal, CSPmaxTime, FirstResp_minVal,CSPminTime, peak2peak, init_rms, afterCSP_rms, silentPeriodOnSetIndx, silentPeriodOffSetIndx] = func3(app,app.endPoint, app.startPoint, AllCntSampleNumber);
                if CSP <= 0
                    txt = txt + newline + ['<a>CSP Sample ' num2str(i) ' :</a><a style="color:red;"> Not Detected</a><br>'];
                    %txt_save = txt_save + newline + [ num2str(i) ' \tNot Detected'];
                    txt_Labelled_Data = txt_Labelled_Data + [num2str(app.Time_Data) ' ' num2str(app.Amplitude_Data) ' ' num2str(0) ' ' num2str(0) ' ' num2str(0) ' ' num2str(0) ' ' num2str(0) ' ' num2str(0) ' ' num2str(0) ' ' num2str(0) ' ' num2str(0) ' ' num2str(0) ' ' num2str(0) ' ' num2str(0)] +newline;
                else
                    txt = txt + newline + ['<a>CSP Sample ' num2str(i) ' : ' num2str(CSP) ' s </a><br>'];
                    %txt_save = txt_save + newline + [ num2str(i) ' \t\t ' num2str(FirstResp_maxVal) '\t' num2str(CSPmaxTime) '\t' num2str(FirstResp_minVal) '\t' num2str(CSPminTime) '\t' num2str(peak2peak) '\t' num2str(CSP) '\t' num2str(spOnSet) '\t' num2str(spOffSet) '\t' num2str(init_rms) '\t' num2str(afterCSP_rms)];
                    txt_Labelled_Data = txt_Labelled_Data + [num2str(app.Time_Data) ' ' num2str(app.Amplitude_Data) ' ' num2str(FirstResp_maxVal) ' ' num2str(CSPmaxTime) ' ' num2str(FirstResp_minVal) ' ' num2str(CSPminTime) ' ' num2str(peak2peak) ' ' num2str(CSP) ' ' num2str(spOnSet) ' ' num2str(spOffSet) ' ' num2str(init_rms) ' ' num2str(afterCSP_rms) ' ' num2str(silentPeriodOnSetIndx) ' ' num2str(silentPeriodOffSetIndx)] +newline;
                    NumberOfCountedSample = NumberOfCountedSample+1;
                    TotalCSPtime = TotalCSPtime + CSP;
                end
                %txt_Labelled_Data = txt_Labelled_Data + [num2str(app.Time_Data') '\t' num2str(app.Amplitude_Data') '\t'] +newline;
            end

                txt = txt+ newline + newline + ['<b><a style="color:blue;">Avg. CSP: ' num2str(TotalCSPtime/NumberOfCountedSample) ' s </a></b><br>'];
                %txt_save = txt_save+ newline + newline + ['\t\t\t\t\t\tAvg. CSP Duration \t' num2str(TotalCSPtime/NumberOfCountedSample)];
            %app.DisplayAllCSPLabel.Text = txt;
            app.HTML.HTMLSource =txt;
            
%             fileID = fopen('SavedResult.txt','w');
%             fprintf(fileID,txt_save);
%             fclose(fileID);
            %app.endPoint = 0;
            
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                    %[filename,path] = uigetfile('*.txt', 'Open text file','MultiSelect','on');
%                     [file_name, File_Path]= uiputfile("SavedCSPResult.txt");
%                     delete(f);
%                     
%                     if exist(string(File_Path), 'file')
%                         fileID = fopen(fullfile(File_Path,file_name),'w');
%                         fprintf(fileID,txt_save);
%                         fclose(fileID);
%                     end
                    
                    [file_name, File_Path]= uiputfile(string(app.fileNameOnly) + "_L.txt");
                    delete(f);
                    
                    if exist(string(File_Path), 'file')
                        fileID = fopen(fullfile(File_Path,file_name),'w');
                        fprintf(fileID,txt_Labelled_Data);
                        fclose(fileID);
                    end
        end

        % Value changed function: DeveloperModeCheckBox
        function DeveloperModeCheckBoxValueChanged(app, event)
            value = app.DeveloperModeCheckBox.Value;
            if value == 1
                app.SavaDataforMLButton.Visible = "on";
                app.Test_Label_1.Visible = "on";
                app.ITT_Th_LevelLabel.Visible  = "on";
                app.ITT_ROI_LevelLabel.Visible  = "on";
                app.ITT_BaseOffsetLabel.Visible  = "on";
                app.ITT_ControlPeakOffsetLabel.Visible  = "on";
            else
                app.SavaDataforMLButton.Visible = "off";
                app.Test_Label_1.Visible = "off";
                app.ITT_Th_LevelLabel.Visible  = "off";
                app.ITT_ROI_LevelLabel.Visible  = "off";
                app.ITT_BaseOffsetLabel.Visible = "off";
                app.ITT_ControlPeakOffsetLabel.Visible  = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.URNeuroAppUIFigure = uifigure('Visible', 'off');
            app.URNeuroAppUIFigure.Name = 'UR Neuro App';
            app.URNeuroAppUIFigure.Icon = 'icon.png';
            app.FileMenu = uimenu(app.URNeuroAppUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.CalibrationMenu = uimenu(app.URNeuroAppUIFigure);
            app.CalibrationMenu.Text = 'Calibration';
            app.OpenCalibrationMenu = uimenu(app.CalibrationMenu);
            app.OpenCalibrationMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenCalibrationMenuSelected, true);
            app.OpenCalibrationMenu.Visible = 'off';
            app.OpenCalibrationMenu.Text = 'Open Calibration';
            app.MVCGripForceMenu = uimenu(app.CalibrationMenu);
            app.MVCGripForceMenu.MenuSelectedFcn = createCallbackFcn(app, @MVCGripForceMenuSelected, true);
            app.MVCGripForceMenu.Text = 'MVC Grip Force';
            app.HelpMenu = uimenu(app.URNeuroAppUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.TabGroup = uitabgroup(app.URNeuroAppUIFigure);
            app.CSPTab = uitab(app.TabGroup);
            app.CSPTab.Title = 'CSP';
            app.CSPTab.ButtonDownFcn = createCallbackFcn(app, @CSPTabButtonDown, true);
            app.UIAxes = uiaxes(app.CSPTab);
            title(app.UIAxes, 'Cortical Silent Period')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.SampleNumberLabel = uilabel(app.CSPTab);
            app.SampleNumberLabel.Text = 'Sample Number:';
            app.Label = uilabel(app.CSPTab);
            app.NextButton = uibutton(app.CSPTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.CSPTab, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.HTML = uihtml(app.CSPTab);
            app.ShowAllSaveButton = uibutton(app.CSPTab, 'push');
            app.ShowAllSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAllSaveButtonPushed, true);
            app.ShowAllSaveButton.Text = 'Show All & Save';
            app.OffsetButton = uibutton(app.CSPTab, 'push');
            app.OffsetButton.ButtonPushedFcn = createCallbackFcn(app, @OffsetButtonPushed, true);
            app.OffsetButton.Text = 'Offset';
            app.OnsetButton = uibutton(app.CSPTab, 'push');
            app.OnsetButton.ButtonPushedFcn = createCallbackFcn(app, @OnsetButtonPushed, true);
            app.OnsetButton.Text = 'Onset';
            app.Disp_CSPLabel = uilabel(app.CSPTab);
            app.Disp_CSPLabel.Text = 'Disp_CSP';
            app.Disp_RMSLabel = uilabel(app.CSPTab);
            app.Disp_RMSLabel.Text = 'Disp_RMS';
            app.Disp_RMS_After_CSPLabel = uilabel(app.CSPTab);
            app.Disp_RMS_After_CSPLabel.Text = 'Disp_RMS_After_CSP';
            app.Disp_uV_MaxLabel = uilabel(app.CSPTab);
            app.Disp_uV_MaxLabel.Text = 'Disp_uV_Max';
            app.Disp_uV_MinLabel = uilabel(app.CSPTab);
            app.Disp_uV_MinLabel.Text = 'Disp_uV_Min';
            app.Disp_Peak2PeakLabel = uilabel(app.CSPTab);
            app.Disp_Peak2PeakLabel.Text = 'Disp_Peak2Peak';
            app.Disp_CSP_OffSetLabel = uilabel(app.CSPTab);
            app.Disp_CSP_OffSetLabel.Text = 'Disp_CSP_OffSet';
            app.Disp_CSP_OnSetLabel = uilabel(app.CSPTab);
            app.Disp_CSP_OnSetLabel.Text = 'Disp_CSP_OnSet';
            app.Test_Label_1 = uilabel(app.CSPTab);
            app.Test_Label_1.Visible = 'off';
            app.Test_Label_1.Text = 'Test_Label_1';
            app.Test_Label_2 = uilabel(app.CSPTab);
            app.Test_Label_2.Visible = 'off';
            app.Test_Label_2.Text = 'Test_Label_2';
            app.Test_Label_3 = uilabel(app.CSPTab);
            app.Test_Label_3.Visible = 'off';
            app.Test_Label_3.Text = 'Test_Label_3';
            app.OnsetThLabel = uilabel(app.CSPTab);
            app.OnsetThLabel.Text = 'On-set Th';
            app.OnsetThEditField = uieditfield(app.CSPTab, 'text');
            app.OffsetThEditFieldLabel = uilabel(app.CSPTab);
            app.OffsetThEditFieldLabel.Text = 'Off-set Th';
            app.OffsetThEditField = uieditfield(app.CSPTab, 'text');
            app.UpdateThButton = uibutton(app.CSPTab, 'push');
            app.UpdateThButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateThButtonPushed, true);
            app.UpdateThButton.Text = 'Update Th';
            app.Test_Label_4 = uilabel(app.CSPTab);
            app.Test_Label_4.Visible = 'off';
            app.Test_Label_4.Text = 'Test_Label_4';
            app.AutoThresholdCheckBox = uicheckbox(app.CSPTab);
            app.AutoThresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoThresholdCheckBoxValueChanged, true);
            app.AutoThresholdCheckBox.Text = 'Auto Threshold ';
            app.SavaDataforMLButton = uibutton(app.CSPTab, 'push');
            app.SavaDataforMLButton.ButtonPushedFcn = createCallbackFcn(app, @SavaDataforMLButtonPushed, true);
            app.SavaDataforMLButton.Visible = 'off';
            app.SavaDataforMLButton.Text = 'Sava Data for ML';
            app.MEPATab = uitab(app.TabGroup);
            app.MEPATab.Title = 'MEPA';
            app.UIAxes2 = uiaxes(app.MEPATab);
            title(app.UIAxes2, 'Peak-to-Peak Amplitude Measurement')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.NextButton_2 = uibutton(app.MEPATab, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Text = 'Next';
            app.PreviousButton_2 = uibutton(app.MEPATab, 'push');
            app.PreviousButton_2.ButtonPushedFcn = createCallbackFcn(app, @PreviousButton_2Pushed, true);
            app.PreviousButton_2.Text = 'Previous';
            app.SampleNumberLabel_2 = uilabel(app.MEPATab);
            app.SampleNumberLabel_2.Text = 'Sample Number:';
            app.Label_2 = uilabel(app.MEPATab);
            app.Label_2.Text = 'Label:';
            app.SelectROIButton = uibutton(app.MEPATab, 'push');
            app.SelectROIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectROIButtonPushed, true);
            app.SelectROIButton.Text = 'Select ROI';
            app.ProcessAllSaveButton = uibutton(app.MEPATab, 'push');
            app.ProcessAllSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessAllSaveButtonPushed, true);
            app.ProcessAllSaveButton.Text = 'Process All & Save';
            app.MEPAIdentificationMarkerLabel = uilabel(app.MEPATab);
            app.MEPAIdentificationMarkerLabel.Text = 'MEPA Identification Marker';
            app.ChangeThresholdLabel = uilabel(app.MEPATab);
            app.ChangeThresholdLabel.Text = 'Change Threshold';
            app.ThresholdEditField_MEPA = uieditfield(app.MEPATab, 'text');
            app.ThresholdEditField_MEPA.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditField_MEPAValueChanged, true);
            app.TheMEPAThesholdissetasLabel = uilabel(app.MEPATab);
            app.TheMEPAThesholdissetasLabel.Text = 'The MEPA Theshold is set as ';
            app.ThLabel_MEPA = uilabel(app.MEPATab);
            app.ThLabel_MEPA.Text = 'Th';
            app.MPLabel = uilabel(app.MEPATab);
            app.MPLabel.Text = 'MP';
            app.ThLabel = uilabel(app.MEPATab);
            app.ThLabel.Text = 'Th';
            app.TestLebel3Label = uilabel(app.MEPATab);
            app.TestLebel3Label.Visible = 'off';
            app.TestLebel3Label.Text = 'Test Lebel 3';
            app.MotorPotentialLabel = uilabel(app.MEPATab);
            app.MotorPotentialLabel.Text = 'Motor Potential :';
            app.ThresholdLabel = uilabel(app.MEPATab);
            app.ThresholdLabel.Text = 'Threshold :';
            app.ITTTab = uitab(app.TabGroup);
            app.ITTTab.Title = 'ITT';
            app.UIAxesITT = uiaxes(app.ITTTab);
            title(app.UIAxesITT, 'Interpolated Twitch')
            xlabel(app.UIAxesITT, 'Time')
            ylabel(app.UIAxesITT, 'Amplitude')
            zlabel(app.UIAxesITT, 'Z')
            app.ITTdirectoryLebel = uilabel(app.ITTTab);
            app.ITTdirectoryLebel.Text = 'ITTdirectoryLabel';
            app.ITT_HTML = uihtml(app.ITTTab);
            app.SaveButton_2 = uibutton(app.ITTTab, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.SetThresholdButton = uibutton(app.ITTTab, 'push');
            app.SetThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @SetThresholdButtonPushed, true);
            app.SetThresholdButton.Text = 'Set Threshold';
            app.SetROIButton = uibutton(app.ITTTab, 'push');
            app.SetROIButton.ButtonPushedFcn = createCallbackFcn(app, @SetROIButtonPushed, true);
            app.SetROIButton.Text = 'Set ROI';
            app.RefreshITTButton = uibutton(app.ITTTab, 'push');
            app.RefreshITTButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshITTButtonPushed, true);
            app.RefreshITTButton.Text = 'Autoset';
            app.ITT_Th_LevelLabel = uilabel(app.ITTTab);
            app.ITT_Th_LevelLabel.Text = 'ITT_Th_Level';
            app.ITT_ROI_LevelLabel = uilabel(app.ITTTab);
            app.ITT_ROI_LevelLabel.Text = 'ITT_ROI_Level';
            app.ROIafterFirstStimulationLabel = uilabel(app.ITTTab);
            app.ROIafterFirstStimulationLabel.Text = 'ROI after First Stimulation';
            app.StimulationDetectionThresholdLabel = uilabel(app.ITTTab);
            app.StimulationDetectionThresholdLabel.Text = 'Stimulation Detection Threshold';
            app.ITT_BaseOffsetLabel = uilabel(app.ITTTab);
            app.ITT_BaseOffsetLabel.Text = 'ITT_Base Offset';
            app.ITT_ControlPeakOffsetLabel = uilabel(app.ITTTab);
            app.ITT_ControlPeakOffsetLabel.Text = 'ITT_Control Peak Offset';
            app.TTATab = uitab(app.TabGroup);
            app.TTATab.Title = 'TTA';
            app.UIAxesTTA = uiaxes(app.TTATab);
            title(app.UIAxesTTA, 'Toe Tapping Accuracy')
            xlabel(app.UIAxesTTA, 'Time')
            ylabel(app.UIAxesTTA, 'Amplitude')
            zlabel(app.UIAxesTTA, 'Z')
            app.TTA_BeepVsPos = uiaxes(app.TTATab);
            title(app.TTA_BeepVsPos, 'Beep vs Position')
            xlabel(app.TTA_BeepVsPos, 'Beep Position Before Response')
            ylabel(app.TTA_BeepVsPos, 'Beep Number')
            zlabel(app.TTA_BeepVsPos, 'Z')
            app.TTA_BeepVsPos.ButtonDownFcn = createCallbackFcn(app, @TTA_BeepVsPosButtonDown, true);
            app.TTA_RespVsBeep = uiaxes(app.TTATab);
            title(app.TTA_RespVsBeep, 'Response vs Beep')
            xlabel(app.TTA_RespVsBeep, 'Beep Number')
            ylabel(app.TTA_RespVsBeep, 'Response Time')
            zlabel(app.TTA_RespVsBeep, 'Z')
            app.TTA_RespVsBeep.ButtonDownFcn = createCallbackFcn(app, @TTA_RespVsBeepButtonDown, true);
            app.PreviousButton_TTA = uibutton(app.TTATab, 'push');
            app.PreviousButton_TTA.ButtonPushedFcn = createCallbackFcn(app, @PreviousButton_TTAPushed, true);
            app.PreviousButton_TTA.Text = 'Previous';
            app.NextButton_TTA = uibutton(app.TTATab, 'push');
            app.NextButton_TTA.ButtonPushedFcn = createCallbackFcn(app, @NextButton_TTAPushed, true);
            app.NextButton_TTA.Text = 'Next';
            app.SelectTaskDropDownLabel = uilabel(app.TTATab);
            app.SelectTaskDropDownLabel.Text = 'Select Task';
            app.SelectTaskDropDown = uidropdown(app.TTATab);
            app.SelectTaskDropDown.Items = {'LNLC', 'LNHC', 'HNLC', 'HNHC'};
            app.SelectTaskDropDown.Value = 'HNLC';
            app.ShowMoreButton = uibutton(app.TTATab, 'push');
            app.ShowMoreButton.ButtonPushedFcn = createCallbackFcn(app, @ShowMoreButtonPushed, true);
            app.ShowMoreButton.Enable = 'off';
            app.ShowMoreButton.Text = 'Show More';
            app.TrialNoLabel = uilabel(app.TTATab);
            app.TrialNoLabel.Text = 'Trial No.: ';
            app.PedalGainEditFieldLabel = uilabel(app.TTATab);
            app.PedalGainEditFieldLabel.Text = 'Pedal Gain';
            app.PedalGainEditField = uieditfield(app.TTATab, 'text');
            app.PedalGainEditField.ValueChangedFcn = createCallbackFcn(app, @PedalGainEditFieldValueChanged, true);
            app.BeepGainEditFieldLabel = uilabel(app.TTATab);
            app.BeepGainEditFieldLabel.Text = 'Beep Gain';
            app.BeepGainEditField = uieditfield(app.TTATab, 'text');
            app.BeepGainEditField.ValueChangedFcn = createCallbackFcn(app, @BeepGainEditFieldValueChanged, true);
            app.PedalOffsetEditFieldLabel = uilabel(app.TTATab);
            app.PedalOffsetEditFieldLabel.Text = 'Pedal Offset';
            app.PedalOffsetEditField = uieditfield(app.TTATab, 'numeric');
            app.PedalOffsetEditField.ValueChangedFcn = createCallbackFcn(app, @PedalOffsetEditFieldValueChanged, true);
            app.TTA_FileDirectoryLabel = uilabel(app.TTATab);
            app.TTA_FileDirectoryLabel.Text = 'File Directory:';
            app.SaveResultButton = uibutton(app.TTATab, 'push');
            app.SaveResultButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultButtonPushed, true);
            app.SaveResultButton.Text = 'Save Result';
            app.PedalThresholdEditFieldLabel = uilabel(app.TTATab);
            app.PedalThresholdEditFieldLabel.Text = 'Pedal Threshold';
            app.PedalThresholdEditField = uieditfield(app.TTATab, 'numeric');
            app.PedalThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @PedalThresholdEditFieldValueChanged, true);
            app.TTA_AutoThresholdCheckBox = uicheckbox(app.TTATab);
            app.TTA_AutoThresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @TTA_AutoThresholdCheckBoxValueChanged, true);
            app.TTA_AutoThresholdCheckBox.Text = 'Auto Threshold';
            app.HReflexTab = uitab(app.TabGroup);
            app.HReflexTab.Title = 'H-Reflex';
            app.UIAxesHReflex = uiaxes(app.HReflexTab);
            title(app.UIAxesHReflex, 'H-Reflex')
            xlabel(app.UIAxesHReflex, 'Time')
            ylabel(app.UIAxesHReflex, 'Voltage & Amplitude')
            zlabel(app.UIAxesHReflex, 'Z')
            app.UIAxesHRoutput = uiaxes(app.HReflexTab);
            title(app.UIAxesHRoutput, 'H-reflex Result')
            xlabel(app.UIAxesHRoutput, 'Trial Number')
            ylabel(app.UIAxesHRoutput, 'P2P and Volt (mV)')
            zlabel(app.UIAxesHRoutput, 'Z')
            app.UIAxesHRoutput.ButtonDownFcn = createCallbackFcn(app, @UIAxesHRoutputButtonDown, true);
            app.UIAxesHRAslope = uiaxes(app.HReflexTab);
            title(app.UIAxesHRAslope, 'H-reflex-ascending slope')
            xlabel(app.UIAxesHRAslope, 'Voltage (mV)')
            ylabel(app.UIAxesHRAslope, 'P2P (mV)')
            zlabel(app.UIAxesHRAslope, 'Z')
            app.UIAxesHRAslope.ButtonDownFcn = createCallbackFcn(app, @UIAxesHRAslopeButtonDown, true);
            app.SaveProcessedDataButton_Hreflex = uibutton(app.HReflexTab, 'push');
            app.SaveProcessedDataButton_Hreflex.ButtonPushedFcn = createCallbackFcn(app, @SaveProcessedDataButton_HreflexPushed, true);
            app.SaveProcessedDataButton_Hreflex.Enable = 'off';
            app.SaveProcessedDataButton_Hreflex.Text = 'Save Processed Data';
            app.Hreflex_FileDirectoryLabel = uilabel(app.HReflexTab);
            app.Hreflex_FileDirectoryLabel.Text = 'File Directory:';
            app.MReflexTab = uitab(app.TabGroup);
            app.MReflexTab.Title = 'M-Reflex';
            app.UIAxesMReflex = uiaxes(app.MReflexTab);
            title(app.UIAxesMReflex, 'M-Reflex')
            xlabel(app.UIAxesMReflex, 'Time')
            ylabel(app.UIAxesMReflex, 'Voltage & Amplitude')
            zlabel(app.UIAxesMReflex, 'Z')
            app.UIAxesMRoutput = uiaxes(app.MReflexTab);
            title(app.UIAxesMRoutput, 'Hreflex Result')
            xlabel(app.UIAxesMRoutput, 'Trial Number')
            ylabel(app.UIAxesMRoutput, 'P2P and Volt')
            zlabel(app.UIAxesMRoutput, 'Z')
            app.MaxLabel = uilabel(app.MReflexTab);
            app.MaxLabel.Text = 'Max:';
            app.MinLabel = uilabel(app.MReflexTab);
            app.MinLabel.Text = 'Min:';
            app.TTACSPTab = uitab(app.TabGroup);
            app.TTACSPTab.Title = 'TTA CSP';
            app.UIAxesTTACSP = uiaxes(app.TTACSPTab);
            title(app.UIAxesTTACSP, 'TTA CSP')
            xlabel(app.UIAxesTTACSP, 'Time')
            ylabel(app.UIAxesTTACSP, 'Amplitude')
            zlabel(app.UIAxesTTACSP, 'Z')
            app.CSP_DurationLabel = uilabel(app.TTACSPTab);
            app.CSP_DurationLabel.Visible = 'off';
            app.CSP_DurationLabel.Text = 'CSP_Duration:';
            app.FileDirectoryLabelTTACSP = uilabel(app.TTACSPTab);
            app.FileDirectoryLabelTTACSP.Text = 'File Directory:';
            app.SplitNumberLabel = uilabel(app.TTACSPTab);
            app.SplitNumberLabel.Text = 'Split Number';
            app.SubmitButtonTTACSP = uibutton(app.TTACSPTab, 'push');
            app.SubmitButtonTTACSP.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonTTACSPPushed, true);
            app.SubmitButtonTTACSP.Text = 'Update Th';
            app.NextButton_splittedCSP = uibutton(app.TTACSPTab, 'push');
            app.NextButton_splittedCSP.ButtonPushedFcn = createCallbackFcn(app, @NextButton_splittedCSPPushed, true);
            app.NextButton_splittedCSP.Text = 'Next';
            app.PreviousButton_splittedCSP = uibutton(app.TTACSPTab, 'push');
            app.PreviousButton_splittedCSP.ButtonPushedFcn = createCallbackFcn(app, @PreviousButton_splittedCSPPushed, true);
            app.PreviousButton_splittedCSP.Text = 'Previous';
            app.SplitButton = uibutton(app.TTACSPTab, 'push');
            app.SplitButton.ButtonPushedFcn = createCallbackFcn(app, @SplitButtonPushed, true);
            app.SplitButton.Text = 'Split';
            app.PreviousButton_TTACSP = uibutton(app.TTACSPTab, 'push');
            app.PreviousButton_TTACSP.ButtonPushedFcn = createCallbackFcn(app, @PreviousButton_TTACSPPushed, true);
            app.PreviousButton_TTACSP.Text = 'Previous';
            app.NextButton_TTACSP = uibutton(app.TTACSPTab, 'push');
            app.NextButton_TTACSP.ButtonPushedFcn = createCallbackFcn(app, @NextButton_TTACSPPushed, true);
            app.NextButton_TTACSP.Text = 'Next';
            app.ThresholdEditFieldTTACSP = uieditfield(app.TTACSPTab, 'text');
            app.ThresholdEditFieldLabel = uilabel(app.TTACSPTab);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.LeftboundaryEditField = uieditfield(app.TTACSPTab, 'numeric');
            app.LeftboundaryEditField.ValueChangedFcn = createCallbackFcn(app, @LeftboundaryEditFieldValueChanged, true);
            app.LeftboundaryEditFieldLabel = uilabel(app.TTACSPTab);
            app.LeftboundaryEditFieldLabel.Text = 'Left boundary';
            app.RightboundaryEditField = uieditfield(app.TTACSPTab, 'numeric');
            app.RightboundaryEditField.ValueChangedFcn = createCallbackFcn(app, @RightboundaryEditFieldValueChanged, true);
            app.RightboundaryEditFieldLabel = uilabel(app.TTACSPTab);
            app.RightboundaryEditFieldLabel.Text = 'Right boundary';
            app.HighpassFreqEditFieldTTACSP = uieditfield(app.TTACSPTab, 'numeric');
            app.HighpassFreqEditFieldTTACSP.ValueChangedFcn = createCallbackFcn(app, @HighpassFreqEditFieldTTACSPValueChanged, true);
            app.HighpassFreqEditFieldLabel = uilabel(app.TTACSPTab);
            app.HighpassFreqEditFieldLabel.Text = 'Highpass Freq';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.SampleSizeEditFieldLabel = uilabel(app.SettingsTab);
            app.SampleSizeEditFieldLabel.Text = 'Sample Size';
            app.SampleSizeEditField = uieditfield(app.SettingsTab, 'text');
            app.SampleSizeEditField.ValueChangedFcn = createCallbackFcn(app, @SampleSizeEditFieldValueChanged, true);
            app.SaveButton = uibutton(app.SettingsTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Label_3 = uilabel(app.SettingsTab);
            app.Label_3.Text = '(2000, 1200,1000)';
            app.SSValLabel = uilabel(app.SettingsTab);
            app.SSValLabel.Text = 'SS Val';
            app.DeveloperModeCheckBox = uicheckbox(app.SettingsTab);
            app.DeveloperModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @DeveloperModeCheckBoxValueChanged, true);
            app.DeveloperModeCheckBox.Text = 'Developer Mode';
            % Show the figure after all components are created
            app.URNeuroAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.URNeuroAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.URNeuroAppUIFigure)
        end
    end
end