classdef gripForce < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        FileMenu           matlab.ui.container.Menu
        OpenMenu           matlab.ui.container.Menu
        HelpMenu           matlab.ui.container.Menu
        TimeatmaxValLabel  matlab.ui.control.Label
        MaxValueLabel      matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
        GripForce_data = {};
        filepath = "";
    end
    
    methods (Access = public)
        
        function results = GripForceFunction(app)
            startPoint = 4;
            endPoint = height(app.GripForce_data);
            
            Time = app.GripForce_data(startPoint:endPoint,1);
            GripForce = app.GripForce_data(startPoint:endPoint,4);
            
            Time = Time{:,:};
            GripForce = GripForce{:,:};
            
            [GripmaxVal, GripmaxIndx] = max(GripForce);
            plot(app.UIAxes, Time, GripForce);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, Time(GripmaxIndx), GripForce(GripmaxIndx), '-o');
            app.MaxValueLabel.Text = "Max Value: " + string(GripmaxVal);
            app.TimeatmaxValLabel.Text = "Time at Max Value: " +string(Time(GripmaxIndx));
            
            %disp(GripmaxVal);
            %fprintf('%f\n', GripmaxVal)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
             app.GripForce_data ={};
            %% clearing done
            
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [filename,path] = uigetfile('*.txt', 'Open text file','MultiSelect','on');
            delete(f);
            
            %[filename, path] = uigetfile('*.*');
            txt = string(path)+string(filename);
            app.filepath = txt;
            %app.MaxValueLabel.Text = txt;
            %startupConfig(app);
            
            if app.filepath ~=  ""
                if app.filepath ~= "00"
                    app.GripForce_data= readtable(app.filepath,'PreserveVariableNames',true);
                    %func2(app, app.endPoint, app.startPoint, app.sampleNumber);
                    GripForceFunction(app);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'MVC Grip')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Newton')
            zlabel(app.UIAxes, 'Z')
            app.MaxValueLabel = uilabel(app.UIFigure);
            app.MaxValueLabel.Text = 'Max Value:';
            app.TimeatmaxValLabel = uilabel(app.UIFigure);
            app.TimeatmaxValLabel.Text = 'Time at maxVal:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gripForce

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end