classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        PreviousButton  matlab.ui.control.Button
        NextButton      matlab.ui.control.Button
        UIAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
        endPoint = 0;

    end
    
    methods (Access = public)
        function startupConfig(app)
            
        end
        
        function results = func2(app,endPoint, startPoint)
            
            cla(app.UIAxes);
            %global endPoint ;
            data= readcell('data2.txt');
            DataSize = size(data);


%             startPoint = endPoint+ 7;
%             endPoint = startPoint+1999;
            %app.endPoint = endPoint;
            Time = data(startPoint:endPoint,1);
            Amplitude = data(startPoint:endPoint,2);
            
            for i = 1:length(Time)
                TimeD(i) = Time{i};
            end 
            
            for i = 1:length(Amplitude)
                AmplitudeD(i) = Amplitude{i};
            end 
            
            AmplitudeD = AmplitudeD';
            TimeD = TimeD';
            
            
            %================================ CSP analysis started =======
 
            hpData = highpass(AmplitudeD,80,1e3);
            
            meanHPdata = 0;
            for i = 1:length(hpData)
                meanHPdata(i) = mean(hpData);
                i = i+1;
            end 
            
            rectHPdata = 0;
            for i = 1:length(hpData)
                if hpData(i)> mean(hpData)
                    rectHPdata(i) = mean(hpData);
                else
                    rectHPdata(i) = hpData(i);
                end 
                %i = i+1;
            end 
            
            zeroTime = find(TimeD == 0);
            spOnSet = 0;
            spOnSetIndex =0;
            
            for i = (zeroTime+20):length(AmplitudeD)
                if AmplitudeD(i) <= -0.2
                    spOnSet = TimeD(i);
                    spOnSetIndex = i;
                    break;
                end 
                %i = i+1;
            end 
            
            plot(app.UIAxes,TimeD,AmplitudeD);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, spOnSet,0, '-o');
            
            spSearchPoint = find(TimeD == (spOnSet+0.05));

            spOffSet = 0;
            for i = spSearchPoint:length(AmplitudeD)
                if rectHPdata(i) < (meanHPdata-0.010)
                    spOffSet = TimeD(i);
                    break;
                end 
                %i = i+1;
            end
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, spOffSet,0, '-o');
            
            lineX = [spOnSet spOffSet];
            lineY = [0 0];
            line(app.UIAxes, lineX, lineY);
            
            CorticalSilentPeriod = spOffSet - spOnSet;

            txt = ['CSP: ' num2str(CorticalSilentPeriod) ' ms'];
            text(app.UIAxes, spOnSet+0.01,-0.10,txt)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.endPoint = 0;
            startPoint = app.endPoint+ 7;
            app.endPoint = startPoint+1999;
            func2(app, app.endPoint, startPoint);
%             cla(app.UIAxes);
%             global endPoint ;
%             data= readcell('data2.txt');
%             DataSize = size(data);
%             
%             @func2;
%             
%             
%             startPoint = endPoint+ 7;
%             endPoint = startPoint+1999;
%             app.endPoint = endPoint;
%             Time = data(startPoint:endPoint,1);
%             Amplitude = data(startPoint:endPoint,2);
%             
%             for i = 1:length(Time)
%                 TimeD(i) = Time{i};
%             end 
%             
%             for i = 1:length(Amplitude)
%                 AmplitudeD(i) = Amplitude{i};
%             end 
%             
%             AmplitudeD = AmplitudeD';
%             TimeD = TimeD';
%             
%             
%             %================================ CSP analysis started =======
%  
%             hpData = highpass(AmplitudeD,80,1e3);
%             
%             meanHPdata = 0;
%             for i = 1:length(hpData)
%                 meanHPdata(i) = mean(hpData);
%                 i = i+1;
%             end 
%             
%             rectHPdata = 0;
%             for i = 1:length(hpData)
%                 if hpData(i)> mean(hpData)
%                     rectHPdata(i) = mean(hpData);
%                 else
%                     rectHPdata(i) = hpData(i);
%                 end 
%                 %i = i+1;
%             end 
%             
%             zeroTime = find(TimeD == 0);
%             spOnSet = 0;
%             spOnSetIndex =0;
%             
%             for i = (zeroTime+20):length(AmplitudeD)
%                 if AmplitudeD(i) <= -0.2
%                     spOnSet = TimeD(i);
%                     spOnSetIndex = i;
%                     break;
%                 end 
%                 %i = i+1;
%             end 
%             
%             plot(app.UIAxes,TimeD,AmplitudeD);
%             hold(app.UIAxes, 'on');
%             plot(app.UIAxes, spOnSet,0, '-o');
%             
%             spSearchPoint = find(TimeD == (spOnSet+0.05));
% 
%             spOffSet = 0;
%             for i = spSearchPoint:length(AmplitudeD)
%                 if rectHPdata(i) < (meanHPdata-0.010)
%                     spOffSet = TimeD(i);
%                     break;
%                 end 
%                 %i = i+1;
%             end
%             hold(app.UIAxes, 'on')
%             plot(app.UIAxes, spOffSet,0, '-o');
%             
%             lineX = [spOnSet spOffSet];
%             lineY = [0 0];
%             line(app.UIAxes, lineX, lineY);
%             
%             CorticalSilentPeriod = spOffSet - spOnSet;
% 
%             txt = ['CSP: ' num2str(CorticalSilentPeriod) ' ms'];
%             text(app.UIAxes, spOnSet+0.01,-0.10,txt)
            
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            startPoint = app.endPoint + 7;
            app.endPoint = startPoint + 1999;
            func2(app, app.endPoint, startPoint);            
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            startPoint = app.endPoint - 1999 -1999-7;
            app.endPoint = startPoint + 1999;
            func2(app, app.endPoint, startPoint);  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end