classdef calib < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CalibrationWindowUIFigure    matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        OpenMenu                     matlab.ui.container.Menu
        ExitMenu                     matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        AboutMenu                    matlab.ui.container.Menu
        SaveButton                   matlab.ui.control.Button
        OffsetsearchingpointSpinner  matlab.ui.control.Spinner
        OffsetsearchingpointSpinnerLabel  matlab.ui.control.Label
        ThresholdSpinner             matlab.ui.control.Spinner
        ThresholdSpinnerLabel        matlab.ui.control.Label
        PassFreqSpinner              matlab.ui.control.Spinner
        PassFreqSpinnerLabel         matlab.ui.control.Label
        HTML                         matlab.ui.control.HTML
        AllButton                    matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        SampleNumberLabel            matlab.ui.control.Label
        PreviousButton               matlab.ui.control.Button
        NextButton                   matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
        
        dataCalib ={} ;
        dataPointPerSample = 1999; %799; %1999;
        AmpOffsetVaule = 1.1;
        
        endPoint = 0;
        sampleNumber = 1;
        SampleDataSize = 0;
        filepath = "";
        freqPass = 80;
        startP = 0;
        thresholdLevel = 0.010;
        spSearchStartPoint = 0.05;
        
        ROI_Start_X = 0;
        ROI_End_X = 0;
        ROI_X = zeros;

    end
    
    methods (Access = public)
        function startupConfig(app)
            configVals = readcell("Appconfig.txt");
            app.freqPass = cell2mat(configVals(1,1));
            app.thresholdLevel = cell2mat(configVals(2,1));
            app.spSearchStartPoint = cell2mat(configVals(3,1));
            app.ROI_Start_X = cell2mat(configVals(4,1));
            app.ROI_End_X = cell2mat(configVals(4,2));
            app.ROI_X(1) = app.ROI_Start_X;
            app.ROI_X(2) = app.ROI_End_X ;
            
        end


        function results = func2(app,endPoint, startPoint, sampleNumber)
            
            if app.filepath ~= ""
                cla(app.UIAxes);
                %global endPoint ;
                %data= readcell(app.filepath);
                DataSize = size(app.dataCalib);
                %app.SampleDataSize = size(readcell(app.filepath));
                app.SampleDataSize = size(app.dataCalib);


    %             startPoint = endPoint+ 7;
    %             endPoint = startPoint+1999;
                %app.endPoint = endPoint;
                Time = app.dataCalib(startPoint:endPoint,1);
                Amplitude = app.dataCalib(startPoint:endPoint,2);
                
                for i = 1:length(Time)
                    TimeD(i) = Time{i};
                end 
                
                for i = 1:length(Amplitude)
                    AmplitudeD(i) = Amplitude{i}+app.AmpOffsetVaule;
                end 
                
                AmplitudeD = AmplitudeD';
                TimeD = TimeD';
                
                
                %================= CSP analysis started =======================
     
                hpData = highpass(AmplitudeD,app.freqPass,1e3);   %% Hight pass filter
                
                meanHPdata = 0;
                for i = 1:length(hpData)
                    meanHPdata(i) = mean(hpData);                 %% finding mean of the signal
                    i = i+1;
                end 
                
                rectHPdata = 0;
                for i = 1:length(hpData)
                    if hpData(i)> mean(hpData)
                        rectHPdata(i) = mean(hpData);           %% half wave rectified signal 
                    else                                        %% only pass values that r less than mean value
                        rectHPdata(i) = hpData(i);
                    end 
                    %i = i+1;
                end 
                
                zeroTime = find(TimeD == 0);
                spOnSet = 0;
                spOnSetIndex =0;
                
                for i = (zeroTime+20):length(AmplitudeD)
                    if AmplitudeD(i) <= -0.2
                        spOnSet = TimeD(i);
                        spOnSetIndex = i;
                        break;
                    end 
                    %i = i+1;
                end 
                
                
                spSearchPoint = find(TimeD == (spOnSet+app.spSearchStartPoint));
                spSearchStartat = spOnSet+app.spSearchStartPoint;
    
                spOffSet = 0;
                for i = spSearchPoint:length(AmplitudeD)
                    if rectHPdata(i) < (meanHPdata-app.thresholdLevel)
                        spOffSet = TimeD(i);
                        break;
                    end 
                    %i = i+1;
                end
                
                CorticalSilentPeriod = spOffSet - spOnSet;
                
                %===================== display features =======================
                plot(app.UIAxes,TimeD,rectHPdata);
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, spOnSet,0, '-o');
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, spOffSet,0, '-o');
                
                hold(app.UIAxes, 'on');
                p = plot(app.UIAxes, spSearchStartat,0, '+');
                p.MarkerSize = 20;
                p.Color = "black";
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, TimeD, (meanHPdata-app.thresholdLevel));
                
                lineX = [spOnSet spOffSet];
                lineY = [0 0];
                line(app.UIAxes, lineX, lineY, Color="red");
                
    
                txt = ['CSP: ' num2str(CorticalSilentPeriod) ' ms'];
                text(app.UIAxes, spOnSet+0.01,-0.10,txt)
                
                app.SampleNumberLabel.Text = "Sample Number: " + string(app.sampleNumber);
                app.Label.Text = string(app.SampleDataSize(1)+","+ app.filepath);
                
                if sampleNumber <= 1
                    app.PreviousButton.Enable = 'off';
                else
                    app.PreviousButton.Enable = 'on';
                end
                
                if (endPoint+app.dataPointPerSample)> app.SampleDataSize(1)
                    app.NextButton.Enable = 'off';
                else
                    app.NextButton.Enable = 'on';
                end
            end
        end
        
        function results3 = func3(app,endPoint, startPoint)
            
            if app.filepath == ""
                data= readcell('data2.txt');
            else
                data =readcell(app.filepath);
            end
            %data= readcell('data2.txt');
            DataSize = size(data);
           
                        
%             startPoint = endPoint+ 7;
%             endPoint = startPoint+1999;
            %app.endPoint = endPoint;
            Time = data(startPoint:endPoint,1);
            Amplitude = data(startPoint:endPoint,2);
            
            for i = 1:length(Time)
                TimeD(i) = Time{i};
            end 
            
            for i = 1:length(Amplitude)
                AmplitudeD(i) = Amplitude{i};
            end 
            
            AmplitudeD = AmplitudeD';
            TimeD = TimeD';
            
            
            %================= CSP analysis started =======================
 
            hpData = highpass(AmplitudeD,app.freqPass,1e3);
            
            meanHPdata = 0;
            for i = 1:length(hpData)
                meanHPdata(i) = mean(hpData);
                i = i+1;
            end 
            
            rectHPdata = 0;
            for i = 1:length(hpData)
                if hpData(i)> mean(hpData)
                    rectHPdata(i) = mean(hpData);
                else
                    rectHPdata(i) = hpData(i);
                end 
                %i = i+1;
            end 
            
            zeroTime = find(TimeD == 0);
            spOnSet = 0;
            spOnSetIndex =0;
            
            for i = (zeroTime+20):length(AmplitudeD)
                if AmplitudeD(i) <= -0.2
                    spOnSet = TimeD(i);
                    spOnSetIndex = i;
                    break;
                end 
                %i = i+1;
            end 
            
            
            spSearchPoint = find(TimeD == (spOnSet+app.spSearchStartPoint));

            spOffSet = 0;
            for i = spSearchPoint:length(AmplitudeD)
                if rectHPdata(i) < (meanHPdata-app.thresholdLevel)
                    spOffSet = TimeD(i);
                    break;
                end 
                %i = i+1;
            end
            
            CorticalSilentPeriod = spOffSet - spOnSet;
            results3 = CorticalSilentPeriod;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, path1)
            startupConfig(app);
%             if app.filepath ==  ""
%                 app.SampleDataSize = size(readcell("data2.txt"));
%                 path1 = readcell("data2.txt");
%             end
            app.endPoint = 0;
            app.sampleNumber = 1;
            app.PassFreqSpinner.Value = app.freqPass;
            app.ThresholdSpinner.Value = app.thresholdLevel;
            app.OffsetsearchingpointSpinner.Value = app.spSearchStartPoint;
            startPoint = app.endPoint+ 7;
            app.endPoint = startPoint+app.dataPointPerSample;
            app.startP = startPoint;
            app.filepath = path1;
            app.dataCalib = readcell(app.filepath);
            func2(app, app.endPoint, startPoint, app.sampleNumber);
            app.HTML.HTMLSource ="<html></html>";
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            %app.sampleNumber = app.sampleNumber+1;
            if (app.endPoint+7+app.dataPointPerSample) <= app.SampleDataSize(1)
                app.sampleNumber = app.sampleNumber+1;
                startPoint = app.endPoint + 7;
                app.endPoint = startPoint + app.dataPointPerSample;
            else
                app.endPoint = app.endPoint;
                startPoint = app.endPoint -app.dataPointPerSample;
                app.NextButton.Enable = 'off';
            end
            app.startP = startPoint;
            func2(app, app.endPoint, startPoint, app.sampleNumber);
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if app.sampleNumber > 1
                app.sampleNumber = app.sampleNumber-1;
                startPoint = app.endPoint - app.dataPointPerSample -app.dataPointPerSample-7;
                app.endPoint = startPoint + app.dataPointPerSample;
            else
                app.sampleNumber = 1;
                app.endPoint = app.endPoint;
                startPoint = app.endPoint -app.dataPointPerSample;
                app.PreviousButton.Enable = 'off';
            end
            app.startP = startPoint;
            func2(app, app.endPoint, startPoint, app.sampleNumber);  
        end

        % Button pushed function: AllButton
        function AllButtonPushed(app, event)
            endPoint = 0;
            app.sampleNumber = 1;
            NumberOfCountedSample = 0;
            TotalCSPtime = 0; 
            txt = "";
            maxLimit = round(app.SampleDataSize(1)/2006);
            for i = 1:maxLimit
                startPoint = endPoint+ 7;
                endPoint = startPoint+app.dataPointPerSample;
                CSP = func3(app,endPoint, startPoint);
                if CSP <= 0
                    txt = txt + newline + ['<a>CSP Sample ' num2str(i) ' :</a><a style="color:red;"> Not Detected</a><br>'];
                else
                    txt = txt + newline + ['<a>CSP Sample ' num2str(i) ' : ' num2str(CSP) ' ms </a><br>'];
                    NumberOfCountedSample = NumberOfCountedSample+1;
                    TotalCSPtime = TotalCSPtime + CSP;
                end
            end

                txt = txt+ newline + newline + ['<b><a style="color:blue;">Avg. CSP: ' num2str(TotalCSPtime/NumberOfCountedSample) ' ms</a></b><br>'];
            %app.DisplayAllCSPLabel.Text = txt;
            app.HTML.HTMLSource =txt;
            fileID = fopen('CSP_report.txt','w');
            fileValue = txt;
            fprintf(fileID,fileValue);
            fclose(fileID);
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            
            fileID = fopen('Appconfig.txt','w');
            fileValue = string(app.freqPass)+"\tFrqPass"+"\n"+string(app.thresholdLevel)+"\tThLvl"+"\n"+string(app.spSearchStartPoint)+"\tSPsearch";
            fileValue = append( fileValue+ "\n" + string(app.ROI_Start_X) + "\t" + string(app.ROI_End_X) + "\tROI");
            %fileValue = ;
            fprintf(fileID,fileValue);
            fclose(fileID);
            app.delete;
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [filename, path] = uigetfile();
            txt = string(path)+string(filename);
            app.filepath = txt;
            app.Label.Text = txt;
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            app2;
        end

        % Value changing function: PassFreqSpinner
        function PassFreqSpinnerValueChanging(app, event)
            app.freqPass = event.Value;
            func2(app, app.endPoint, app.startP, app.sampleNumber);
        end

        % Value changing function: ThresholdSpinner
        function ThresholdSpinnerValueChanging(app, event)
            app.thresholdLevel = event.Value;
            func2(app, app.endPoint, app.startP, app.sampleNumber);
        end

        % Value changing function: OffsetsearchingpointSpinner
        function OffsetsearchingpointSpinnerValueChanging(app, event)
            app.spSearchStartPoint = event.Value;
            func2(app, app.endPoint, app.startP, app.sampleNumber);
        end

        % Value changed function: OffsetsearchingpointSpinner
        function OffsetsearchingpointSpinnerValueChanged(app, event)
            app.spSearchStartPoint = app.OffsetsearchingpointSpinner.Value;
            func2(app, app.endPoint, app.startP, app.sampleNumber);
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
%             fileID = fopen('Appconfig.txt','w');
%             fileValue = string(app.freqPass)+"\n"+string(app.thresholdLevel)+"\n"+string(app.spSearchStartPoint);
%             %fileValue = ;
            fileID = fopen('Appconfig.txt','w');
            fileValue = string(app.freqPass)+"\tFrqPass"+"\n"+string(app.thresholdLevel)+"\tThLvl"+"\n"+string(app.spSearchStartPoint)+"\tSPsearch";
            fileValue = append( fileValue+ "\n" + string(app.ROI_Start_X) + "\t" + string(app.ROI_End_X) + "\tROI");
            fileValue = append(fileValue+ "\n" +string(app.dataPointPerSample) + "\tSampleSize");
            fprintf(fileID,fileValue);
            fclose(fileID);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CalibrationWindowUIFigure = uifigure('Visible', 'off');
            app.CalibrationWindowUIFigure.Name = 'Calibration Window';
            app.FileMenu = uimenu(app.CalibrationWindowUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.HelpMenu = uimenu(app.CalibrationWindowUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.UIAxes = uiaxes(app.CalibrationWindowUIFigure);
            title(app.UIAxes, 'Cortical Silent Period Identifier')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.NextButton = uibutton(app.CalibrationWindowUIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.CalibrationWindowUIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.SampleNumberLabel = uilabel(app.CalibrationWindowUIFigure);
            app.SampleNumberLabel.Text = 'Sample Number:';
            app.Label = uilabel(app.CalibrationWindowUIFigure);
            app.AllButton = uibutton(app.CalibrationWindowUIFigure, 'push');
            app.AllButton.ButtonPushedFcn = createCallbackFcn(app, @AllButtonPushed, true);
            app.AllButton.Text = 'All';
            app.HTML = uihtml(app.CalibrationWindowUIFigure);
            app.PassFreqSpinnerLabel = uilabel(app.CalibrationWindowUIFigure);
            app.PassFreqSpinnerLabel.Text = 'Pass Freq.';
            app.PassFreqSpinner = uispinner(app.CalibrationWindowUIFigure);
            app.PassFreqSpinner.ValueChangingFcn = createCallbackFcn(app, @PassFreqSpinnerValueChanging, true);
            app.ThresholdSpinnerLabel = uilabel(app.CalibrationWindowUIFigure);
            app.ThresholdSpinnerLabel.Text = 'Threshold';
            app.ThresholdSpinner = uispinner(app.CalibrationWindowUIFigure);
            app.ThresholdSpinner.Step = 0.001;
            app.ThresholdSpinner.ValueChangingFcn = createCallbackFcn(app, @ThresholdSpinnerValueChanging, true);
            app.ThresholdSpinner.Value = 0.01;
            app.OffsetsearchingpointSpinnerLabel = uilabel(app.CalibrationWindowUIFigure);
            app.OffsetsearchingpointSpinnerLabel.Text = 'Offset searching point';
            app.OffsetsearchingpointSpinner = uispinner(app.CalibrationWindowUIFigure);
            app.OffsetsearchingpointSpinner.Step = 0.01;
            app.OffsetsearchingpointSpinner.ValueChangingFcn = createCallbackFcn(app, @OffsetsearchingpointSpinnerValueChanging, true);
            app.OffsetsearchingpointSpinner.ValueChangedFcn = createCallbackFcn(app, @OffsetsearchingpointSpinnerValueChanged, true);
            app.SaveButton = uibutton(app.CalibrationWindowUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.CalibrationWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calib(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CalibrationWindowUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CalibrationWindowUIFigure)
        end
    end
end