classdef mepa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        FileMenu           matlab.ui.container.Menu
        OpenMenu           matlab.ui.container.Menu
        ExitMenu           matlab.ui.container.Menu
        CalibrationMenu    matlab.ui.container.Menu
        OpenwindowMenu     matlab.ui.container.Menu
        HelpMenu           matlab.ui.container.Menu
        AboutMenu          matlab.ui.container.Menu
        SelectROIButton    matlab.ui.control.Button
        HTML               matlab.ui.control.HTML
        AllButton          matlab.ui.control.Button
        Label              matlab.ui.control.Label
        SampleNumberLabel  matlab.ui.control.Label
        PreviousButton     matlab.ui.control.Button
        NextButton         matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
        data ={} ;
        endPoint = 0;
        startPoint = 0;
        sampleNumber = 1;
        SampleDataSize = 0;
        filepath = "";
        freqPass = 80;
        startP = 0;
        thresholdLevel = 0.010;
        spSearchStartPoint = 0.05;
        
        ROI_Start_X = 0;
        ROI_End_X = 0;
        ROI_X = zeros;
        
        TimeD2 = zeros;
        AmplitudeD2 = zeros;

    end
    
    methods (Access = public)
        function startupConfig(app)
            if app.filepath ~=  ""
                app.SampleDataSize = size(readcell(app.filepath));
            end
            configVals = readcell("Appconfig.txt");
            app.freqPass = cell2mat(configVals(1,1));
            app.thresholdLevel = cell2mat(configVals(2,1));
            app.spSearchStartPoint = cell2mat(configVals(3,1));
            app.ROI_Start_X = cell2mat(configVals(4,1));
            app.ROI_End_X = cell2mat(configVals(4,2));
            app.ROI_X(1) = app.ROI_Start_X;
            app.ROI_X(2) = app.ROI_End_X ;
        end
        
        function results = func2(app,endPoint, startPoint, sampleNumber)
            if app.filepath ~= ""
                cla(app.UIAxes);
                %global endPoint ;
                %app.data= readcell(app.filepath);
                %DataSize = size(data);
                app.SampleDataSize = size(app.data);
               
                            
    %             startPoint = endPoint+ 7;
    %             endPoint = startPoint+1999;
                %app.endPoint = endPoint;
                Time = app.data(startPoint:endPoint,1);
                Amplitude = app.data(startPoint:endPoint,2);
                
                TimeD =zeros;
                for i = 1:length(Time)
                    TimeD(i) = Time{i};
                    app.TimeD2(i) =Time{i};
                end 
                
                AmplitudeD=zeros;
                for i = 1:length(Amplitude)
                    AmplitudeD(i) = Amplitude{i};
                    app.AmplitudeD2(i) = Amplitude{i};
                end 
                
                AmplitudeD = AmplitudeD';
                TimeD = TimeD';
                
                
                %================= CSP analysis started =======================
     
%                 hpData = highpass(AmplitudeD,app.freqPass,1e3);
%                 
%                 meanHPdata = zeros;
%                 for i = 1:length(hpData)
%                     meanHPdata(i) = mean(hpData);
%                 end 
%                 
%                 rectHPdata = zeros;
%                 for i = 1:length(hpData)
%                     if hpData(i)> mean(hpData)
%                         rectHPdata(i) = mean(hpData);
%                     else
%                         rectHPdata(i) = hpData(i);
%                     end 
%                     %i = i+1;
%                 end 
%                 
                zeroTime = find(TimeD == 0);
%                 spOnSet = 0;
%                 %spOnSetIndex =0;
%                 
%                 for i = (zeroTime+41):length(AmplitudeD)
%                     if AmplitudeD(i) <= -0.2
%                         spOnSet = TimeD(i);
%                         %spOnSetIndex = i;
%                         break;
%                     end 
%                     %i = i+1;
%                 end 
%                 
%                 
%                 spSearchPoint = find(TimeD == (spOnSet+app.spSearchStartPoint));
%     
%                 spOffSet = 0;
%                 for i = spSearchPoint:length(AmplitudeD)
%                     if rectHPdata(i) < (meanHPdata-app.thresholdLevel)
%                         spOffSet = TimeD(i);
%                         break;
%                     end 
%                     %i = i+1;
%                 end
%                 if spOnSet <=0
%                     CorticalSilentPeriod = 0;
%                 else
%                     CorticalSilentPeriod = spOffSet - spOnSet;
%                 end
                %CorticalSilentPeriod = spOffSet - spOnSet;
                
                
                %===================== display features =======================
                plot(app.UIAxes,TimeD,AmplitudeD);
%                 if CorticalSilentPeriod > 0
%                     hold(app.UIAxes, 'on');
%                     plot(app.UIAxes, spOnSet,0, '-o');
%                     
%                     hold(app.UIAxes, 'on')
%                     plot(app.UIAxes, spOffSet,0, '-o');
%                     
%                     lineX = [spOnSet spOffSet];
%                     lineY = [0 0];
%                     line(app.UIAxes, lineX, lineY, Color="red");
%                     
%         
%                     txt = ['CSP: ' num2str(CorticalSilentPeriod) ' ms'];
%                     text(app.UIAxes, spOnSet+0.01,-0.10,txt);
%                 else
%                     txt = 'CSP: not measurable';
%                     text(app.UIAxes, spOnSet+0.01,-0.10,txt);
%                 end
%                 


                app.SampleNumberLabel.Text = "Sample Number: " + string(app.sampleNumber);
                app.Label.Text = string(app.SampleDataSize(1) +","+ app.filepath);
                
                if sampleNumber <= 1
                    app.PreviousButton.Enable = 'off';
                else
                    app.PreviousButton.Enable = 'on';
                end
                
                if (endPoint+1999)> app.SampleDataSize(1)
                    app.NextButton.Enable = 'off';
                else
                    app.NextButton.Enable = 'on';
                end


%                 stp = zeroTime+20;
%                 cutAmp = AmplitudeD(stp:2000);
%                 [maxVal,maxInx] = max(cutAmp);
%                 [minVal,minInx] = min(cutAmp);
%                 maxInx_stp = maxInx+stp-1;
%                 minInx_stp = minInx+stp-1;
%                 hold(app.UIAxes, 'on');
%                 plot(app.UIAxes, TimeD(maxInx_stp), AmplitudeD(maxInx_stp), '-o');
%                 hold(app.UIAxes, 'on');
%                 plot(app.UIAxes, TimeD(minInx_stp), AmplitudeD(minInx_stp), '-o');
                
%                 lineX2 = [TimeD(minInx_stp) TimeD(maxInx_stp)];
%                 lineY2 = [AmplitudeD(maxInx_stp) AmplitudeD(maxInx_stp)];
%                 line(app.UIAxes, lineX2,lineY2,Color="red");
%                 
%                 lineX3 = [TimeD(minInx_stp) TimeD(maxInx_stp)];
%                 lineY3 = [AmplitudeD(minInx_stp) AmplitudeD(minInx_stp)];
%                 line(app.UIAxes,lineX3, lineY3,Color="red");
%                 
%                 lineX4 = [TimeD(maxInx_stp) TimeD(maxInx_stp)];
%                 lineY4 = [AmplitudeD(minInx_stp) AmplitudeD(maxInx_stp)];
%                 line(app.UIAxes,lineX4, lineY4,Color="red");
%                 
%                 lineX5 = [TimeD(minInx_stp) TimeD(minInx_stp)];
%                 lineY5 = [AmplitudeD(minInx_stp) AmplitudeD(maxInx_stp)];
%                 line(app.UIAxes,lineX5, lineY5,Color="red");
%                 
%                 motorPotential = AmplitudeD(maxInx_stp) - AmplitudeD(minInx_stp);
%                 txt = ['Potential: ' num2str(motorPotential) ' mV'];
%                 text(app.UIAxes,TimeD(minInx_stp),AmplitudeD(minInx_stp)-0.10,txt);


                hold(app.UIAxes, 'on');
                %ROI first bar
                px = app.ROI_Start_X; % app.ROI_X(1)
                py = 0;
                lineX6 = [px px];
                lineY6 = [py-0.5 py+0.5];
                line(app.UIAxes, lineX6, lineY6,Color="green");
                
                %ROI Second bar
                px = app.ROI_End_X; % app.ROI_X(2)
                py = 0;
                lineX6 = [px px];
                lineY6 = [py-0.5 py+0.5];
                line(app.UIAxes, lineX6, lineY6,Color="green");
                
                %ROI MEPA
                [~,startTime]=min(abs(app.TimeD2-app.ROI_X(1)));  % find index where the roi_x(1) is closest
                [~,endTime]=min(abs(app.TimeD2-app.ROI_X(2)));
                stp = startTime;
                cutAmp = app.AmplitudeD2(stp:endTime);
                [~,maxInx] = max(cutAmp);
                [~,minInx] = min(cutAmp);
                maxInx_stp = maxInx+stp-1;
                minInx_stp = minInx+stp-1;
                plot(app.UIAxes, app.TimeD2(maxInx_stp), app.AmplitudeD2(maxInx_stp), '-o');
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, app.TimeD2(minInx_stp), app.AmplitudeD2(minInx_stp), '-o');
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, TimeD(maxInx_stp), AmplitudeD(maxInx_stp), '-o');
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, TimeD(minInx_stp), AmplitudeD(minInx_stp), '-o');
                
                lineX2 = [TimeD(minInx_stp) TimeD(maxInx_stp)];
                lineY2 = [AmplitudeD(maxInx_stp) AmplitudeD(maxInx_stp)];
                line(app.UIAxes, lineX2,lineY2,Color="red");
                
                lineX3 = [TimeD(minInx_stp) TimeD(maxInx_stp)];
                lineY3 = [AmplitudeD(minInx_stp) AmplitudeD(minInx_stp)];
                line(app.UIAxes,lineX3, lineY3,Color="red");
                
                lineX4 = [TimeD(maxInx_stp) TimeD(maxInx_stp)];
                lineY4 = [AmplitudeD(minInx_stp) AmplitudeD(maxInx_stp)];
                line(app.UIAxes,lineX4, lineY4,Color="red");
                
                lineX5 = [TimeD(minInx_stp) TimeD(minInx_stp)];
                lineY5 = [AmplitudeD(minInx_stp) AmplitudeD(maxInx_stp)];
                line(app.UIAxes,lineX5, lineY5,Color="red");
                
                motorPotential = AmplitudeD(maxInx_stp) - AmplitudeD(minInx_stp);
                txt = ['Potential: ' num2str(motorPotential) ' mV'];
                text(app.UIAxes,TimeD(minInx_stp),AmplitudeD(minInx_stp)-0.10,txt);


            end
        end


        function results3 = func3(app,endPoint, startPoint)
            
            if app.filepath == ""
                data= readcell('data2.txt');
            else
                data =readcell(app.filepath);
            end
            %data= readcell('data2.txt');
            %DataSize = size(data);
           
                        
%             startPoint = endPoint+ 7;
%             endPoint = startPoint+1999;
            %app.endPoint = endPoint;
            Time = app.data(startPoint:endPoint,1);
            Amplitude = app.data(startPoint:endPoint,2);
            
            TimeD = zeros;
            for i = 1:length(Time)
                TimeD(i) = Time{i};
            end 
            
            AmplitudeD = zeros;
            for i = 1:length(Amplitude)
                AmplitudeD(i) = Amplitude{i};
            end 
            
            AmplitudeD = AmplitudeD';
            TimeD = TimeD';
            
            
            %================= CSP analysis started =======================
 
            hpData = highpass(AmplitudeD,app.freqPass,1e3);
            
            meanHPdata = zeros;
            for i = 1:length(hpData)
                meanHPdata(i) = mean(hpData);
            end 
            
            rectHPdata = zeros;
            for i = 1:length(hpData)
                if hpData(i)> mean(hpData)
                    rectHPdata(i) = mean(hpData);
                else
                    rectHPdata(i) = hpData(i);
                end 
                %i = i+1;
            end 
            
            zeroTime = find(TimeD == 0);
            spOnSet = 0;
            %spOnSetIndex =0;
            
            for i = (zeroTime+41):length(AmplitudeD)
                if AmplitudeD(i) <= -0.2
                    spOnSet = TimeD(i);
                    %spOnSetIndex = i;
                    break;
                end 
                %i = i+1;
            end 
            
            
            spSearchPoint = find(TimeD == (spOnSet+app.spSearchStartPoint));

            spOffSet = 0;
            for i = spSearchPoint:length(AmplitudeD)
                if rectHPdata(i) < (meanHPdata-app.thresholdLevel)
                    spOffSet = TimeD(i);
                    break;
                end 
                %i = i+1;
            end
            
            if spOnSet <=0
                    CorticalSilentPeriod = 0;
            else
                    CorticalSilentPeriod = spOffSet - spOnSet;
            end
            %CorticalSilentPeriod = spOffSet - spOnSet;
            results3 = CorticalSilentPeriod;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.endPoint = 0;
            app.sampleNumber = 1;
            app.startPoint = app.endPoint+ 7;
            app.endPoint = app.startPoint+1999;
            func2(app, app.endPoint, app.startPoint, app.sampleNumber);
            app.HTML.HTMLSource ="<html></html>";
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            %app.sampleNumber = app.sampleNumber+1;
            if (app.endPoint+7+1999) <= app.SampleDataSize(1)
                app.sampleNumber = app.sampleNumber+1;
                app.startPoint = app.endPoint + 7;
                app.endPoint = app.startPoint + 1999;
            else
                app.endPoint = app.endPoint;
                app.startPoint = app.endPoint -1999;
                app.NextButton.Enable = 'off';
            end
            func2(app, app.endPoint, app.startPoint, app.sampleNumber);
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if app.sampleNumber > 1
                app.sampleNumber = app.sampleNumber-1;
                app.startPoint = app.endPoint - 1999 -1999-7;
                app.endPoint = app.startPoint + 1999;
            else
                app.sampleNumber = 1;
                app.endPoint = app.endPoint;
                app.startPoint = app.endPoint -1999;
                app.PreviousButton.Enable = 'off';
            end
            func2(app, app.endPoint, app.startPoint, app.sampleNumber);  
        end

        % Button pushed function: AllButton
        function AllButtonPushed(app, event)
            endPoint = 0;
            %app.sampleNumber = 1;
            NumberOfCountedSample = 0;
            TotalCSPtime = 0; 
            txt = "";
            txt_save = "";
            maxLimit = round(app.SampleDataSize(1)/2006);
            for i = 1:maxLimit
                app.startPoint = endPoint+ 7;
                endPoint = app.startPoint+1999;
                CSP = func3(app,endPoint, app.startPoint);
                if CSP <= 0
                    txt = txt + newline + ['<a>CSP Sample ' num2str(i) ' :</a><a style="color:red;"> Not Detected</a><br>'];
                    txt_save = txt_save + newline + ['CSP Sample ' num2str(i) ' : Not Detected'];
                else
                    txt = txt + newline + ['<a>CSP Sample ' num2str(i) ' : ' num2str(CSP) ' ms </a><br>'];
                    txt_save = txt_save + newline + ['CSP Sample ' num2str(i) ' : ' num2str(CSP) ' ms'];
                    NumberOfCountedSample = NumberOfCountedSample+1;
                    TotalCSPtime = TotalCSPtime + CSP;
                end
            end

                txt = txt+ newline + newline + ['<b><a style="color:blue;">Avg. CSP: ' num2str(TotalCSPtime/NumberOfCountedSample) ' ms</a></b><br>'];
                txt_save = txt_save+ newline + newline + ['Avg. CSP: ' num2str(TotalCSPtime/NumberOfCountedSample) ' ms'];
            %app.DisplayAllCSPLabel.Text = txt;
            app.HTML.HTMLSource =txt;
            fileID = fopen('SavedResult.txt','w');
            fprintf(fileID,txt_save);
            fclose(fileID);
            %app.endPoint = 0;
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            app.delete;
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            %% clearing initials 
            app.endPoint = 0;
            app.sampleNumber = 1;
            app.startPoint = app.endPoint+ 7;
            app.endPoint = app.startPoint+1999;
            %% clearing done
            
            [filename, path] = uigetfile();
            txt = string(path)+string(filename);
            app.filepath = txt;
            app.Label.Text = txt;
            startupConfig(app);
            app.data= readcell(app.filepath);
            func2(app, app.endPoint, app.startPoint, app.sampleNumber);
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            app2;
        end

        % Menu selected function: OpenwindowMenu
        function OpenwindowMenuSelected(app, event)

            %cab = calib;
            calib(app.filepath);
          
            
            %cab.func2;
        end

        % Button pushed function: SelectROIButton
        function SelectROIButtonPushed(app, event)
            %button= zeros;
            cnt = zeros;
            roi_x=zeros;
            roi_y=zeros;
            while 1
                % Set up figure handle visibility, run ginput, and return state
                fhv = app.UIFigure.HandleVisibility;        % Current status
                app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
                set(0, 'CurrentFigure', app.UIFigure)       % Make fig current
                
                [px,py,button]= ginput(1);
                plot(px,py,'*');

                %new bar
                lineX6 = [px px];
                lineY6 = [py-0.5 py+0.5];
                line(app.UIAxes, lineX6, lineY6,Color="red");
                
                app.UIFigure.HandleVisibility = fhv;        % return original state
                if button == 1
                    cnt = cnt+1;
                    roi_x(cnt)=px;
                    roi_y(cnt)=py;
                    
                    %previous bar
                    lineX6 = [app.ROI_X(cnt) app.ROI_X(cnt)];
                    lineY6 = [0-0.5 0+0.5];
                    line(app.UIAxes, lineX6, lineY6,Color="white");
                    %button = 0;
                    if cnt ==2 
                        break;
                    end
                end
            end
            
            [~,startTime]=min(abs(app.TimeD2-roi_x(1)));  % find index where the roi_x(1) is closest
            [~,endTime]=min(abs(app.TimeD2-roi_x(2)));
            %startTime = find(Time == roi_x(1));
            %endTime = find(Time == roi_x(2));
            stp = startTime;
            cutAmp = app.AmplitudeD2(stp:endTime);
            [~,maxInx] = max(cutAmp);
            [~,minInx] = min(cutAmp);
            maxInx_stp = maxInx+stp-1;
            minInx_stp = minInx+stp-1;
            plot(app.UIAxes, app.TimeD2(maxInx_stp), app.AmplitudeD2(maxInx_stp), '-o');
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, app.TimeD2(minInx_stp), app.AmplitudeD2(minInx_stp), '-o');
            
            %hold(app.UIAxes, 'on');
            
            app.ROI_Start_X= roi_x(1);
            app.ROI_End_X= roi_x(2);
            app.ROI_X(1) = roi_x(1);
            app.ROI_X(2) = roi_x(2);
            
            fileID = fopen('Appconfig.txt','w');
            fileValue = string(app.freqPass)+"\tFrqPass"+"\n"+string(app.thresholdLevel)+"\tThLvl"+"\n"+string(app.spSearchStartPoint)+"\tSPsearch";
            fileValue = append( fileValue+ "\n" + string(app.ROI_Start_X) + "\t" + string(app.ROI_End_X) + "\tROI");
            %fileValue = ;
            fprintf(fileID,fileValue);
            fclose(fileID);
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.CalibrationMenu = uimenu(app.UIFigure);
            app.CalibrationMenu.Text = 'Calibration';
            app.OpenwindowMenu = uimenu(app.CalibrationMenu);
            app.OpenwindowMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenwindowMenuSelected, true);
            app.OpenwindowMenu.Text = 'Open window';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Motor Evoked Potential Amplitude')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.SampleNumberLabel = uilabel(app.UIFigure);
            app.SampleNumberLabel.Text = 'Sample Number:';
            app.Label = uilabel(app.UIFigure);
            app.AllButton = uibutton(app.UIFigure, 'push');
            app.AllButton.ButtonPushedFcn = createCallbackFcn(app, @AllButtonPushed, true);
            app.AllButton.Text = 'All';
            app.HTML = uihtml(app.UIFigure);
            app.SelectROIButton = uibutton(app.UIFigure, 'push');
            app.SelectROIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectROIButtonPushed, true);
            app.SelectROIButton.Text = 'Select ROI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mepa

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end