classdef urNeuroApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        URNeuroAppUIFigure       matlab.ui.Figure
        FileMenu                 matlab.ui.container.Menu
        OpenMenu                 matlab.ui.container.Menu
        ExitMenu                 matlab.ui.container.Menu
        CalibrationMenu          matlab.ui.container.Menu
        OpenCalibrationMenu      matlab.ui.container.Menu
        HelpMenu                 matlab.ui.container.Menu
        AboutMenu                matlab.ui.container.Menu
        TabGroup                 matlab.ui.container.TabGroup
        CSPTab                   matlab.ui.container.Tab
        Disp_Peak2PeakLabel      matlab.ui.control.Label
        Disp_uV_MinLabel         matlab.ui.control.Label
        Disp_uV_MaxLabel         matlab.ui.control.Label
        Disp_RMS_After_CSPLabel  matlab.ui.control.Label
        Disp_RMSLabel            matlab.ui.control.Label
        Disp_CSPLabel            matlab.ui.control.Label
        OnsetButton              matlab.ui.control.Button
        OffsetButton             matlab.ui.control.Button
        AllButton                matlab.ui.control.Button
        HTML                     matlab.ui.control.HTML
        PreviousButton           matlab.ui.control.Button
        NextButton               matlab.ui.control.Button
        Label                    matlab.ui.control.Label
        SampleNumberLabel        matlab.ui.control.Label
        UIAxes3                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
        MEPATab                  matlab.ui.container.Tab
        SelectROIButton          matlab.ui.control.Button
        Label_2                  matlab.ui.control.Label
        SampleNumberLabel_2      matlab.ui.control.Label
        PreviousButton_2         matlab.ui.control.Button
        NextButton_2             matlab.ui.control.Button
        UIAxes2                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        data ={} ;
        mep_data ={};
        extractedCSP_data ={};  % {Trial_number, CSP_OnSet, CSP_OffSet}
        endPoint = 0;
        startPoint = 0;
        sampleNumber = 1;
        %AllCntSampleNumber = 1;
        SampleDataSize = 0;
        
        dataPointPerSample = 1999; %1999;799
        AmpOffsetVaule = 0; % This creates CSP intractable. better not to use it.  
        
        filepath = "";
        freqPass = 30;
        startP = 0;
        thresholdLevel = 0.021;
        spSearchStartPoint = 0.06;
        
        ROI_Start_X = 0;
        ROI_End_X = 0;
        ROI_X = zeros;
        
        TimeD2 = zeros;
        AmplitudeD2 = zeros;
        
        rectMinBefStimLine = 0;
    end
    
    methods (Access = public)
        function startupConfig(app)
            if app.filepath ~=  ""
                app.SampleDataSize = size(readcell(app.filepath));
            end
            configVals = readcell("Appconfig.txt");
            app.freqPass = cell2mat(configVals(1,1));
            app.thresholdLevel = cell2mat(configVals(2,1));
            app.spSearchStartPoint = cell2mat(configVals(3,1));
            app.ROI_Start_X = cell2mat(configVals(4,1));
            app.ROI_End_X = cell2mat(configVals(4,2));
            app.ROI_X(1) = app.ROI_Start_X;
            app.ROI_X(2) = app.ROI_End_X ;
            
        end
        
        function results = func2(app,endPoint, startPoint, sampleNumber)
            if app.filepath ~= ""
                cla(app.UIAxes);
                cla(app.UIAxes3);
                %global endPoint ;
                %data= readcell(app.filepath);
                %DataSize = size(data);
                app.SampleDataSize = size(app.data);
               
                            
    %             startPoint = endPoint+ 7;
    %             endPoint = startPoint+1999;
                %app.endPoint = endPoint;
                Time = app.data(startPoint:endPoint,1);
                Amplitude = app.data(startPoint:endPoint,2);
                
                TimeD =zeros;
                for i = 1:length(Time)
                    TimeD(i) = Time{i};
                end 
                
                AmplitudeD=zeros;
                for i = 1:length(Amplitude)
                    AmplitudeD(i) = Amplitude{i} + app.AmpOffsetVaule;
                end 
                
                AmplitudeD = AmplitudeD';
                TimeD = TimeD';
                
                
                zeroTime = find(TimeD == 0);
                %Max & Min uV & Time
                [FirstResp_maxVal,FirstResp_maxIndx] = max(AmplitudeD(zeroTime+41:length(AmplitudeD)));
                [FirstResp_minVal,FirstResp_minIndx] = min(AmplitudeD(zeroTime+41:length(AmplitudeD)));
                peak2peak = abs(FirstResp_maxVal) + abs(FirstResp_minVal);
                
                FirstResp_minIndx = FirstResp_minIndx+zeroTime+40;
                %end Max & Min uV & Time
                
                [MinBefStim, MinIdx] = min(AmplitudeD(1:150));
                MinBefStim = MinBefStim*0.5;
                
                
                init_rms = (rms(AmplitudeD(1:150)));
                %rectMinBefStim = init_rms;
               
                
                CSP_onSet_Indx = 0;
                CSP_onSet_Indx_Amp = 0;
                OnsetArray =zeros;
%                 for i = FirstResp_minIndx:-1:1
%                     if (AmplitudeD(i)) >= rectMinBefStim
%                         break;
%                     end
%                     CSP_onSet_Indx = i;
%                     CSP_onSet_Indx_Amp = AmplitudeD(i);
%                     OnsetArray(FirstResp_minIndx+1 -i)  = AmplitudeD(i);
%                 end

                for i = 1:FirstResp_minIndx
                    if (AmplitudeD(FirstResp_minIndx+1-i)) >= MinBefStim
                        break;
                    end
                    CSP_onSet_Indx = FirstResp_minIndx+1-i;
                    CSP_onSet_Indx_Amp = AmplitudeD(FirstResp_minIndx+1-i);
                    OnsetArray(i)  = AmplitudeD(FirstResp_minIndx+1-i);
                end

                %CSP_onSet_Indx = CSP_onSet_Indx;
                
                
                %================= CheckIfSampleIsExtracted ===================
                thisSampleExtractedBefore = 0;
                if size(app.extractedCSP_data,1) ==0
                    thisSampleExtractedBefore = 0;
                else
                    sampleNumberFound = 0;
                    for i = 1:size(app.extractedCSP_data,1)
                        if app.extractedCSP_data{i,1} == app.sampleNumber
                            sampleNumberFound = 1;
                            thisSampleExtractedBefore = 1;
                            break;
                        else
                            sampleNumberFound = 0;
                            thisSampleExtractedBefore = 0;
                        end
                    end
                    if sampleNumberFound == 0
                    end
                end
                
                %================= CSP analysis started =======================
     
                spOnSet = 0;
                spOffSet = 0;
                zeroTime = find(TimeD == 0);
                if thisSampleExtractedBefore == 0
                    hpData = highpass(AmplitudeD,app.freqPass,1e3);
                
                    meanHPdata = zeros;
                    for i = 1:length(hpData)
                        meanHPdata(i) = mean(hpData);
                    end 
                    
                    rectHPdata = zeros;
                    for i = 1:length(hpData)
                        if hpData(i)> mean(hpData)
                            rectHPdata(i) = mean(hpData);
                        else
                            rectHPdata(i) = hpData(i);
                        end 
                        %i = i+1;
                    end 
                    
                    %zeroTime = find(TimeD == 0);
                    %spOnSet = 0;
                    %spOnSetIndex =0;
                    
                     [rectMinBefStim, rectMinIdx] = min(rectHPdata(1:150));
                     rectMinBefStim = rectMinBefStim*0.5;
                    
                    
%                     for i = (zeroTime+61):length(AmplitudeD)
%                         if AmplitudeD(i) <= (FirstResp_minVal/3) %-0.2
%                             spOnSet = TimeD(i);
%                             %spOnSetIndex = i;
%                             break;
%                         end 
%                         %i = i+1;
%                     end
                    
                    spOnSet = TimeD(CSP_onSet_Indx); % edited 06.04.2023
                    
                    plot(app.UIAxes3, TimeD, rectHPdata);
                    hold(app.UIAxes3, 'on');
                    plot(app.UIAxes3, spOnSet, FirstResp_minVal/3, '-o');
                    text(app.UIAxes3,spOnSet,FirstResp_minVal, string(rectMinBefStim));
                    
                    
                    app.rectMinBefStimLine = repmat(rectMinBefStim, 1, length(TimeD));
                    hold(app.UIAxes, 'on');
                    hold(app.UIAxes3, 'on');
                    %plot(app.UIAxes, TimeD, rectMinBefStimLine, Color="red");
                    plot(app.UIAxes3, TimeD, app.rectMinBefStimLine);
                    
                    %spSearchPoint = find(TimeD == (spOnSet+app.spSearchStartPoint));
                    spSearchPoint = CSP_onSet_Indx+200;
                    plot(app.UIAxes3, TimeD(spSearchPoint), FirstResp_minVal/3, '-o');
        
                    %spOffSet = 0;
                    for i = spSearchPoint:length(AmplitudeD)
                        if rectHPdata(i) <= (rectMinBefStim)         %(meanHPdata-app.thresholdLevel)
                            spOffSet = TimeD(i);
                            break;
                        end 
                        %i = i+1;
                    end
                else
                    spOnSet = cell2mat(app.extractedCSP_data(app.sampleNumber, 2));
                    spOffSet = cell2mat(app.extractedCSP_data(app.sampleNumber, 3));
                end


                if spOnSet <=0
                    CorticalSilentPeriod = 0;
                else
                    CorticalSilentPeriod = spOffSet - spOnSet;
                end
                %CorticalSilentPeriod = spOffSet - spOnSet;
                
%                 %Max & Min uV & Time
%                 [FirstResp_maxVal,FirstResp_maxIndx] = max(AmplitudeD(zeroTime+41:length(AmplitudeD)));
%                 [FirstResp_minVal,FirstResp_minIndx] = min(AmplitudeD(zeroTime+41:length(AmplitudeD)));
%                 peak2peak = abs(FirstResp_maxVal) + abs(FirstResp_minVal);
%                 
%                 %end Max & Min uV & Time
                
                %RMS value caluculation
                
                init_rms = rms(AmplitudeD(1:200));
                
                [~,spOffSetIdx]=min(abs(TimeD-spOffSet));
                afterOffset_rms = rms(AmplitudeD(spOffSetIdx:length(AmplitudeD)));
                
                
                disp(spOffSet);
                disp(spOffSetIdx);
                disp(afterOffset_rms);
               
                % End RMS value caluculation
                
                %===================== display features =======================
                plot(app.UIAxes,TimeD,AmplitudeD);
                
                hold(app.UIAxes, "on");
                plot(app.UIAxes, TimeD, app.rectMinBefStimLine, Color="yello");
                plot(app.UIAxes, TimeD(CSP_onSet_Indx), CSP_onSet_Indx_Amp, '-o', Color="green");
                plot(app.UIAxes, TimeD(zeroTime+41), CSP_onSet_Indx_Amp, '-o' ,Color="red");
                
                text(app.UIAxes, TimeD(CSP_onSet_Indx), AmplitudeD(CSP_onSet_Indx)-0.80,"csp in = "+num2str(CSP_onSet_Indx) +" Val = "+num2str(AmplitudeD(CSP_onSet_Indx)));
                
                
                txt = ['RMS = ' num2str(init_rms)];        % shows the CSP duration
                text(app.UIAxes, spOnSet+0.01,-0.15,txt);
                app.Disp_RMSLabel.Text = string("RMS before Stimulation = "+MinBefStim);


                txt = ['RMS after CSP = ' num2str(afterOffset_rms)];        % shows the CSP duration
                
                text2 = "";
                for i = 1:length(OnsetArray)
                    text2 = text2 +"," + string(OnsetArray);
                end
%                text(app.UIAxes, spOnSet+0.10,-0.15,txt);
                app.Disp_RMS_After_CSPLabel.Text = string(OnsetArray);
                
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, TimeD(FirstResp_maxIndx+zeroTime+40), FirstResp_maxVal, '-o');  % shows the Maximum uV
%                text(app.UIAxes, TimeD(FirstResp_maxIndx+zeroTime+40), FirstResp_maxVal+0.03,"uV Max = "+num2str(FirstResp_maxVal) +", Time = "+num2str(TimeD(FirstResp_maxIndx+zeroTime+40)));
                
                plot(app.UIAxes, TimeD(FirstResp_minIndx), FirstResp_minVal, '-o');  % shows the Minimum uV
%                text(app.UIAxes, TimeD(FirstResp_minIndx+zeroTime+40), FirstResp_minVal-0.03,"uV Min = "+num2str(FirstResp_minVal) +", Time = "+num2str(TimeD(FirstResp_minIndx+zeroTime+40)));
%                text(app.UIAxes, spOnSet+0.01,-0.20,"Peak-Peak (uV) = "+peak2peak);
                app.Disp_Peak2PeakLabel.Text = string("Peak-Peak (uV) = "+peak2peak);
                text(app.UIAxes, TimeD(FirstResp_minIndx), FirstResp_minVal-0.03,"uV Min = "+num2str(FirstResp_minVal) +", TimeIdx = "+num2str((FirstResp_minIndx))+ " ->" +num2str(TimeD(FirstResp_minIndx+zeroTime+40)));                
                
                if CorticalSilentPeriod > 0
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes, spOnSet,0, '-o');
                    
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes, spOffSet,0, '-o');
                    
                    lineX = [spOnSet spOffSet];
                    lineY = [0 0];
                    line(app.UIAxes, lineX, lineY, Color="red");
                    
        
                    txt = ['CSP: ' num2str(CorticalSilentPeriod) ' s'];        % shows the CSP duration
%                    text(app.UIAxes, spOnSet+0.01,-0.10,txt);
                    app.Disp_CSPLabel.Text = string(txt);
                    
                    txt = ['CSP started at: ' num2str(spOnSet) 's'];            % shows the CSP starting time
%                    text(app.UIAxes, spOnSet,+0.15,txt, Color="black");
                    
                    txt = ['CSP ended at: ' num2str(spOffSet) 's'];           % shows the CSP ending time
%                    text(app.UIAxes, spOffSet,+0.15,txt, Color="red");
                else
                    txt = 'CSP: not measurable';
%                    text(app.UIAxes, spOnSet+0.01,-0.10,txt);
                end
                
%                 plot(app.UIAxes,TimeD,AmplitudeD);
%                 hold(app.UIAxes, 'on');
%                 plot(app.UIAxes, spOnSet,0, '-o');
%                 
%                 hold(app.UIAxes, 'on')
%                 plot(app.UIAxes, spOffSet,0, '-o');
%                 
%                 lineX = [spOnSet spOffSet];
%                 lineY = [0 0];
%                 line(app.UIAxes, lineX, lineY, Color="red");
%                 
%     
%                 txt = ['CSP: ' num2str(CorticalSilentPeriod) ' ms'];
%                 text(app.UIAxes, spOnSet+0.01,-0.10,txt);
                
                app.SampleNumberLabel.Text = "Sample Number: " + string(app.sampleNumber);
                app.Label.Text = string(app.SampleDataSize(1) +","+ app.filepath);
                
                if sampleNumber <= 1
                    app.PreviousButton.Enable = 'off';
                else
                    app.PreviousButton.Enable = 'on';
                end
                
                if (endPoint+app.dataPointPerSample)> app.SampleDataSize(1)
                    app.NextButton.Enable = 'off';
                else
                    app.NextButton.Enable = 'on';
                end
                
                
                %app.extractedCSP_data = [app.extractedCSP_data; {app.sampleNumber,spOnSet,spOffSet}];
                %disp(size(app.extractedCSP_data,1));
                %disp([app.extractedCSP_data]);
                
                if size(app.extractedCSP_data,1) ==0
                    app.extractedCSP_data = [app.extractedCSP_data; {app.sampleNumber,spOnSet,spOffSet}];
                else
                    sampleNumberFound = 0;
                    for i = 1:size(app.extractedCSP_data,1)
                        if app.extractedCSP_data{i,1} == app.sampleNumber
                            sampleNumberFound = 1;
                            break;
                        else
                            sampleNumberFound = 0;
                        end
                    end
                    if sampleNumberFound == 0
                        app.extractedCSP_data = [app.extractedCSP_data; {app.sampleNumber,spOnSet,spOffSet}];
                    end
                end
                
                %disp([app.extractedCSP_data]);
                
            end
            
            
        end


        %%%%% Function_3 for Calculating CSP altogether %%%%%
        
        function [CSPduration, results2, results1, FirstResp_maxVal, CSPmaxTime, FirstResp_minVal,CSPminTime, peak2peak, init_rms, afterCSP_rms] = func3(app,endPoint, startPoint, AllCntSampleNumber)
            
            if app.filepath == ""
                app.data= readcell('data2.txt');
            else
                app.data = app.data;
            end
            %data= readcell('data2.txt');
            %DataSize = size(data);
           
                        
%             startPoint = endPoint+ 7;
%             endPoint = startPoint+1999;
            %app.endPoint = endPoint;
            Time = app.data(startPoint:endPoint,1);
            Amplitude = app.data(startPoint:endPoint,2);
            
            TimeD = zeros;
            for i = 1:length(Time)
                TimeD(i) = Time{i};
            end 
            
            AmplitudeD = zeros;
            for i = 1:length(Amplitude)
                AmplitudeD(i) = Amplitude{i};
            end 
            
            AmplitudeD = AmplitudeD';
            TimeD = TimeD';
            
            
            %================= CheckIfSampleIsExtracted ===================
                thisSampleExtractedBefore = 0;
                if size(app.extractedCSP_data,1) ==0
                    thisSampleExtractedBefore = 0;
                else
                    sampleNumberFound = 0;
                    for i = 1:size(app.extractedCSP_data,1)
                        if app.extractedCSP_data{i,1} == AllCntSampleNumber
                            sampleNumberFound = 1;
                            thisSampleExtractedBefore = 1;
                            break;
                        else
                            sampleNumberFound = 0;
                            thisSampleExtractedBefore = 0;
                        end
                    end
                    if sampleNumberFound == 0
                    end
                end
                
             %================= CSP analysis started =======================
            
             %================= CSP analysis started =======================
     
                spOnSet = 0;
                spOffSet = 0;
                zeroTime = find(TimeD == 0);
                if thisSampleExtractedBefore == 0
                    hpData = highpass(AmplitudeD,app.freqPass,1e3);
                
                    meanHPdata = zeros;
                    for i = 1:length(hpData)
                        meanHPdata(i) = mean(hpData);
                    end 
                    
                    rectHPdata = zeros;
                    for i = 1:length(hpData)
                        if hpData(i)> mean(hpData)
                            rectHPdata(i) = mean(hpData);
                        else
                            rectHPdata(i) = hpData(i);
                        end 
                        %i = i+1;
                    end 
                    
                    %zeroTime = find(TimeD == 0);
                    %spOnSet = 0;
                    %spOnSetIndex =0;
                    
                    for i = (zeroTime+41):length(AmplitudeD)
                        if AmplitudeD(i) <= -0.2
                            spOnSet = TimeD(i);
                            %spOnSetIndex = i;
                            break;
                        end 
                        %i = i+1;
                    end 
                    
                    
                    spSearchPoint = find(TimeD == (spOnSet+app.spSearchStartPoint));
        
                    %spOffSet = 0;
                    for i = spSearchPoint:length(AmplitudeD)
                        if rectHPdata(i) < (meanHPdata-app.thresholdLevel)
                            spOffSet = TimeD(i);
                            break;
                        end 
                        %i = i+1;
                    end
                else
                    spOnSet = cell2mat(app.extractedCSP_data(AllCntSampleNumber, 2));
                    spOffSet = cell2mat(app.extractedCSP_data(AllCntSampleNumber, 3));
                end
            
            
%             %================= CSP analysis started =======================
%  
%             hpData = highpass(AmplitudeD,app.freqPass,1e3);
%             
%             meanHPdata = zeros;
%             for i = 1:length(hpData)
%                 meanHPdata(i) = mean(hpData);
%             end 
%             
%             rectHPdata = zeros;
%             for i = 1:length(hpData)
%                 if hpData(i)> mean(hpData)
%                     rectHPdata(i) = mean(hpData);
%                 else
%                     rectHPdata(i) = hpData(i);
%                 end 
%                 %i = i+1;
%             end 
%             
%             zeroTime = find(TimeD == 0);
%             spOnSet = 0;
%             %spOnSetIndex =0;
%             
%             for i = (zeroTime+41):length(AmplitudeD)
%                 if AmplitudeD(i) <= -0.2
%                     spOnSet = TimeD(i);
%                     %spOnSetIndex = i;
%                     break;
%                 end 
%                 %i = i+1;
%             end 
%             
%             
%             spSearchPoint = find(TimeD == (spOnSet+app.spSearchStartPoint));
% 
%             spOffSet = 0;
%             for i = spSearchPoint:length(AmplitudeD)
%                 if rectHPdata(i) < (meanHPdata-app.thresholdLevel)
%                     spOffSet = TimeD(i);
%                     break;
%                 end 
%                 %i = i+1;
%             end
            
            
            if spOnSet <=0
                    CorticalSilentPeriod = 0;
            else
                    CorticalSilentPeriod = spOffSet - spOnSet;
            end
            %CorticalSilentPeriod = spOffSet - spOnSet;
            CSPduration = CorticalSilentPeriod ;
            results2 = spOnSet;
            results1 = spOffSet;
            [FirstResp_maxVal,FirstResp_maxIndx] = max(AmplitudeD(zeroTime+41:length(AmplitudeD)));
            [FirstResp_minVal,FirstResp_minIndx] = min(AmplitudeD(zeroTime+41:length(AmplitudeD)));
            CSPmaxTime = TimeD(FirstResp_maxIndx+zeroTime+40);
            CSPminTime = TimeD(FirstResp_minIndx+zeroTime+40);
            peak2peak = abs(FirstResp_maxVal) + abs(FirstResp_minVal);
            
            %RMS value caluculation
                
            init_rms = rms(AmplitudeD(1:200));
            
            [~,spOffSetIdx]=min(abs(TimeD-spOffSet));
            afterCSP_rms = rms(AmplitudeD(spOffSetIdx:length(AmplitudeD)));
               
            % End RMS value caluculation
        end


        function MEPAres = MEPAfunc(app,endPoint, startPoint, sampleNumber)
            if app.filepath ~= ""
                cla(app.UIAxes2);
                %global endPoint ;
                %app.data= readcell(app.filepath);
                %DataSize = size(data);
                app.SampleDataSize = size(app.mep_data);


                Time = app.mep_data(startPoint:endPoint,1);
                Amplitude = app.mep_data(startPoint:endPoint,2);
                
                TimeD =zeros;
                for i = 1:length(Time)
                    TimeD(i) = Time{i};
                    app.TimeD2(i) =Time{i};
                end 
                
                AmplitudeD=zeros;
                for i = 1:length(Amplitude)
                    AmplitudeD(i) = Amplitude{i};
                    app.AmplitudeD2(i) = Amplitude{i};
                end 
                
                AmplitudeD = AmplitudeD';
                TimeD = TimeD';


                zeroTime = find(TimeD == 0);
                
                
                %RMS value caluculation
                
                init_rms = rms(AmplitudeD(1:200));
                
%                 [~,spOffSetIdx]=min(abs(TimeD-spOffSet));
%                 afterOffset_rms = rms(AmplitudeD(spOffSetIdx:length(AmplitudeD)));
%                 
%                 
%                 disp(spOffSet);
%                 disp(spOffSetIdx);
%                 disp(afterOffset_rms);
               
                % End RMS value caluculation


                %===================== display features =======================
                plot(app.UIAxes2,TimeD,AmplitudeD);
                
                
                app.SampleNumberLabel_2.Text = "Sample Number: " + string(app.sampleNumber);
                app.Label_2.Text = string(app.SampleDataSize(1) +","+ app.filepath);
                
                if sampleNumber <= 1
                    app.PreviousButton_2.Enable = 'off';
                else
                    app.PreviousButton_2.Enable = 'on';
                end
                
                if (endPoint+app.dataPointPerSample)> app.SampleDataSize(1)
                    app.NextButton_2.Enable = 'off';
                else
                    app.NextButton_2.Enable = 'on';
                end


                hold(app.UIAxes2, 'on');
                %ROI first bar
                px = app.ROI_Start_X; % app.ROI_X(1)
                py = 0;
                lineX6 = [px px];
                lineY6 = [py-0.5 py+0.5];
                line(app.UIAxes2, lineX6, lineY6,Color="green");
                
                %ROI Second bar
                px = app.ROI_End_X; % app.ROI_X(2)
                py = 0;
                lineX6 = [px px];
                lineY6 = [py-0.5 py+0.5];
                line(app.UIAxes2, lineX6, lineY6,Color="green");
                
                %ROI MEPA
                [~,startTime]=min(abs(app.TimeD2-app.ROI_X(1)));  % find index where the roi_x(1) is closest
                [~,endTime]=min(abs(app.TimeD2-app.ROI_X(2)));
                stp = startTime;
                cutAmp = app.AmplitudeD2(stp:endTime);
                [~,maxInx] = max(cutAmp);
                [~,minInx] = min(cutAmp);
                maxInx_stp = maxInx+stp-1;
                minInx_stp = minInx+stp-1;
                plot(app.UIAxes2, app.TimeD2(maxInx_stp), app.AmplitudeD2(maxInx_stp), '-o');
                hold(app.UIAxes2, 'on');
                plot(app.UIAxes2, app.TimeD2(minInx_stp), app.AmplitudeD2(minInx_stp), '-o');
                
                hold(app.UIAxes2, 'on');
                plot(app.UIAxes2, TimeD(maxInx_stp), AmplitudeD(maxInx_stp), '-o');
                hold(app.UIAxes2, 'on');
                plot(app.UIAxes2, TimeD(minInx_stp), AmplitudeD(minInx_stp), '-o');
                
                lineX2 = [TimeD(minInx_stp) TimeD(maxInx_stp)];
                lineY2 = [AmplitudeD(maxInx_stp) AmplitudeD(maxInx_stp)];
                line(app.UIAxes2, lineX2,lineY2,Color="red");
                
                lineX3 = [TimeD(minInx_stp) TimeD(maxInx_stp)];
                lineY3 = [AmplitudeD(minInx_stp) AmplitudeD(minInx_stp)];
                line(app.UIAxes2,lineX3, lineY3,Color="red");
                
                lineX4 = [TimeD(maxInx_stp) TimeD(maxInx_stp)];
                lineY4 = [AmplitudeD(minInx_stp) AmplitudeD(maxInx_stp)];
                line(app.UIAxes2,lineX4, lineY4,Color="red");
                
                lineX5 = [TimeD(minInx_stp) TimeD(minInx_stp)];
                lineY5 = [AmplitudeD(minInx_stp) AmplitudeD(maxInx_stp)];
                line(app.UIAxes2,lineX5, lineY5,Color="red");
                
                motorPotential = AmplitudeD(maxInx_stp) - AmplitudeD(minInx_stp);
                txt = ['Potential: ' num2str(motorPotential) ' V'];
                text(app.UIAxes2,TimeD(minInx_stp),AmplitudeD(minInx_stp)-0.10,txt);
                
                txt = ['RMS before stimulation: ' num2str(init_rms) ' V'];
                text(app.UIAxes2,TimeD(100),AmplitudeD(1)+0.5,txt);


            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.endPoint = 0;
            app.sampleNumber = 1;
            app.startPoint = app.endPoint+ 7;
            app.endPoint = app.startPoint+app.dataPointPerSample;
            func2(app, app.endPoint, app.startPoint, app.sampleNumber);
            app.HTML.HTMLSource ="<html></html>";
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            
            if (app.TabGroup.SelectedTab == app.CSPTab)
                %% clearing initials 
                app.endPoint = 0;
                app.sampleNumber = 1;
                app.startPoint = app.endPoint+ 7;
                app.endPoint = app.startPoint+app.dataPointPerSample;
                app.extractedCSP_data = {};
                %% clearing done
                
                [filename, path] = uigetfile('*.*');
                txt = string(path)+string(filename);
                app.filepath = txt;
                app.Label.Text = txt;
                startupConfig(app);
                app.data= readcell(app.filepath);
                func2(app, app.endPoint, app.startPoint, app.sampleNumber);       
            elseif (app.TabGroup.SelectedTab == app.MEPATab)
                %% clearing initials 
                app.endPoint = 0;
                app.sampleNumber = 1;
                app.startPoint = app.endPoint+ 7;
                app.endPoint = app.startPoint+app.dataPointPerSample;
                %% clearing done
                
                [filename, path] = uigetfile('*.*');
                txt = string(path)+string(filename);
                app.filepath = txt;
                app.Label.Text = txt;
                startupConfig(app);
                app.mep_data= readcell(app.filepath);
                MEPAfunc(app, app.endPoint, app.startPoint, app.sampleNumber); 
            
            else
                
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            %app.sampleNumber = app.sampleNumber+1;
            if (app.endPoint+7+app.dataPointPerSample) <= app.SampleDataSize(1)
                app.sampleNumber = app.sampleNumber+1;
                app.startPoint = app.endPoint + 7;
                app.endPoint = app.startPoint + app.dataPointPerSample;
            else
                app.endPoint = app.endPoint;
                app.startPoint = app.endPoint - app.dataPointPerSample;
                app.NextButton.Enable = 'off';
            end
            func2(app, app.endPoint, app.startPoint, app.sampleNumber);
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if app.sampleNumber > 1
                app.sampleNumber = app.sampleNumber-1;
                app.startPoint = app.endPoint - app.dataPointPerSample -app.dataPointPerSample-7;
                app.endPoint = app.startPoint + app.dataPointPerSample;
            else
                app.sampleNumber = 1;
                app.endPoint = app.endPoint;
                app.startPoint = app.endPoint -app.dataPointPerSample;
                app.PreviousButton.Enable = 'off';
            end
            func2(app, app.endPoint, app.startPoint, app.sampleNumber);  
        end

        % Button pushed function: AllButton
        function AllButtonPushed(app, event)
            endPoint = 0;
            %app.sampleNumber = 1;
            NumberOfCountedSample = 0;
            %AllCntSampleNumber = 1;
            TotalCSPtime = 0; 
            txt = "";
            txt_save = "Trail Number \t\tMaximum(uV) \tMaximum Time(s) \tMinimum(uV) \tMinimum Time(s) \tPeak-Peak(uV) \tCSP Duration(s)  \tCSP Onset(s) \tCSP Offset(s) \tRMS Before Stimulation \tRMS after CSP";
            maxLimit = round(app.SampleDataSize(1)/(app.dataPointPerSample+7));
            for i = 1:maxLimit
                app.startPoint = endPoint+ 7;
                AllCntSampleNumber = i;
                endPoint = app.startPoint+app.dataPointPerSample;
                [CSP, spOnSet, spOffSet, FirstResp_maxVal, CSPmaxTime, FirstResp_minVal,CSPminTime, peak2peak, init_rms, afterCSP_rms] = func3(app,endPoint, app.startPoint, AllCntSampleNumber);
                if CSP <= 0
                    txt = txt + newline + ['<a>CSP Sample ' num2str(i) ' :</a><a style="color:red;"> Not Detected</a><br>'];
                    txt_save = txt_save + newline + [ num2str(i) ' \tNot Detected'];
                else
                    txt = txt + newline + ['<a>CSP Sample ' num2str(i) ' : ' num2str(CSP) ' s </a><br>'];
                    txt_save = txt_save + newline + [ num2str(i) ' \t\t ' num2str(FirstResp_maxVal) '\t' num2str(CSPmaxTime) '\t' num2str(FirstResp_minVal) '\t' num2str(CSPminTime) '\t' num2str(peak2peak) '\t' num2str(CSP) '\t' num2str(spOnSet) '\t' num2str(spOffSet) '\t' num2str(init_rms) '\t' num2str(afterCSP_rms)];
                    NumberOfCountedSample = NumberOfCountedSample+1;
                    TotalCSPtime = TotalCSPtime + CSP;
                end
            end

                txt = txt+ newline + newline + ['<b><a style="color:blue;">Avg. CSP: ' num2str(TotalCSPtime/NumberOfCountedSample) ' s </a></b><br>'];
                txt_save = txt_save+ newline + newline + ['\t\t\t\t\t\tAvg. CSP Duration \t' num2str(TotalCSPtime/NumberOfCountedSample)];
            %app.DisplayAllCSPLabel.Text = txt;
            app.HTML.HTMLSource =txt;
            fileID = fopen('SavedResult.txt','w');
            fprintf(fileID,txt_save);
            fclose(fileID);
            %app.endPoint = 0;
        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            %app.sampleNumber = app.sampleNumber+1;
            if (app.endPoint+7+app.dataPointPerSample) <= app.SampleDataSize(1)
                app.sampleNumber = app.sampleNumber+1;
                app.startPoint = app.endPoint + 7;
                app.endPoint = app.startPoint + app.dataPointPerSample;
            else
                app.endPoint = app.endPoint;
                app.startPoint = app.endPoint -app.dataPointPerSample;
                app.NextButton.Enable = 'off';
            end
            MEPAfunc(app, app.endPoint, app.startPoint, app.sampleNumber);
        end

        % Button pushed function: PreviousButton_2
        function PreviousButton_2Pushed(app, event)
            if app.sampleNumber > 1
                app.sampleNumber = app.sampleNumber-1;
                app.startPoint = app.endPoint - app.dataPointPerSample -app.dataPointPerSample-7;
                app.endPoint = app.startPoint + app.dataPointPerSample;
            else
                app.sampleNumber = 1;
                app.endPoint = app.endPoint;
                app.startPoint = app.endPoint -app.dataPointPerSample;
                app.PreviousButton.Enable = 'off';
            end
            MEPAfunc(app, app.endPoint, app.startPoint, app.sampleNumber);  
        end

        % Button pushed function: SelectROIButton
        function SelectROIButtonPushed(app, event)

            cnt = zeros;
            roi_x=zeros;
            roi_y=zeros;
            while 1
                % Set up figure handle visibility, run ginput, and return state
                fhv = app.URNeuroAppUIFigure.HandleVisibility;        % Current status
                app.URNeuroAppUIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
                set(0, 'CurrentFigure', app.URNeuroAppUIFigure)       % Make fig current
                
                [px,py,button]= ginput(1);
                plot(px,py,'*');

                %new bar
                lineX6 = [px px];
                lineY6 = [py-0.5 py+0.5];
                line(app.UIAxes2, lineX6, lineY6,Color="red");
                
                app.URNeuroAppUIFigure.HandleVisibility = fhv;        % return original state
                if button == 1
                    cnt = cnt+1;
                    roi_x(cnt)=px;
                    roi_y(cnt)=py;
                    
                    %previous bar
                    lineX6 = [app.ROI_X(cnt) app.ROI_X(cnt)];
                    lineY6 = [0-0.5 0+0.5];
                    line(app.UIAxes2, lineX6, lineY6,Color="white");
                    %button = 0;
                    if cnt ==2 
                        break;
                    end
                end
            end
            
            [~,startTime]=min(abs(app.TimeD2-roi_x(1)));  % find index where the roi_x(1) is closest
            [~,endTime]=min(abs(app.TimeD2-roi_x(2)));
            %startTime = find(Time == roi_x(1));
            %endTime = find(Time == roi_x(2));
            stp = startTime;
            cutAmp = app.AmplitudeD2(stp:endTime);
            [~,maxInx] = max(cutAmp);
            [~,minInx] = min(cutAmp);
            maxInx_stp = maxInx+stp-1;
            minInx_stp = minInx+stp-1;
            plot(app.UIAxes2, app.TimeD2(maxInx_stp), app.AmplitudeD2(maxInx_stp), '-o');
            hold(app.UIAxes2, 'on');
            plot(app.UIAxes2, app.TimeD2(minInx_stp), app.AmplitudeD2(minInx_stp), '-o');
            
            %hold(app.UIAxes, 'on');
            
            app.ROI_Start_X= roi_x(1);
            app.ROI_End_X= roi_x(2);
            app.ROI_X(1) = roi_x(1);
            app.ROI_X(2) = roi_x(2);
            
            fileID = fopen('Appconfig.txt','w');
            fileValue = string(app.freqPass)+"\tFrqPass"+"\n"+string(app.thresholdLevel)+"\tThLvl"+"\n"+string(app.spSearchStartPoint)+"\tSPsearch";
            fileValue = append( fileValue+ "\n" + string(app.ROI_Start_X) + "\t" + string(app.ROI_End_X) + "\tROI");
            %fileValue = ;
            fprintf(fileID,fileValue);
            fclose(fileID);
        end

        % Button down function: CSPTab
        function CSPTabButtonDown(app, event)
            app.Label.Text = "CSP";
        end

        % Menu selected function: OpenCalibrationMenu
        function OpenCalibrationMenuSelected(app, event)
            calib(app.filepath);
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            app2;
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            app.delete;
        end

        % Button pushed function: OffsetButton
        function OffsetButtonPushed(app, event)
            while 1
                % Set up figure handle visibility, run ginput, and return state
                fhv = app.URNeuroAppUIFigure.HandleVisibility;        % Current status
                app.URNeuroAppUIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
                set(0, 'CurrentFigure', app.URNeuroAppUIFigure)       % Make fig current
                
                [px,py,button]= ginput(1);
                plot(px,py,'o');
                
                %new bar
                lineX6 = [px px];
                lineY6 = [py-0.5 py+0.5];
                line(app.UIAxes, lineX6, lineY6,Color="red");


                app.extractedCSP_data(app.sampleNumber, 3) = {px};
                
                
                spOnSet = cell2mat(app.extractedCSP_data(app.sampleNumber, 2));
                spOffSet = cell2mat(app.extractedCSP_data(app.sampleNumber, 3));
                
                lineX = [spOnSet spOffSet];
                lineY = [0 0];
                line(app.UIAxes, lineX, lineY, Color="green");
                
                
                app.URNeuroAppUIFigure.HandleVisibility = fhv;        % return original state
                if button == 1
                    break;
                end
            end
        end

        % Button pushed function: OnsetButton
        function OnsetButtonPushed(app, event)
             while 1
                % Set up figure handle visibility, run ginput, and return state
                fhv = app.URNeuroAppUIFigure.HandleVisibility;        % Current status
                app.URNeuroAppUIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
                set(0, 'CurrentFigure', app.URNeuroAppUIFigure)       % Make fig current
                
                [px,py,button]= ginput(1);
                plot(px,py,'o');
                
                %new bar
                lineX6 = [px px];
                lineY6 = [py-0.5 py+0.5];
                line(app.UIAxes, lineX6, lineY6,Color="red");
                
                
                app.extractedCSP_data(app.sampleNumber, 2) = {px};
                
                spOnSet = cell2mat(app.extractedCSP_data(app.sampleNumber, 2));
                spOffSet = cell2mat(app.extractedCSP_data(app.sampleNumber, 3));
                
                lineX = [spOnSet spOffSet];
                lineY = [0 0];
                line(app.UIAxes, lineX, lineY, Color="green");
                
                
                app.URNeuroAppUIFigure.HandleVisibility = fhv;        % return original state
                if button == 1
                    break;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.URNeuroAppUIFigure = uifigure('Visible', 'off');
            app.URNeuroAppUIFigure.Name = 'UR Neuro App';
            app.FileMenu = uimenu(app.URNeuroAppUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.CalibrationMenu = uimenu(app.URNeuroAppUIFigure);
            app.CalibrationMenu.Text = 'Calibration';
            app.OpenCalibrationMenu = uimenu(app.CalibrationMenu);
            app.OpenCalibrationMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenCalibrationMenuSelected, true);
            app.OpenCalibrationMenu.Text = 'Open Calibration';
            app.HelpMenu = uimenu(app.URNeuroAppUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.TabGroup = uitabgroup(app.URNeuroAppUIFigure);
            app.CSPTab = uitab(app.TabGroup);
            app.CSPTab.Title = 'CSP';
            app.CSPTab.ButtonDownFcn = createCallbackFcn(app, @CSPTabButtonDown, true);
            app.UIAxes = uiaxes(app.CSPTab);
            title(app.UIAxes, 'Cortical Silent Period')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes3 = uiaxes(app.CSPTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.SampleNumberLabel = uilabel(app.CSPTab);
            app.SampleNumberLabel.Text = 'Sample Number:';
            app.Label = uilabel(app.CSPTab);
            app.NextButton = uibutton(app.CSPTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.CSPTab, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.HTML = uihtml(app.CSPTab);
            app.AllButton = uibutton(app.CSPTab, 'push');
            app.AllButton.ButtonPushedFcn = createCallbackFcn(app, @AllButtonPushed, true);
            app.AllButton.Text = 'All';
            app.OffsetButton = uibutton(app.CSPTab, 'push');
            app.OffsetButton.ButtonPushedFcn = createCallbackFcn(app, @OffsetButtonPushed, true);
            app.OffsetButton.Text = 'Offset';
            app.OnsetButton = uibutton(app.CSPTab, 'push');
            app.OnsetButton.ButtonPushedFcn = createCallbackFcn(app, @OnsetButtonPushed, true);
            app.OnsetButton.Text = 'Onset';
            app.Disp_CSPLabel = uilabel(app.CSPTab);
            app.Disp_CSPLabel.Text = 'Disp_CSP';
            app.Disp_RMSLabel = uilabel(app.CSPTab);
            app.Disp_RMSLabel.Text = 'Disp_RMS';
            app.Disp_RMS_After_CSPLabel = uilabel(app.CSPTab);
            app.Disp_RMS_After_CSPLabel.Text = 'Disp_RMS_After_CSP';
            app.Disp_uV_MaxLabel = uilabel(app.CSPTab);
            app.Disp_uV_MaxLabel.Text = 'Disp_uV_Max';
            app.Disp_uV_MinLabel = uilabel(app.CSPTab);
            app.Disp_uV_MinLabel.Text = 'Disp_uV_Min';
            app.Disp_Peak2PeakLabel = uilabel(app.CSPTab);
            app.Disp_Peak2PeakLabel.Text = 'Disp_Peak2Peak';
            app.MEPATab = uitab(app.TabGroup);
            app.MEPATab.Title = 'MEPA';
            app.UIAxes2 = uiaxes(app.MEPATab);
            title(app.UIAxes2, 'Motor Evoked Potential Amplitude')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.NextButton_2 = uibutton(app.MEPATab, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Text = 'Next';
            app.PreviousButton_2 = uibutton(app.MEPATab, 'push');
            app.PreviousButton_2.ButtonPushedFcn = createCallbackFcn(app, @PreviousButton_2Pushed, true);
            app.PreviousButton_2.Text = 'Previous';
            app.SampleNumberLabel_2 = uilabel(app.MEPATab);
            app.SampleNumberLabel_2.Text = 'Sample Number:';
            app.Label_2 = uilabel(app.MEPATab);
            app.Label_2.Text = 'Label:';
            app.SelectROIButton = uibutton(app.MEPATab, 'push');
            app.SelectROIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectROIButtonPushed, true);
            app.SelectROIButton.Text = 'Select ROI';
            % Show the figure after all components are created
            app.URNeuroAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = urNeuroApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.URNeuroAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.URNeuroAppUIFigure)
        end
    end
end