classdef uSaskNeuroApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        uSaskNeuronAppUIFigure  matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        OpenMenu                matlab.ui.container.Menu
        HelpMenu                matlab.ui.container.Menu
        NumberofSampleLabel     matlab.ui.control.Label
        SampleNoLabel           matlab.ui.control.Label
        PreviousButton          matlab.ui.control.Button
        NextButton              matlab.ui.control.Button
        FileDirectoryLabel      matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        
        filepath = "";
        data = {};
        fileData_AllLines = {};
        sampleNumber = 0;
        totalNumberOfSample = 0;
    end
    
    methods (Access = private)
        
        function results = func(app)
            
            app.SampleNoLabel.Text = "Sample No.:"+string(app.sampleNumber-7);
            charLine = char(app.fileData_AllLines(app.sampleNumber));  % 8  to 39
            SingleSampleALLData = strsplit(charLine, '\t');
            %T = array2table(C);
            
            startingChar = char(SingleSampleALLData(26)); % it starts from 26th number column
            extractedSingleSampleData = strsplit(startingChar, ';');
            doubledextractedSingleSampleData = cellfun(@str2double, extractedSingleSampleData);
            plot(app.UIAxes, doubledextractedSingleSampleData);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [filename,path] = uigetfile('*.txt', 'Open text file','MultiSelect','on');
            delete(f);
            
            txt = string(path)+string(filename);
            app.filepath = txt;
            app.FileDirectoryLabel.Text = txt;
            
            if app.filepath ~=  ""
                if app.filepath ~= "00"
                    
                    %Load the file
                    
                    fileID = fopen(app.filepath, 'r');
                    
                    % Initialize a cell array to store lines
                    app.fileData_AllLines = {};
                    
                    % Read the file line by line
                    currentLine = fgetl(fileID);
                    while ischar(currentLine)
                        app.fileData_AllLines{end+1} = currentLine;
                        currentLine = fgetl(fileID);
                    end
                    
                    fclose(fileID);
                    
                    
                    %total Number of Sample Count
                    for i = 8:length(app.fileData_AllLines)
                        charLine = char(app.fileData_AllLines(i));
                        if strcmp(charLine(1:6), 'Sample') || strcmp(charLine(1:6), 'sample')
                            app.totalNumberOfSample = i;
                        else
                            break;
                        end
                    end
                    app.NumberofSampleLabel.Text = "Number of Sample: "+ string(app.totalNumberOfSample-7);
                    
                    
                    app.sampleNumber = 8;  % 8 is the initial sample
                    app.SampleNoLabel.Text = "Sample No.:"+string(app.sampleNumber-7);

                    charLine = char(app.fileData_AllLines(app.sampleNumber));  % 8  to 39
                    SingleSampleALLData = strsplit(charLine, '\t');
                    %T = array2table(C);
                    
                    startingChar = char(SingleSampleALLData(26)); % it starts from 26th number column
                    extractedSingleSampleData = strsplit(startingChar, ';');
                    doubledextractedSingleSampleData = cellfun(@str2double, extractedSingleSampleData);
                    plot(app.UIAxes, doubledextractedSingleSampleData);


                end
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.sampleNumber >= app.totalNumberOfSample
                app.NextButton.Enable = "off";
            else
                app.sampleNumber = app.sampleNumber+1;  % 8 is the initial sample
                if app.sampleNumber > 8
                    app.PreviousButton.Enable = "on";
                end
            end
            func(app);
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if app.sampleNumber > 8
                app.sampleNumber = app.sampleNumber-1;  % 8 is the initial sample
                func(app);
                if app.sampleNumber < app.totalNumberOfSample
                    app.NextButton.Enable = "on";
                end
            else
                app.PreviousButton.Enable = "off";
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.uSaskNeuronAppUIFigure = uifigure('Visible', 'off');
            app.uSaskNeuronAppUIFigure.Name = 'uSaskNeuronApp';
            app.uSaskNeuronAppUIFigure.Icon = 'icon.png';
            app.FileMenu = uimenu(app.uSaskNeuronAppUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.HelpMenu = uimenu(app.uSaskNeuronAppUIFigure);
            app.HelpMenu.Text = 'Help';
            app.UIAxes = uiaxes(app.uSaskNeuronAppUIFigure);
            title(app.UIAxes, 'plot')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.FileDirectoryLabel = uilabel(app.uSaskNeuronAppUIFigure);
            app.FileDirectoryLabel.Text = 'File Directory: ';
            app.NextButton = uibutton(app.uSaskNeuronAppUIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.uSaskNeuronAppUIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.SampleNoLabel = uilabel(app.uSaskNeuronAppUIFigure);
            app.SampleNoLabel.Text = 'Sample No.:';
            app.NumberofSampleLabel = uilabel(app.uSaskNeuronAppUIFigure);
            app.NumberofSampleLabel.Text = 'Number of Sample:';
            % Show the figure after all components are created
            app.uSaskNeuronAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uSaskNeuroApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.uSaskNeuronAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.uSaskNeuronAppUIFigure)
        end
    end
end